classdef Strauss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                  matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        WeeklyRecipe             matlab.ui.container.Tab
        MainLayout               matlab.ui.container.GridLayout
        StraussImag              matlab.ui.control.Image
        getRequ                  matlab.ui.control.Button
        CalcPlan                 matlab.ui.control.Button
        outFileButton            matlab.ui.control.Button
        OutputTab                matlab.ui.container.TabGroup
        Pot_1                    matlab.ui.container.Tab
        Grid_Pot1                matlab.ui.container.GridLayout
        WeeklyPlan_1             matlab.ui.control.Table
        PotVolLabel_1            matlab.ui.control.Label
        UpdateVol_1              matlab.ui.control.Label
        PotCapLabel_1            matlab.ui.control.Label
        updateCap_1              matlab.ui.control.Label
        PotPORLabel_1            matlab.ui.control.Label
        updateportions_1         matlab.ui.control.Label
        Pot_2                    matlab.ui.container.Tab
        Grid_Pot2                matlab.ui.container.GridLayout
        WeeklyPlan_2             matlab.ui.control.Table
        PotVolLabel_2            matlab.ui.control.Label
        UpdateVol_2              matlab.ui.control.Label
        PotCapLabel_2            matlab.ui.control.Label
        updateCap_2              matlab.ui.control.Label
        PotPORLabel_2            matlab.ui.control.Label
        updateportions_2         matlab.ui.control.Label
        Pot_3                    matlab.ui.container.Tab
        Grid_Pot3                matlab.ui.container.GridLayout
        WeeklyPlan_3             matlab.ui.control.Table
        PotVolLabel_3            matlab.ui.control.Label
        UpdateVol_3              matlab.ui.control.Label
        PotCapLabel_3            matlab.ui.control.Label
        updateCap_3              matlab.ui.control.Label
        PotPORLabel_3            matlab.ui.control.Label
        updateportions_3         matlab.ui.control.Label
        TottalLoad               matlab.ui.control.Label
        TabGroup2                matlab.ui.container.TabGroup
        Tab_1                    matlab.ui.container.Tab
        GridLayout_1             matlab.ui.container.GridLayout
        Grid_Times_1             matlab.ui.container.GridLayout
        Label_StartDay           matlab.ui.control.Label
        StartDay_1               matlab.ui.control.DropDown
        Label_EndDay             matlab.ui.control.Label
        EndDay_1                 matlab.ui.control.DropDown
        Label_start_time         matlab.ui.control.Label
        start_time_1             matlab.ui.control.DropDown
        Label_end_time           matlab.ui.control.Label
        end_time_1               matlab.ui.control.DropDown
        Label_Tdays              matlab.ui.control.Label
        TdaysLabel_1             matlab.ui.control.Label
        Label_times              matlab.ui.control.Label
        timesLabel_1             matlab.ui.control.Label
        Tab_2                    matlab.ui.container.Tab
        GridLayout_2             matlab.ui.container.GridLayout
        Grid_Times_2             matlab.ui.container.GridLayout
        Label_StartDay_2         matlab.ui.control.Label
        StartDay_2               matlab.ui.control.DropDown
        Label_EndDay_2           matlab.ui.control.Label
        EndDay_2                 matlab.ui.control.DropDown
        Label_start_time_2       matlab.ui.control.Label
        start_time_2             matlab.ui.control.DropDown
        Label_end_time_2         matlab.ui.control.Label
        end_time_2               matlab.ui.control.DropDown
        Label_TdaysLabel_2       matlab.ui.control.Label
        TdaysLabel_2             matlab.ui.control.Label
        Label_timesLabel_2       matlab.ui.control.Label
        timesLabel_2             matlab.ui.control.Label
        Tab_3                    matlab.ui.container.Tab
        GridLayout_3             matlab.ui.container.GridLayout
        Grid_Times_3             matlab.ui.container.GridLayout
        Label_StartDay_3         matlab.ui.control.Label
        StartDay_3               matlab.ui.control.DropDown
        Label_EndDay_3           matlab.ui.control.Label
        EndDay_3                 matlab.ui.control.DropDown
        Label_start_time_3       matlab.ui.control.Label
        start_time_3             matlab.ui.control.DropDown
        Label_end_time_3         matlab.ui.control.Label
        end_time_3               matlab.ui.control.DropDown
        Label_TdaysLabel_3       matlab.ui.control.Label
        TdaysLabel_3             matlab.ui.control.Label
        Label_timesLabel_3       matlab.ui.control.Label
        timesLabel_3             matlab.ui.control.Label
        capacityEditFieldLabel   matlab.ui.control.Label
        capacityEditField        matlab.ui.control.NumericEditField
        weeklyRequirements       matlab.ui.container.Tab
        GridLayout10             matlab.ui.container.GridLayout
        weeklyrequirementsTable  matlab.ui.control.Table
        CookingTimesDeafult      matlab.ui.container.Tab
        GridLayout9              matlab.ui.container.GridLayout
        CookingTimesTable        matlab.ui.control.Table
        Cookingsize              matlab.ui.container.Tab
        GridLayout11             matlab.ui.container.GridLayout
        CookingSizesTable        matlab.ui.control.Table
        settings                 matlab.ui.container.Tab
        PDownTime                matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        addDownTime              matlab.ui.control.Button
        Start_time_label         matlab.ui.control.Label
        start_time_down          matlab.ui.control.DropDown
        End_time_label           matlab.ui.control.Label
        End_time_down            matlab.ui.control.DropDown
        Start_day_label          matlab.ui.control.Label
        Start_day_down           matlab.ui.control.DropDown
        Label_12                 matlab.ui.control.Label
        pot_list_down            matlab.ui.control.DropDown
        DownTimeTable            matlab.ui.control.Table
        MaintananceImage         matlab.ui.control.Image
        PPots                    matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        PotsTable                matlab.ui.control.Table
        removePot                matlab.ui.control.Button
        PotsImage                matlab.ui.control.Image
        addPot                   matlab.ui.control.Button
        PAddRecipe               matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        Raddrecipe               matlab.ui.control.Button
        Rremoverecipe            matlab.ui.control.Button
        RecImg                   matlab.ui.control.Image
        Label_9                  matlab.ui.control.Label
        Rserialnumber            matlab.ui.control.NumericEditField
        Label_11                 matlab.ui.control.Label
        Rname                    matlab.ui.control.EditField
        RcleaningT               matlab.ui.control.NumericEditField
        RcleaningT_Label         matlab.ui.control.Label
        RcleaningT_dur           matlab.ui.control.DropDown
        RcookingT_Label          matlab.ui.control.Label
        RcookingT                matlab.ui.control.NumericEditField
        RcookingT_Label_2        matlab.ui.control.Label
        PCleaning                matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        AllowCIP                 matlab.ui.control.CheckBox
        DescriptionCIP           matlab.ui.control.Label
        AllowClean1200           matlab.ui.control.CheckBox
        Description1200          matlab.ui.control.Label
        CIP_Time                 matlab.ui.control.NumericEditField
        CIP_min_hour             matlab.ui.control.DropDown
        Time_1200                matlab.ui.control.NumericEditField
        min_hour_1200            matlab.ui.control.DropDown
        AllowWashTime            matlab.ui.control.CheckBox
        DescriptionWashTime      matlab.ui.control.Label
        WashTime                 matlab.ui.control.NumericEditField
        WashTime_min_hour        matlab.ui.control.DropDown
        DOCIP_min_hour_2         matlab.ui.control.DropDown
        DOCIP_Time_2             matlab.ui.control.NumericEditField
        DOTime_1200_Time_2       matlab.ui.control.NumericEditField
        DOTime_1200_min_hour_2   matlab.ui.control.DropDown
        DO_afterT                matlab.ui.control.Label
        FOR_T                    matlab.ui.control.Label
        FloorHrs                 matlab.ui.container.Panel
        GridLayout8              matlab.ui.container.GridLayout
        SUN_label                matlab.ui.control.Label
        SUN_time                 matlab.ui.control.DropDown
        THUR_label               matlab.ui.control.Label
        THUR_time                matlab.ui.control.DropDown
        SAT                      matlab.ui.control.CheckBox
        SAT_label                matlab.ui.control.Label
        FRI_label                matlab.ui.control.Label
        FRI                      matlab.ui.control.CheckBox
        FRI_START_label          matlab.ui.control.Label
        FRI_START                matlab.ui.control.DropDown
        FRI_END_label            matlab.ui.control.Label
        FRI_END                  matlab.ui.control.DropDown
        SAT_START_label          matlab.ui.control.Label
        SAT_START                matlab.ui.control.DropDown
        SAT_END_label            matlab.ui.control.Label
        SAT_END                  matlab.ui.control.DropDown
        Weekly_label             matlab.ui.control.Label
        WEEKLY                   matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        model % calculation model
        MaxIt % number of iterations untill achiving the final setup.
        vols % structure of the volumes of the pots
        data % all the input data
        
        start_day_1; % start time of the week after convertion pot 1
        start_day_2; % start time of the week after convertion pot 2
        start_day_3; % start time of the week after convertion pot 3
        
        end_day_1; % end time of the week after convertion pot1
        end_day_2; % end time of the week after convertionpot 2
        end_day_3; % end time of the week after convertion pot 3
        
        days_1; %number of cooking days avilable pot 1
        days_2; %number of cooking days avilable pot 2
        days_3; %number of cooking days avilable pot 3
        times_1; %total number of hr of cooking in the week pot 1
        times_2; %total number of hr of cooking in the week pot 2
        times_3; %total number of hr of cooking in the week pot 3
        capacitySetting %capacity setting time for all factory
        
        startcooking; %formal start cooking time for the week
        stopcooking; %formal end cooking time for the week
        withFRI; % consider Friday
        withSAT; % consider Saturday
        extraTime % addSat hr to SUN
        
        cleaning_table % table of cleaning times
        cleaningInfo % struct with information about the cleaning matrix
        
        mainpath; % main folder of this app
        outFile % name of output file
        cookingpath % path to cooking time
        cookingreq % path to cooking restrictions
        
        default_clean; % base cleaning time between recipes
        default_CIP_clean; % CIP cleaning time
        default_CIP_dur; % CIP cleaning frequency
        default_1200_clean; % CIP cleaning time for 1200 pot
        default_1200_dur; %CIP cleaning frequency for 1200 pot
        
        TotalRecipes; %% when adding new recipies need to update the row number
        oats; %% mark special outs recipes that have to be cooked on wed/thur for Kosher
        
        JoinOats;
        firstOats % original Index number of oats recipes in x.Position vector.
        
        printingCounter % extructs the information for each pot
    end
    
    
    methods (Access = private)
        
        %% this function imports the the cooking table from EXCEL file
        function cooking_table = get_cooking_time(app,path2file)
            %% this function imports the the cooking table from EXCEL file
            % New_files\בישול  פרי נתונים.xlsx';
            
            %% Input handling
            sheetName = 1;
            dataLines = [2, app.TotalRecipes]; %% when adding new recipies need to update the row number
            
            %% Setup the Import Options and import the data
            opts = spreadsheetImportOptions("NumVariables", 3);
            
            % Specify sheet and range
            opts.Sheet = sheetName;
            opts.DataRange = "A" + dataLines(1, 1) + ":C" + dataLines(1, 2);
            
            % Specify column names and types
            opts.VariableNames = ["Serial_number", "Recipe", "Cooking_time"];
            opts.VariableTypes = ["double", "string", "double"];
            
            % Specify variable properties
            opts = setvaropts(opts, "Recipe", "WhitespaceRule", "preserve");
            opts = setvaropts(opts, "Recipe", "EmptyFieldRule", "auto");
            
            % Import the data
            cooking_table = readtable(path2file, opts, "UseExcel", false);
            
            for idx = 2:size(dataLines, 1)
                opts.DataRange = "A" + dataLines(idx, 1) + ":C" + dataLines(idx, 2);
                tb = readtable(path2file, opts, "UseExcel", false);
                cooking_table = [cooking_table; tb]; %#ok<AGROW>
            end
            
        end
        
        
        function Portions = getportions(app,workbookFile)
            %IMPORTFILE Import data from a spreadsheet
            
            %  Example:
            %  Portions = importfile("\בישול  פרי נתונים.xlsx", "Sheet1", [2, 48]);
            
            %% Input handling
            sheetName = 1;
            dataLines = [2, app.TotalRecipes]; %% when adding new recipies need to update the row number
            
            %% Setup the Import Options and import the data
            opts = spreadsheetImportOptions("NumVariables", 11);
            
            % Specify sheet and range
            opts.Sheet = sheetName;
            opts.DataRange = "A" + dataLines(1, 1) + ":K" + dataLines(1, 2);
            
            % Specify column names and types
            opts.VariableNames = ["Serial_number", "Var2", "Var3", "P300", "P400", "P450", "P500", "P600", "P800","P900", "P1200"];
            opts.SelectedVariableNames = ["Serial_number", "Var2","P300", "P400", "P450", "P500", "P600", "P800", "P900","P1200"];
            opts.VariableTypes = ["double", "char", "char", "double", "double", "double", "double", "double", "double","double", "double"];
            
            % Specify variable properties
            opts = setvaropts(opts, "Var3", "WhitespaceRule", "preserve");
            opts = setvaropts(opts,  "Var3", "EmptyFieldRule", "auto");
            
            % Import the data
            Portions = readtable(workbookFile, opts, "UseExcel", false);
            
            for idx = 2:size(dataLines, 1)
                opts.DataRange = "A" + dataLines(idx, 1) + ":K" + dataLines(idx, 2);
                tb = readtable(workbookFile, opts, "UseExcel", false);
                Portions = [Portions; tb]; %#ok<AGROW>
            end
            
        end
        
        
        %% this function imports the the cleaning table from EXCEL file
        function cleaning_table = import_cleaning_table(app,workbookFile)
            %% this function imports the the cleaning table from EXCEL file
            % \אילוצים לנקיונות.xlsx
            
            %% Input handling
            sheetName = 1;
            dataLines = [2, app.TotalRecipes]; %% when adding new recipies need to update the row number
            
            %% Setup the Import Options and import the data
            opts = spreadsheetImportOptions("NumVariables", 5);
            
            % Specify sheet and range
            opts.Sheet = sheetName;
            opts.DataRange = "A" + dataLines(1, 1) + ":E" + dataLines(1, 2);
            
            % Specify column names and types
            opts.VariableNames = ["Serial_number", "Recipe", "Cleaning_time", "Pair_cooking", "Recomendation"];
            opts.VariableTypes = ["double", "string", "double", "double", "double"];
            
            % Specify variable properties
            opts = setvaropts(opts, "Recipe", "WhitespaceRule", "preserve");
            opts = setvaropts(opts, "Recipe", "EmptyFieldRule", "auto");
            
            % Import the data
            cleaning_table = readtable(workbookFile, opts, "UseExcel", false);
            
            for idx = 2:size(dataLines, 1)
                opts.DataRange = "A" + dataLines(idx, 1) + ":E" + dataLines(idx, 2);
                tb = readtable(workbookFile, opts, "UseExcel", false);
                cleaning_table = [cleaning_table; tb]; %#ok<AGROW>
            end
            
        end
        
        %% this Function convert FULL_PLAN into weekly plan
        function import_and_process_main(app)
            %% this Function convert FULL_PLAN into weekly plan and reads it as a table.
            
            %% get the main file
            [name,path] = uigetfile('*.xlsx','בחר קובץ ראשי של דרישות שבועייות','MultiSelect','off');
            path2file = fullfile(path,name);
            %path2file = 'C:\Users\amitk\OneDrive - Systematics LTD\Desktop\Amit\Consults\Strauss - Copy\Full_plan\FULL_PLAN.xlsx';
            figure(app.MainApp);
            %read table only for relevent part - not changing therefor set
            %Range for reading.
            sheets = sheetnames(path2file);
            if any(ismember(sheets,'נוכחי'))
                Ttype = 0;
                tbl = readtable(path2file,'Range','C252:T313','Sheet','נוכחי');
            else
                Ttype = 1;
                tbl = readtable(path2file);
            end
            if  Ttype == 0
                %% extract rows only with serial number of recipe
                serial_number = tbl{:,end};
                serial_idx = ~isnan(serial_number);
                tbl = tbl(serial_idx,:);
                
                %% eliminate nan cols
                num_recipe = size(tbl,1);
                cells = {3,3,3,3,2};
                cooking_days = {'wed','tue','mon','sun','thur'};
                
                % create a table to store the weekly plan
                weekly_req = table('Size',[num_recipe 7],'VariableTypes',{'double','double',...
                    'double','double','double','string','double'},'VariableNames',[cooking_days {'Recipe','Serial_number'}]);
                flag = 1;
                
                %% find relevent cols with amounts
                for i = 1:length(cells)
                    rangeCell = sum(cell2mat(cells(1:i)));
                    temp = tbl(:,flag:rangeCell);
                    eliminate = sum(ismissing(temp)) == num_recipe;
                    temp(:,eliminate) = [];
                    if size(temp,2)>1
                        weekly_req{:,i} = sum(temp{:,:},2,'omitnan');
                    else % all amounts are in one col - no need to join
                        weekly_req(:,i) = temp(:,1);
                    end
                    flag = flag +cells{i};
                end
                % get Serial Number col
                weekly_req(:,'Serial_number') =  tbl(:,end);
                
                % get Recipe col
                weekly_req(:,'Recipe') =  tbl(:,end-2);
                
                %% reorder the final table
                order = [{'Serial_number','Recipe'} ...
                    cooking_days{5} cooking_days{4} cooking_days{3}...
                    cooking_days{2} cooking_days{1}];
                weekly_req = weekly_req(:,order);
                %% store a copy of weekly req
                cd(app.mainpath)
                writetable(weekly_req,fullfile(app.mainpath,'weeklyRequirements.xlsx'));
                app.weeklyrequirementsTable.Data = weekly_req;
            else
                cd(app.mainpath)
                tbl(:,3:end) = fillmissing(tbl(:,3:end),'constant',0);
                % find multiple rows of same recipe:
                [~,locb] = ismember(tbl.Serial_number,unique(tbl.Serial_number));
                for line =1:length(unique(tbl.Serial_number))
                    temptbl = tbl(locb == line,:);
                    if height(temptbl)>1
                        %only if i have multiple rows
                        newline = temptbl(1,:);
                        newline{:,3:end} = newline{:,3:end} + sum(temptbl{2:end,3:end},1);
                        tbl(locb == line,:) = [];
                        tbl = [tbl;newline]; %#ok<AGROW>
                    end
                    [~,locb] = ismember(tbl.Serial_number,unique(tbl.Serial_number));
                end
                
                weekly_req = tbl;
                app.weeklyrequirementsTable.Data = weekly_req;
                
            end
        end
        
        %% this function gets the pots list from the setting tab
        function setVolumes(app)
            %% this function gets the pots list from the setting tab
            
            % convert table to list to remove NAN values
            pots_list = cell2mat(app.PotsTable.Data);
            pots_list = pots_list(~isnan(pots_list));
            
            % get unique volumes of the pots
            uniqueVol = unique(pots_list)';
            app.vols.v = uniqueVol;
            
            % count the number of pots in each volume
            app.vols.n = zeros(size(uniqueVol));
            for v = 1:numel(uniqueVol)
                app.vols.n(v) = nnz(pots_list==app.vols.v(v));
            end
            
        end
        
        %% this function converts hebrew days into numbers
        function setdays(app,iPOT)
            %% this function converts hebrew days into numbers
            % convert start day
            if iPOT~=-1
                value=app.(['StartDay_' num2str(iPOT)]).Value;
                list = app.(['StartDay_' num2str(iPOT)]).Items;
                [~,app.(['start_day_' num2str(iPOT)])] =ismember(value,list);
                % convert end day
                value=app.(['EndDay_' num2str(iPOT)]).Value;
                list = app.(['EndDay_' num2str(iPOT)]).Items;
                [~,app.(['end_day_' num2str(iPOT)])] =ismember(value,list);
                % calculate cooking duration for the week
                set_up_workweek(app,iPOT)
            else
                for ii = 1:3
                    value=app.(['StartDay_' num2str(ii)]).Value;
                    list = app.(['StartDay_' num2str(ii)]).Items;
                    [~,app.(['start_day_' num2str(ii)])] =ismember(value,list);
                    % convert end day
                    value=app.(['EndDay_' num2str(ii)]).Value;
                    list = app.(['EndDay_' num2str(ii)]).Items;
                    [~,app.(['end_day_' num2str(ii)])] =ismember(value,list);
                    % calculate cooking duration for the week
                    set_up_workweek(app,ii)
                end
            end
            
        end
        
        %% this function calculates the total number of cooking days and hours
        function set_up_workweek(app,iPOT)
            %% this function calculates the total number of cooking days and hours
            % start with finding the full duration of the cooking days:
            if app.(['end_day_' num2str(iPOT)])>app.(['start_day_' num2str(iPOT)]) % starting from sunday and so on.
                quest = 'האם מדובר על אותו שבוע בישול';dlgtitle = 'הגדרת זמני בישול שבועיים';
                btn1='כן - אותו שבוע';btn2='לא';
                defbtn=btn1;
                answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
                if strcmp(answer,btn2)
                    [Tdays1,Ttimes1] = main_calc(app,app.(['start_day_' num2str(iPOT)]),...
                        5,str2num(app.(['start_time_' num2str(iPOT)]).Value),app.stopcooking); %#ok<*ST2NM>
                    [Tdays2,Ttimes2] = main_calc(app,1,app.(['end_day_' num2str(iPOT)]),...
                        app.startcooking,str2num(app.(['end_time_' num2str(iPOT)]).Value));
                    Tdays = Tdays1+Tdays2;
                    Ttimes = Ttimes1+Ttimes2;
                else
                    [Tdays,Ttimes] = main_calc(app,app.(['start_day_' num2str(iPOT)]),app.(['end_day_' num2str(iPOT)]),...
                        str2num(app.(['start_time_' num2str(iPOT)]).Value),str2num(app.(['end_time_' num2str(iPOT)]).Value));
                end
                
            elseif app.(['end_day_' num2str(iPOT)])==app.(['start_day_' num2str(iPOT)]) % one day of cooking
                quest = 'האם מדובר ביום בישול יחיד?';dlgtitle = 'הגדרת זמני בישול שבועיים';
                btn1='כן - יום אחד';btn2='לא- שבוע מלא';
                defbtn=btn2;
                answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
                if strcmp(answer,btn2)
                    [Tdays1,Ttimes1] = main_calc(app,app.(['start_day_' num2str(iPOT)]),...
                        5,str2num(app.(['start_time_' num2str(iPOT)]).Value),app.stopcooking); %#ok<*ST2NM>
                    [Tdays2,Ttimes2] = main_calc(app,1,app.(['end_day_' num2str(iPOT)]),...
                        app.startcooking,str2num(app.(['end_time_' num2str(iPOT)]).Value));
                    Tdays = Tdays1+Tdays2;
                    Ttimes = Ttimes1+Ttimes2;
                else
                    Tdays = 1;
                    Ttimes = str2num(app.(['end_time_' num2str(iPOT)]).Value)-str2num(app.(['start_time_' num2str(iPOT)]).Value);
                end
            elseif app.(['end_day_' num2str(iPOT)])<app.(['start_day_' num2str(iPOT)]) % starting from the weekend and continue into the week
                [Tdays,Ttimes] = main_calc(app,app.(['start_day_' num2str(iPOT)]),...
                    5,str2num(app.(['start_time_' num2str(iPOT)]).Value),app.stopcooking); %#ok<*ST2NM>
                if app.(['end_day_' num2str(iPOT)]) == 1
                    % add sunday:
                    Tdays = Tdays+1;
                    Ttimes = Ttimes + addAday(app,app.startcooking,str2num(app.(['end_time_' num2str(iPOT)]).Value)); %considering starting to cook on sunday at 06:00
                else
                    %add two and more days:
                    [days2,times2] = main_calc(app,1,app.(['end_day_' num2str(iPOT)]),app.startcooking,str2num(app.(['end_time_' num2str(iPOT)]).Value));%considering starting to cook on sunday at 06:00
                    Tdays = Tdays+days2;
                    Ttimes = Ttimes +times2;
                end
            end
            %             %% check times and days
            %             n = Ttimes/24;
            %             if n>Tdays
            %                 Tdays =ceil(n);
            %             end
            % assign days and times to the app properties
            app.(['days_' num2str(iPOT)]) = Tdays;
            app.(['times_' num2str(iPOT)]) = Ttimes;
            
            %check to see if there is Down Time: %% TODO!!
            if ~isempty(app.DownTimeTable.Data)
                %check if iPOT appears in list of down time
                % Add a function here
            end
            
            app.(['TdaysLabel_' num2str(iPOT)]).Text = num2str(app.(['days_' num2str(iPOT)]));
            app.(['timesLabel_' num2str(iPOT)]).Text=num2str(app.(['times_' num2str(iPOT)]));
        end
        
        %% this function is an inner function of set_up_workweek - will calculate the days and times of given inputs
        function [days,times] = main_calc(~,start_day,end_day,start_time,end_time)
            %% this function is an inner function of set_up_workweek - will calculate thr days and times of given inputs
            days = end_day-start_day+1;
            times = (days-1)*24+(24-(24-end_time)-start_time);
            %days = ceil(times/24);
        end
        
        %% this function is an inner function of set_up_workweek - will calculate the extra time for adding days
        function times = addAday(~,start_time,end_time)
            %% this function is an inner function of set_up_workweek - will calculate the extra time for adding days
            times = 24 - start_time - (24-end_time);
        end
        
        %% this function extructs the cleaning times by the settings
        function getCIPtimes(app)
            %% this function extructs the cleaning times by the settings
            
            %% SETUP TIME
            if app.WashTime_min_hour.Value == 'דקות' %#ok<BDSCA>
                app.default_clean = app.WashTime.Value/60;
            else
                app.default_clean = app.WashTime.Value;
            end
            %% CIP
            if app.CIP_min_hour.Value == 'דקות' %#ok<BDSCA>
                app.default_CIP_clean = app.CIP_Time.Value/60;
            else
                app.default_CIP_clean = app.CIP_Time.Value;
            end
            if app.DOCIP_min_hour_2.Value == 'דקות' %#ok<BDSCA>
                app.default_CIP_dur = app.DOCIP_Time_2.Value/60;
            else
                app.default_CIP_dur = app.DOCIP_Time_2.Value;
            end
            %% CIP - 1200
            if app.min_hour_1200.Value == 'דקות' %#ok<BDSCA>
                app.default_1200_clean= app.Time_1200.Value/60;
            else
                app.default_1200_clean = app.Time_1200.Value;
            end
            if app.DOCIP_min_hour_2.Value == 'דקות' %#ok<BDSCA>
                app.default_1200_dur = app.DOTime_1200_Time_2.Value/60;
            else
                app.default_1200_dur = app.DOTime_1200_Time_2.Value;
            end
        end
        
        %% this is the main function of the app - the output will be a struct with the weekly set up
        function allSol = main_function(app)
            allSol= struct;
            
            %% Problem Definition
            
            %get all numeric parameters of 'sample work plan' in cols.
            idx = cell2mat(app.data{1,1}(:,2));
            data_1_1 = idx;
            % looking for NAN values(!?) - switching to zeros.
            data_1_1(isnan(data_1_1))=0;
            % get all cooking times (numeric only)
            data_1_3 = cell2mat(app.data{1,3}(:,2));
            % replace all nan values to zeros.
            data_1_3(isnan(data_1_3))=0;
            
            % get the number of recipes in this weeklyReq
            RecipeNum = app.cleaningInfo.RecNum';
            RecipeNum = repmat(RecipeNum,(length(idx)/length(RecipeNum)),1);
            %             weekly = app.weeklyrequirementsTable.Data;
            
            %% set up the model parameters
            % I = number of total recipes to be cooked this week:
            app.model.I = nnz(data_1_1);
            
            % extract the amount's values from the 'sample work plan' in cols.
            app.model.amount=cell2mat(app.data{1,1}(data_1_1>0,2))';
            
            % number of repetitions for each recipe * cooking time == total
            % cooking time
            app.model.r = ceil(app.model.amount./600).*data_1_3(data_1_1>0)';
            
            % setup a cleaning time vector
            app.model.d0=zeros(1,app.model.I);
            
            % get cleaning time only for the recipes of this week
            app.model.RecNum = RecipeNum(data_1_1>0);
            
            % setup a cleaning time matrix only for this weeks recipes
            app.model.d=app.cleaningInfo.matrix(app.model.RecNum,app.model.RecNum);
            
            % get full recipes list for the week
            app.model.Recipes = (app.data{1,1}(data_1_1>0,1));
            
            % get Recipe's serial number
            app.model.SerialN = app.CookingTimesTable.Data(:,1);
            
            %% Devide model by pots:
            modelPerVol = divide_by_vol(app,data_1_1,data_1_3);
            
            %% set model per volume
            for v = 1: numel(app.vols.v)
                Pot = app.vols.v(v);
                
                %% Initialization
                my_model = modelPerVol.(['P_' num2str(Pot)]);
                % Create Initial Solution
                n = my_model.I+my_model.J-1;
                x.Position=randperm(n);
                if Pot ==600
                    %check if oats in the group as should:
                    app.firstOats = app.model.original_index(:,my_model.serialOats2);
                    
                    
                    [~,app.firstOats] =intersect(my_model.original,app.firstOats);
                    
                    if ~isempty(app.firstOats)
                        app.JoinOats = app.firstOats ;
                    else
                        app.JoinOats = [];
                    end
                    switchI = [];
                    if  ~isempty(app.JoinOats)
                        %for 600 pots - all oat recipes must be in the same pot
                        [~,divitionI] = intersect(x.Position,n);
                        [~,oatLoc] = intersect(x.Position,app.JoinOats);
                        if sum(oatLoc<divitionI) == numel(app.JoinOats)
                        else
                            grpI = oatLoc<divitionI;
                            grp1 = oatLoc(grpI);
                            grp2 = oatLoc(~grpI);
                            
                            if numel(grp2)<numel(grp1)
                                %switch indexes to group all oats into one pot
                                switchI = randperm(divitionI-1,numel(grp2));
                                while ~isempty(intersect(switchI,[grp1 ;divitionI]))
                                    switchI = randperm(divitionI-1,numel(grp2));
                                end
                                swap_group = grp2;
                            else % numel(grp1)<numel(grp2)
                                %switch indexes to group all oats into one pot
                                switchI = randi([divitionI,n],[1 numel(grp1)]);
                                while ~isempty(intersect(switchI,[grp2 ;divitionI]))
                                    switchI = randi([divitionI,n],[1 numel(grp1)]);
                                end
                                swap_group = grp1;
                            end
                        end
                        if ~isempty(switchI)
                            %swap places of indexes according to switchI
                            swap_original = x.Position(switchI);
                            swap_groupB = x.Position(swap_group);
                            x.Position(switchI) = swap_groupB;
                            x.Position(swap_group) = swap_original;
                        end
                        
                    end
                    % move lastouts to the end of the cooking always
                    x.Position = groupOats(app,x.Position,my_model);
                end
                
                [x.Cost, x.Sol]=MyCost(app,x.Position,my_model);
                
                % Update Best Solution Ever Found
                BestSol=x;
                
                % Array to Hold Best Cost Values - while initial cost is inf
                BestCost_vec=zeros(app.MaxIt,1);
                
                %% Main Loop of Maxit to find best solution of divition of recipes
                BestCost = x.Cost;
                for it=1:app.MaxIt
                    % Create Neighbor
                    xnew.Position=CreateNeighbor(app,x.Position,Pot,my_model);
                    %xnew.Position=CreateNeighbor(app,x.Position);
                    [xnew.Cost, xnew.Sol]=MyCost(app,xnew.Position,my_model);
                    if xnew.Cost<BestCost
                        % xnew is better, so it is accepted
                        BestSol=xnew;
                        BestCost=BestSol.Cost;
                    end
                    x=xnew;
                    BestCost_vec(it)=BestCost;
                end
                %BestSol
                %% Generate Output
                list_of_products = my_model.Recipes;
                list_of_iterations = my_model.Niteration;
                for i=1:length(BestSol.Sol.L)
                    BestSol.Sol.L{i,2}=list_of_products(BestSol.Sol.L{i,1});
                    BestSol.Sol.iter = list_of_iterations(BestSol.Sol.L{i,1});
                end
                BestSol.modelInfo = my_model;
                %eval(sprintf('allSol.BestSol_%d=BestSol;',Pot));
                allSol.(['BestSol_' num2str(Pot)]) =BestSol;
            end
            
        end
        
        %% this function simulates a solution oforder in the recipes per volume of pot.
        function [z,sol]=MyCost(app,q,model)
            %% this function simulates a solution oforder in the recipes per volume of pot.
            I=model.I;
            J=model.J;
            d=model.d;
            d0=model.d0;
            r=model.r;
            amount=model.amount;
            
            % get the cooking elements per day set from 1 to 5 as priorities
            priorities = [ones(1,sum(cell2mat(app.data{1, 4}(:,3))>0)),...
                2*ones(1,sum(cell2mat(app.data{1, 4}(:,4))>0)),...
                3*ones(1,sum(cell2mat(app.data{1, 4}(:,5))>0)),...
                4*ones(1,sum(cell2mat(app.data{1, 4}(:,6))>0)),...
                5*ones(1,sum(cell2mat(app.data{1, 4}(:,7))>0))];
            priorities = priorities(model.index);
            
            % get q>I - get positions that are above the I number of recipes
            DelPos=find(q>I);
            From=[0 DelPos]+1;
            To=[DelPos I+J]-1;
            
            %% preallocate solution space by number of pots of the same kind
            L=cell(J,1);
            D=zeros(1,J);% Cumulative sum of setup times of each group
            UC=zeros(1,J);% cumulative sum of cooking time without cleaning time
            
            sol.D_vec = cell(1,J);
            sol.UC_vec = cell(1,J);% cooking time for each recipe
            sol.UC_D_vec = cell(1,J); % SUM[(setup time + cooking time)]_(for all recipes )
            sol.UC_D_vec_cumulative = cell(1,J);
            sol.amount_vec = cell(1,J);% amount vector for each recipe
            
            for j=1:J
                L{j}=q(From(j):To(j));
                L{j} = sort_by_prio(L{j},priorities);
                if ~isempty(L{j})
                    % D is the cumulative sum of the SETUP times between elements.
                    D(j)=d0(L{j}(1)); % idx = first element of L{j} , d0 = zeros list in the first round.
                    for k=1:numel(L{j})-1 % run on the pairs.
                        % get the set up value of the pairs in the q sub-list
                        D(j)=D(j)+d(L{j}(k),L{j}(k+1)); %d is the setup matrix for pairs
                        sol.D_vec{j} = [sol.D_vec{j},d(L{j}(k),L{j}(k+1))];
                    end
                    
                    D(j)=D(j)+d0(L{j}(end));
                    sol.D_vec{j} = [sol.D_vec{j},d0(L{j}(end))];
                    UC(j)=sum(r(L{j}));
                    sol.UC_vec{j} = r(L{j});
                    sol.amount_vec{j} = amount(L{j});
                end
            end
            
            sol.L=L;
            sol.D=D;
            sol.MaxD=max(D); % max of cumulative sum of setuptimes
            sol.TotalD=sum(D); % sum of all cleaning times
            sol.UC=UC;
            
            sol.UC_D_vec = cellfun(@plus,sol.D_vec,sol.UC_vec,'UniformOutput',false);
            sol.UC_D_vec_cumulative = cellfun(@cumsum,sol.UC_D_vec,'UniformOutput',false); %Cumsum(UC_D_VEC)
            z=max(sol.D+sol.UC);
            
            %% inner function to set vector by requirment days
            function sorted_vector = sort_by_prio(vector, priorities)
%                 beforweekend = app.cleaning_table{vector,end}; % get recommendation for when to cook
                vector_prior = priorities(vector);% get the priorities for the recipes of the group
                % check if any of the recipes should be cooked after sunday
%                 beforweekend_prior = vector_prior(logical(~beforweekend));
%                 idx = beforweekend_prior<=2;
%                 if any(idx)
%                     loc_b = find(~beforweekend);
%                     loc_a = loc_b(idx);
%                     vector_prior(loc_a) = vector_prior(loc_a)+1;
%                 end
                indexes_by_prior = [find(vector_prior==1),find(vector_prior==2),find(vector_prior==3),find(vector_prior==4),find(vector_prior==5)];
                sorted_vector = vector(indexes_by_prior);
            end
            
        end
        
        %% this function reorders the order list of the recipes
        function qnew=CreateNeighbor(app,q,flag,my_model)
            %qnew=CreateNeighbor(app,q,flag,my_model)
            %% this function reorders the order list of the recipes
            m=randi([1 3]);
            
            n=numel(q); %return the number of elements in vector q
            i=myrandomsample(app,n,2); %returns k(=2) values sampled uniformly at random, without replacement, from the integers 1 to n.
            
            switch m
                case 1
                    % Do Swap
                    
                    i1=i(1);
                    i2=i(2);
                    
                    qnew=q;
                    qnew([i1 i2])=q([i2 i1]);
                    
                case 2
                    % Do Reversion
                    
                    i1=min(i(1),i(2));
                    i2=max(i(1),i(2));
                    
                    qnew=q;
                    qnew(i1:i2)=q(i2:-1:i1);
                    
                case 3
                    % Do Insertion
                    
                    i1=i(1);
                    i2=i(2);
                    
                    if i1<i2
                        qnew=[q(1:i1-1) q(i1+1:i2) q(i1) q(i2+1:end)];
                    else
                        qnew=[q(1:i2) q(i1) q(i2+1:i1-1) q(i1+1:end)];
                    end
            end
            
            if isequal(flag,600)
                qnew = groupOats(app,qnew,my_model);
            end
            
            %% inner function - myrandomsample Random sample,  without replacement.
            function y = myrandomsample(app,n, k)
                %myrandomsample Random sample,  without replacement.
                %   Y = myrandomsample(n,k) returns Y as a column vector of K values sampled
                %   uniformly at random, without replacement, from the integers 1:N.
                %   Examples:
                %
                %   Draw a single value from the integers 1:10.
                %      n = 10;
                %      x = myrandomsample(n,1);
                
                % Process the stream argument, if present
                defaultStream = true;
                w = [];
                
                % Sample without replacement
                if k > n
                    error('K must be less than or equal %d for sampling without replacement', n);
                end
                if isempty(w)
                    % If the sample is a sizable fraction of the population,
                    % just randomize the whole population (which involves a full
                    % sort of n random values), and take the first k.
                    if 4*k > n
                        if defaultStream
                            rp = randperm(n);
                            
                        end
                        
                        y = rp(1:k);
                        %make sure that y is not the oats
                        if ~isempty(app.JoinOats)
                            ind = ismember(app.JoinOats,y);
                            while sum(ind)>0
                                rp = randperm(n);
                                y = rp(1:k);
                                ind = ismember(app.JoinOats,y);
                            end
                        end
                        
                        % If the sample is a small fraction of the population, a full sort
                        % is wasteful.  Repeatedly sample with replacement until there are
                        % k unique values.
                    else
                        x = zeros(1,n); % flags
                        sumx = 0;
                        while sumx < floor(k) % prevent infinite loop when 0<k<1
                            if defaultStream
                                x(randi(n,1,k-sumx)) = 1; % sample w/replacement
                            end
                            sumx = sum(x); % count how many unique elements so far
                        end
                        y = find(x > 0);
                        if defaultStream
                            y = y(randperm(k));
                        end
                        %make sure that y is not the oats
                        if ~isempty(app.JoinOats)
                            ind = ismember(app.JoinOats,y);
                            while sum(ind)>0
                                x = zeros(1,n); % flags
                                sumx = 0;
                                while sumx < floor(k) % prevent infinite loop when 0<k<1
                                    if defaultStream
                                        x(randi(n,1,k-sumx)) = 1; % sample w/replacement
                                    end
                                    sumx = sum(x); % count how many unique elements so far
                                end
                                y = find(x > 0);
                                if defaultStream
                                    y = y(randperm(k));
                                end
                                ind = ismember(app.JoinOats,y);
                            end
                        end
                        
                    end
                end
                
                y = y(:);
                
            end
            
        end
        
        %% this function divides the recipes between the Pots based on their volume.
        function modelP = divide_by_vol(app,data_1_1,data_1_3)
            %% this function divides the recipes between the Pots based on their volume.
            
            % extract the amounts vector
            amounts = app.model.amount;
            % count the total number of cooking recipes for the week
            num_of_recipes = numel(amounts);
            % get the total numer of pots
            Npots = numel(app.vols.n);
            
            
            %% Input Checks:
            if Npots==1
                Pot = app.vols.v(1);
                app.model.original_index = 1:app.model.I;
                [mods,index,iteration,Allvols] = make_ratios_mods();
                modelP.(['P_' num2str(Pot)]) = make_model(1);
                return
            end
            if isempty(Npots) || isnan(Npots) || Npots ==0
                error('קיימת בעיה באתחול הסירים, נא לבדוק בהגדרות ולחשב מחדש')
            end
            if Npots>1
                %get ratio of pots
                P_ratio = app.vols.n/sum(app.vols.n);
            end
            
            %% get ratios/mods per volumn of pot:
            app.model.original_index = 1:app.model.I;
            
            %% calculate the mod and ratio of the amounts/volume of pot
            [mods,index,iteration,Allvols] = make_ratios_mods(); %ratios- old input
            
            %% check divition
            for j = 1:Npots
                Pot = app.vols.v(j);
                %% set a temp struct for the model of each pot seperatly
                modelP.(['P_' num2str(Pot)])=make_model(j);
            end
            %% Calculate the total time of cooking + basic cleaning for assesment
            total_time = calc_total_time();


            %% check divition by Pot's ratio: app.vols.n/sum(app.vols.n);
            
            T_ratio = total_time(2,:)/sum(total_time(2,:));
            if T_ratio(1)<P_ratio(1)
                while T_ratio(1)<P_ratio(1)
                    if round(P_ratio(1)-T_ratio(1),2)>0.05
                        % transfer recipes to even the times
                        get_min = ismember(modelP.P_1200.mods,min(modelP.P_1200.mods(modelP.P_1200.mods>0)));
                        i_switch = modelP.P_1200.original(get_min);
                        if isnan(Allvols(1,i_switch(1)))
                            modelP.P_1200.mods(find(get_min,1)) = 0;
                            continue;
                        else
                        index(i_switch(1))=1;
                        for j = 1:Npots
                            Pot = app.vols.v(j);
                            modelP.(['P_' num2str(Pot)])=make_model(j);
                        end
                        end

                        total_time = calc_total_time();
                        T_ratio = total_time(2,:)/sum(total_time(2,:));
                    else
                        break
                    end
                end
            else
                while T_ratio(2)<P_ratio(2)
                    if round(P_ratio(2)-T_ratio(2),2)>0.05
                        % transfer recipes to even the times
                        get_min = ismember(modelP.P_600.mods,min(modelP.P_600.mods(modelP.P_600.mods>0)));
                        %% check if the recipe is oats:
                        if any(ismember(app.oats,modelP.P_600.SerialN(get_min)))
                            modelP.P_600.mods(get_min) = 0;
                            continue;
                        end
                        i_switch = modelP.P_600.original(get_min);
                        if isnan(Allvols(2,i_switch(1)))
                            modelP.P_600.mods(find(get_min,1)) = 0;
                            continue;
                        else
                        index(i_switch(1))=2;
                        for j = 1:Npots
                            Pot = app.vols.v(j);
                            modelP.(['P_' num2str(Pot)])=make_model(j);
                        end
                        end
                        total_time = calc_total_time();
                        T_ratio = total_time(2,:)/sum(total_time(2,:));
                    else
                        break
                    end
                end
            end
            %% nested functions
            
            %% this function calculates the mod and ratio of the amounts/volume of pot
            function [mods,index,iteration,Allvols]=make_ratios_mods() %ratios- old input
                %% this function calculates the mod and ratio of the amounts/volume of pot
%                  Portions = getportions(app,'בישול  פרי נתונים.xlsx');
                Portions = app.CookingSizesTable.Data;
                special = app.oats;
                
                % get the order from app;
                serialN = app.model.SerialN;
                [~,locB] = ismember(serialN{:,1},Portions{:,1});
                % set up weekly table of portions.
                Portions = Portions(locB(locB>0),:);
                Portions(:,2) = [];
                weekP = [Portions ; Portions;Portions;Portions;Portions];
                % get amounts for cooking 230*1;
                Totalamounts = data_1_1;
                % make sure that the order is ok, all recipes match.
                %isequal(Portions(:,1),serialN);
                rec = nnz(Totalamounts>0);
                % set Portions Sizes - all possibilities
                Psize = [300, 400 , 450 ,500 , 600, 800 , 900,1200];
                % set the matching Pot for the portion.
                Npot = [1, 1, 1,1, 1, 2, 2,2];
                %% actual iterations by amounts of recipes and allowed portions
                % get the number of itterations for each amount for this week
                
                actual_Portions = Totalamounts./Psize; %ratio table
                actual_Portions = actual_Portions.*(weekP{:,2:end});
                actual_mods = mod(Totalamounts,Psize); %ratio table
                actual_mods = actual_mods.*(weekP{:,2:end});
                actual_Portions = [weekP{:,1} actual_Portions];
                %% set actual_Portions_r as table
                actual_Portions_rt = weekP;
                actual_Portions_rt{:,:} = actual_Portions;
                
                %% get only oats recipes into one pot (600 only)
                %serialN_full = actual_Portions_rt{Totalamounts>0,1};
                serialN_full = actual_Portions_rt{:,1};
                lia = ismember(serialN_full,special);
                
                %% extruct group1 - outs to pot 1 - 600
                actual_Portions_rt{lia,[false Npot==2]} = 0;
                actual_mods(lia,Npot==2) = 0;
                
                
                %% get all recipes with less then one itteration in the small pot;
                mustbeinSpot = actual_Portions_rt{:,2:6}<1 & actual_Portions_rt{:,2:6}>0; % between 0 to 1 - not a full 600 vol
                mustbeinSpot = any(mustbeinSpot,2);
                actual_Portions_rt{mustbeinSpot,[false Npot==2]} = 0;
                actual_mods(mustbeinSpot,Npot==2) = 0;
                
                %% divide into pots_itterations:
                for1200 = actual_Portions_rt{Totalamounts>0,[false Npot==2]};
                for600 = actual_Portions_rt{Totalamounts>0,[false Npot==1]};
                
                %% get corresponding mod values
                mod1200 = actual_mods(Totalamounts>0,Npot==2);
                mod600 = actual_mods(Totalamounts>0,Npot==1);
                
                Cmod1200 = nan(rec,1);
                Cmod600 = nan(rec,1);
                Citer1200 = nan(rec,1);
                Citer600 = nan(rec,1);
                
                vols1200 = nan(rec,1);
                vols600 = nan(rec,1);
                
                portions600 = weekP{Totalamounts>0,2:6};
                portions1200 = weekP{Totalamounts>0,7:9};
                
                lia = ismember(serialN_full(Totalamounts>0),special);
                
                portions1200(lia,:) = 0;
                %portions1200(lia_E,:) = 0;
                
                
                for i = 1:rec
                    indexes = portions600(i,:);
                    getminM = mod600(i,:);
                    getminI = for600(i,:);
                    getminI = getminI(logical(indexes));
                    getminM = getminM(logical(indexes));
                    vol = Psize(Npot ==1); vol = vol(logical(indexes));
                    [~,keep] = min(getminI,[],2);
                    if any(indexes)
                        Citer600(i) = getminI(keep);
                        Cmod600(i) = getminM(keep);
                        vols600(i) = vol(keep);
                    end


                    indexes = portions1200(i,:);
                    getminM = mod1200(i,:);
                    getminI = for1200(i,:);
                    getminI = getminI(logical(indexes));
                    getminM = getminM(logical(indexes));
                    vol = Psize(Npot ==2); vol = vol(logical(indexes));
                    [~,keep] = min(getminI,[],2);
                    if any(indexes)
                        Citer1200(i) = getminI(keep);
                        Cmod1200(i) = getminM(keep);
                        vols1200(i) = vol(keep);
                    end
                end
                
                
                values600 = [Cmod600 Citer600 vols600];
                values1200 = [Cmod1200 Citer1200 vols1200];
                
                %% find recipes that can be cooked on both pots
                mods = [values600(:,1)'; values1200(:,1)'];
                iteration = [values600(:,2)'; values1200(:,2)'];
                Allvols = [values600(:,3)'; values1200(:,3)'];
                index = ones(1,num_of_recipes);
                for i = 1:length(index)
                    test_mods = mods(:,i)';
                    test_iteration =iteration(:,i);
                    if numel(unique(test_mods))==1 % the mod is equal
                        [~,win] = min(ceil(test_iteration),[],'omitnan');
                        index(i) = win;
                    else
                        [~,win] = min(test_mods,[],'omitnan');
                        index(i) = win;
                    end
                end
                
            end
            
            %% this function sets a temp struct for the model of each pot seperatly
            function temp = make_model(v)
                %% this function sets a temp struct for the model of each pot seperatly
                Pot = app.vols.v(v);
                temp.index = index == v;
                temp.original = app.model.original_index(:,temp.index);
                temp.mods = mods(v,temp.index);
                temp.I = nnz(temp.index);
                temp.vol = Allvols(v,temp.index);
                temp.amount = app.model.amount(temp.index);
                cooking_times = data_1_3(data_1_1>0);
                %temp.r = ceil(temp.amount./Pot).*cooking_times(temp.index)';
                temp.r = ceil(temp.amount./temp.vol).*cooking_times(temp.index)';
                temp.d0=zeros(1,temp.I);
                temp.J = app.vols.n(v);
                temp.Recipes = app.model.Recipes(temp.index);
                temp.d=app.model.d(temp.index>0,temp.index>0);
                
                [serialNumbers ,serialOats2] = getSerial();
                temp.SerialN = serialNumbers(temp.index);
                temp.serialOats2 = serialOats2;
                temp.Niteration = iteration(v,temp.index);
                
                function [serialNumbers ,serialOats2] = getSerial()
                    
                    serialNumbers = [app.model.SerialN;app.model.SerialN;app.model.SerialN;app.model.SerialN;app.model.SerialN];
                    serialNumbers = serialNumbers{:,1};
                    serialNumbers = serialNumbers(data_1_1>0);
                    serialOats2 = ismember(serialNumbers,app.oats);
                    
                end
                
            end
            
            %% this function calculates the total time of cooking + basic cleaning for assesment
            function total_time = calc_total_time()
                %% this function calculates the total time of cooking + basic cleaning for assesment
                total_time = zeros(Npots);
                for v = 1: Npots
                    Pot = app.vols.v(v);
                    my_model = modelP.(['P_' num2str(Pot)]);
                    temp_time = sum(my_model.r)+ (app.default_clean)*(my_model.I-1);
                    total_time(:,v) =[Pot ,temp_time];
                end
                
            end
            
            
        end
        
        %% this function is used to extract the solution for each pot
        function varargout = extract_solution(app,getsol,pot)
            %% this function is used to extract the solution for each pot
            
            % for each volume of pot - the size of UC will be the same as
            % the amount of pots.
            for ii =1:numel(getsol.UC)
                app.printingCounter = app.printingCounter +1;
                iPOT = app.printingCounter;
                % exctract recipes
                getRecipes = getsol.L{ii,2}; %cell
                % exctract cooking times
                getCookingT = getsol.UC_vec{ii}';
                % exctract cleaning times
                getCleaningT = getsol.D_vec{ii}';
                % exctract total duration
                getTotalDuration = getsol.UC_D_vec_cumulative{ii}';
                % exctract amounts per recipe
                getamounts = getsol.amount_vec{ii}';
                
                %% create a table to store all the information
                tab = table(getRecipes,getamounts,getCookingT,getCleaningT,getTotalDuration);
                %% create a structure of data about cooking durations for each pot:
                dur_struct = getrealcookingTime(app,app.(['days_' num2str(iPOT)]),...
                    app.(['start_day_' num2str(iPOT)]),app.(['end_day_' num2str(iPOT)]),app.DownTimeTable.Data,iPOT,pot); %timeS,timeE,

                
                %% addCIP cleaning time
                
                tab = addCIP(app,tab,dur_struct,app.(['start_day_' num2str(iPOT)]),app.(['end_day_' num2str(iPOT)]));
                if pot == 1200
                    tab = addCleaning1200(app,tab);
                end
                % make sure that the duration is calculated correctly
                tab.getTotalDuration = cumsum(tab.getCookingT + tab.getCleaningT);
                
                tab =divide_into_days(app,tab,app.(['days_' num2str(iPOT)]),app.(['start_day_' num2str(iPOT)]),app.(['end_day_' num2str(iPOT)]),...
                    dur_struct);
                
                tab = addbreaks(app,tab,dur_struct,...
                    app.(['start_day_' num2str(iPOT)]),app.(['end_day_' num2str(iPOT)]),...
                    app.(['days_' num2str(iPOT)]),app.(['start_day_' num2str(iPOT)]));
                
                % make sure that the duration is calculated correctly
                tab.getTotalDuration = cumsum(tab.getCookingT + tab.getCleaningT);
                
                varargout{ii} = switch2str(app,tab,iPOT);%#ok<AGROW>
            end
        end
        
        %% this function devides the recipe sets to days for ever cycle of planned cooking hours
        function tab = divide_into_days(app,tab,ndays,dayS,dayE,dur_struct)
            %% check if has oats
            if any(contains(string(tab.getRecipes),{'שיבולת'})) || any(contains(string(tab.getRecipes),{'שועל'}))...
                    || any(contains(string(tab.getRecipes),{'ש"ש'})) || any(contains(string(tab.getRecipes),{'שש'}))
                isOats = 1;
                idxoats = contains(string(tab.getRecipes),{'שיבולת'}) | contains(string(tab.getRecipes),{'שועל'}) ...
                    | contains(string(tab.getRecipes),{'ש"ש'}) | contains(string(tab.getRecipes),{'שש'});
            else
                isOats = 0;
            end
            nRecipe = height(tab);
            tab.day = zeros(nRecipe,1);
            
            if ndays ==1 %% in the case its only one day of cooking
                tab.day(:) = 1;
            else
                
                realdays = [dayS:5 1:dayE];
                cumT = 0;
                realdays = realdays(1:ndays);
                
                % most cases are for cooking over the weekend
                SIdx = find(realdays == 5,1);
                Eidx = find(realdays == 1,1);
                if isempty(SIdx)
                    % not cooking on thursday - need to find max day
                    SIdx = find(realdays == max(realdays),1);
                end
                if isempty(Eidx)
                    Eidx = find(realdays == min(realdays),1);
                end
                if SIdx<Eidx
                    realdaysS = realdays(1:SIdx);
                    realdaysE = realdays(Eidx:end);
                else
                    % this means that we are staring from sunday to
                    % thursday
                    realdaysS = realdays(1:SIdx);
                    realdaysE = [];
                end
                
                % check how much time I have until
                SUMrealdaysS = 0;
                for d = 1: numel(realdaysS)
                    %SUMrealdaysS = SUMrealdaysS + dur_struct.(['day_' num2str(realdaysS(d)) 'duration']).planned;
                    SUMrealdaysS = SUMrealdaysS + dur_struct.(['day_' num2str((d)) 'duration']).planned;
                end
                % get the recipes that will be cooked prior to the weekend:
                
                
                if isempty(realdaysE)
                    for d = 1:ndays
                        cumT = cumT + dur_struct.(['day_' num2str(realdays(d)) 'duration']).planned;
                        
                        idx = tab.day==0 & round(tab.getTotalDuration)<=cumT;
                        tab.day(idx) = d;
                        
                        if isOats && (d == 1 || d ==2)
                            % find all day ~=0
                            notZero = tab.day ~=0;
                            if any(xor(notZero,idxoats))
                                tab.day(xor(notZero,idxoats)) = 0;
                            end
                        end
                        if d>1
                            dur_struct.(['day_' num2str(realdays(d)) 'duration']).actual = tab.getTotalDuration(find(idx,1,'last'))...
                                - dur_struct.(['day_' num2str(realdays(d-1)) 'duration']).actual;
                        elseif d ==1
                            dur_struct.(['day_' num2str(realdays(d)) 'duration']).actual = tab.getTotalDuration(find(idx,1,'last'));
                        end
                    end
                else
                    idx = tab.day==0 & round(tab.getTotalDuration)<=SUMrealdaysS;
                    Tbefore = tab(idx,:);
                    if isOats
                        if idx == idxoats
                        else
                           swap = xor(idx,idxoats);
                           Tbefore = [Tbefore(swap,:) ; Tbefore(idxoats,:)];
                           %Tbefore.getTotalDuration = cumsum(Tbefore.getCookingT + Tbefore.getCleaningT);
                           if any(strcmp(Tbefore.getRecipes,'CIP'))
                               CIPswap = strcmp(Tbefore.getRecipes,'CIP');
                               TCIP = Tbefore(~CIPswap,:);
                               iszero = TCIP.getCleaningT == 0;
                               if nnz(iszero)>0
                               isRec = TCIP.getRecipes(iszero);
                               IRec = contains(app.cleaning_table.Recipe,string(isRec));
                               cleaningTime = app.cleaning_table.Cleaning_time(IRec);
                               TCIP.getCleaningT(iszero)= cleaningTime;
                               end
                               TCIP.getTotalDuration = cumsum(TCIP.getCookingT + TCIP.getCleaningT);
                               [maxdur] = max(TCIP.getTotalDuration);
                               if maxdur<app.default_CIP_dur
                                   Tbefore = [Tbefore(~CIPswap,:);Tbefore(CIPswap,:)];
                                   
                               else
                                   addCIP = find(TCIP.getTotalDuration>=app.default_CIP_dur,1);
                                   Tbefore = [TCIP(1:addCIP-1,:);Tbefore(CIPswap,:);TCIP(addCIP:end,:)];
                                   Tbefore.getCleaningT(addCIP-1) = 0;
                               end
                               Tbefore.getTotalDuration = cumsum(Tbefore.getCookingT + Tbefore.getCleaningT);
                               
                           end
                        end
                    end
                    
                    Tafter = tab(~idx,:);Tafter.getTotalDuration = cumsum(Tafter.getCookingT + Tafter.getCleaningT);
                    cumT = 0;
                    for d = 1:numel(realdaysS)
                        cumT = cumT + dur_struct.(['day_' num2str((d)) 'duration']).planned;
                        %cumT = cumT + dur_struct.(['day_' num2str(realdaysS(d)) 'duration']).planned;
                        
                        idx = Tbefore.day==0 & round(Tbefore.getTotalDuration)<=cumT;
                        Tbefore.day(idx) = d;
                        
%                         if isOats && (d == 1 || d ==2)
%                             idxoats = contains(string(Tbefore.getRecipes),{'שיבולת'}) |...
%                                 contains(string(Tbefore.getRecipes),{'שועל'}) | contains(string(Tbefore.getRecipes),{'ש"ש'});
% 
%                             % find all day ~=0
%                             notZero = Tbefore.day ~=0;
%                             if any(xor(notZero,idxoats))
%                                 Tbefore.day(xor(notZero,idxoats)) = 0;
%                             end
%                         end
                        if d>1
%                             dur_struct.(['day_' num2str(realdaysS(d)) 'duration']).actual = Tbefore.getTotalDuration(find(idx,1,'last'))...
%                                 - dur_struct.(['day_' num2str(realdaysS(d-1)) 'duration']).actual;
                                dur_struct.(['day_' num2str((d)) 'duration']).actual = Tbefore.getTotalDuration(find(idx,1,'last'))...
                                - dur_struct.(['day_' num2str((d-1)) 'duration']).actual;


                        elseif d ==1
                            %dur_struct.(['day_' num2str(realdaysS(d)) 'duration']).actual = Tbefore.getTotalDuration(find(idx,1,'last'));
                            dur_struct.(['day_' num2str((d)) 'duration']).actual = Tbefore.getTotalDuration(find(idx,1,'last'));
                        end
                    end
                    cumT = 0;
                    indDay = d+1:d+numel(realdaysE);
                    
                    for d = 1:numel(indDay)
                        %cumT = cumT + dur_struct.(['day_' num2str(realdaysE(d)) 'duration']).planned;
                        cumT = cumT + dur_struct.(['day_' num2str(numel(realdaysS) +(d)) 'duration']).planned;
                        
                        idx = Tafter.day==0 & round(Tafter.getTotalDuration)<=cumT;
                        Tafter.day(idx) = indDay(d);
                        Tafter.day;
%                         if isOats && (d == 1 || d ==2)
%                             idxoats = contains((Tafter.getRecipes),{'שיבולת'}) |...
%                                 contains((Tafter.getRecipes),{'שועל'}) | contains((Tafter.getRecipes),{'ש"ש'});
%                             % find all day ~=0
%                             notZero = Tafter.day ~=0;
%                             if any(xor(notZero,idxoats))
%                                 Tafter.day(xor(notZero,idxoats)) = 0;
%                             end
%                         end
                        if d>1
%                             dur_struct.(['day_' num2str(realdaysE(d)) 'duration']).actual = Tafter.getTotalDuration(find(idx,1,'last'))...
%                                 - dur_struct.(['day_' num2str(realdaysE(d-1)) 'duration']).actual;
                            dur_struct.(['day_' num2str(numel(realdaysS) +(d)) 'duration']).actual = Tafter.getTotalDuration(find(idx,1,'last'))...
                                - dur_struct.(['day_' num2str(numel(realdaysS) +(d) -1) 'duration']).actual;
                            
                        elseif d ==1
%                             dur_struct.(['day_' num2str(realdaysE(d)) 'duration']).actual = Tafter.getTotalDuration(find(idx,1,'last'));
                            dur_struct.(['day_' num2str(numel(realdaysS) +(d)) 'duration']).actual = Tafter.getTotalDuration(find(idx,1,'last'));
                        end
                    end
                    tab = [Tbefore ; Tafter];
                end
               
                if any(tab.day == 0)
                    residuals = find(tab.day == 0);
                    tab.day(residuals) = tab.day(residuals(1)-1);
                end
                tab.day = categorical(tab.day);
            end
            
            
        end

        %% this function sets the weekly and daily duration for cooking includin breaks
        function dur_struct = getrealcookingTime(app,ndays,dayS,dayE,breaktab,iPot,pot) %timeS,timeE,
            % extract break day and time
            realdays = [dayS:5 1:dayE];
            innerstruct = struct('planned',[],'actual',[],'break_duration',[],'break_stratT',[]);
            dur_struct.('weekDuration') = [];
            
            for d = 1:numel(realdays)
                field = ['day_' num2str(d) 'duration'];
                dur_struct.(field) = innerstruct;
            end
            
            if ~isempty(breaktab)
                isBreakN = contains(breaktab.('נפח סיר'), num2str(iPot));
                isBreakV = contains(breaktab.('נפח סיר'), num2str(pot));
                getIntersect = find(isBreakN == isBreakV);
                if isempty(getIntersect)
                else
                    for ii = 1: numel(getIntersect)
                        getLine = breaktab(getIntersect(ii),:);
                        getbreakDay = changeday2num(getLine.('יום בשבוע'));
                        % findoutwichday of the cooking week it is: 
                        getbreakDay = find(realdays == getbreakDay);
                        getbreakTimeS = getLine.('שעת התחלה');
                        getduration = getLine.('שעת סיום') - getbreakTimeS;
                        field = ['day_' num2str(getbreakDay) 'duration'];
                        dur_struct.(field).break_duration = getduration;
                        dur_struct.(field).break_stratT = getbreakTimeS;
                    end
                end
            end
            
            cookDur = cookingTperDay(app,iPot); %realdays,timeS,timeE,ndays);
            dur_struct.('weekDuration') = sum(cookDur);
            for ii = 1:ndays
                %d = realdays(ii);
                %field = ['day_' num2str(d) 'duration'];
                field = ['day_' num2str(ii) 'duration'];
                isbreakdur = dur_struct.(field).break_duration ;
                daydur = cookDur(ii);
                if ~isempty(isbreakdur)
                    daydur = daydur- isbreakdur;
                end
                dur_struct.(field).planned = daydur;
            end
            
            if ~isempty(app.extraTime)
                
            field = ['day_' num2str(find(realdays,1)) 'duration'];
            dur_struct.(field).planned = dur_struct.(field).planned +app.extraTime ;
            end
            
            
            %% inner functions
            function daynum = changeday2num(daystr)
                days  ={'יום א','יום ב','יום ג','יום ד','יום ה'};
                isday = strcmp(daystr,days);
                daynum = find(isday);
            end
        end
        
        %% this function appends CIP cleaning Time for all pots and extra cleaning time for 1200 pots
        function tab = addCIP(app,tab,dur_struct,dayS,dayE)
            %% this function appends CIP cleaning Time for all
            if any(contains(string(tab.getRecipes),{'שיבולת'})) || any(contains(string(tab.getRecipes),{'שועל'})) ...
                    || any(contains(string(tab.getRecipes),{'ש"ש'})) || any(contains(string(tab.getRecipes),{'שש'}))
                idx = contains(string(tab.getRecipes),{'שיבולת'}) | contains(string(tab.getRecipes),{'שועל'}) ...
                    | contains(string(tab.getRecipes),{'ש"ש'}) | contains(string(tab.getRecipes),{'שש'});
                tab = [tab(idx,:) ; tab(~idx,:)];
                tab.getTotalDuration = cumsum(tab.getCookingT + tab.getCleaningT);
            end
            
            dur = tab.getTotalDuration;
            CIP_time = app.default_CIP_dur;% cycle of cooking
            realdaysS = dayS:5;
            realdaysE = 1:dayE;
            CIP_CleaningT = app.default_CIP_clean; % duration of cleaning
            
            % check how many CIPs to add
            SUMrealdaysS = 0;
            for d = 1: numel(realdaysS)
                %SUMrealdaysS = SUMrealdaysS + dur_struct.(['day_' num2str(realdaysS(d)) 'duration']).planned;
                SUMrealdaysS = SUMrealdaysS + dur_struct.(['day_' num2str(d) 'duration']).planned;
            end
            SUMrealdaysE = 0;
            for d = 1: numel(realdaysE)
                %SUMrealdaysE = SUMrealdaysE + dur_struct.(['day_' num2str(realdaysE(d)) 'duration']).planned;
                SUMrealdaysE = SUMrealdaysE + dur_struct.(['day_' num2str(numel(realdaysS) +d) 'duration']).planned;
            end
            nCIP = 0;
            startCIP = 0;
            if SUMrealdaysS<CIP_time
                startCIP = SUMrealdaysS;
            else
                nCIP = nCIP+floor(SUMrealdaysS/CIP_time);
            end
            if SUMrealdaysE<CIP_time
                % set CIP only for first part
            else
                % will have CIP in second part of cooking
                nCIP = nCIP + floor(SUMrealdaysE/CIP_time);
            end
            
            for n = 1:nCIP
                if isequal(n,1)
                    flag = find(round(dur)>=(startCIP+n*CIP_time),1); % get the first index where total duration passes the CIP TIME
                else
                    startCIP = tab.getTotalDuration(find(dur>=(SUMrealdaysS),1)-1);
                    flag = find(round(dur)>=(startCIP+(n-1)*CIP_time),1);
                end   
                if ~isempty(flag)
                    
                    if flag == height(tab) || ~ismembertol(tab{flag,'getTotalDuration'},(startCIP+n*CIP_time+(n-1)*CIP_CleaningT),0.1)
                        if tab{flag-1,'getCleaningT'}<24
                            tab{flag-1,'getCleaningT'} = 0; % don't need to clean before CIP
                        else
                            continue;
                        end
                        tab{:,'getTotalDuration'} = cumsum(tab.getCookingT + tab.getCleaningT);
                        
                        %% initialize CIP row;
                        CIP = tab(1,:);
                        CIP.getRecipes = 'CIP';
                        CIP.getamounts = 0;
                        CIP.getCleaningT = CIP_CleaningT;
                        CIP.getCookingT = 0;
                        CIP.getTotalDuration = tab{flag-1,'getTotalDuration'}+CIP_CleaningT;
                        
                        %% insert CIP into tab;
                        tab = [tab(1:flag-1,:) ;CIP ;tab(flag:end,:)];
                        tab{:,'getTotalDuration'} = cumsum(tab.getCookingT + tab.getCleaningT);
                    else
                        if tab{flag,'getCleaningT'}<24
                            tab{flag,'getCleaningT'} = 0; % don't need to clean before CIP
                        else
                            continue;
                        end
                        tab{:,'getTotalDuration'} = cumsum(tab.getCookingT + tab.getCleaningT);
                        
                        %% initialize CIP row;
                        CIP = tab(1,:);
                        CIP.getRecipes = 'CIP';
                        CIP.getamounts = 0;
                        CIP.getCleaningT = CIP_CleaningT;
                        CIP.getCookingT = 0;
                        CIP.getTotalDuration = tab{flag,'getTotalDuration'}+CIP_CleaningT;
                        
                        %% insert CIP into tab;
                        tab = [tab(1:flag,:) ;CIP ;tab(flag+1:end,:)];
                        tab{:,'getTotalDuration'} = cumsum(tab.getCookingT + tab.getCleaningT);
                    end
                end
                dur = tab.getTotalDuration;
            end
            
        end
        %% add only for large pot - extra wash every 24 hr
        function tab = addCleaning1200(app,tab)
            dur = tab.getTotalDuration;
            wash_time = app.default_1200_dur;%get from app
            nwash = floor(max(dur)/wash_time);
            wash_CleaningT = app.default_1200_clean;
            for w = 1:nwash
                dur = tab.getTotalDuration;
                idx =dur >wash_time*w;
                i = find(idx,1)-1;
                %% Change cleaningTime if minimum time to 40 min;
                if strcmp(tab.getRecipes(i),'CIP') || strcmp(tab.getRecipes(i+1),'CIP') || strcmp(tab.getRecipes(i-1),'CIP')
                    continue
                else
                    if tab.getCleaningT(i) == app.default_clean%app.default_clean % minimal time (take from app)
                        tab.getCleaningT(i) = wash_CleaningT;
                        %tab.getCleaningT(i) = tab.getCleaningT(i)+wash_CleaningT;
                    elseif tab.getCleaningT(i) > app.default_clean%app.default_clean
                        continue;
                    end
                    %% recalculate the duration of the table;
                    tab{:,'getTotalDuration'} = cumsum(tab.getCookingT + tab.getCleaningT);
                end
            end
        end
        
        %% this function appends CIP cleaning Time for all pots and extra cleaning time for 1200 pots
        function tab = addbreaks(app,tab,dur_struct,dayS,dayE,ndays,timeS)
            %% this function appends CIP cleaning Time for all
            %  pots and extra cleaning time for 1200 pots
            
            realdays = [dayS:5 1:dayE];
            for ii = 1:ndays
                d = realdays(ii);
                %field = ['day_' num2str(d) 'duration'];
                field = ['day_' num2str(ii) 'duration'];
                isbreakdur = dur_struct.(field).break_duration ;
                isbreakstart = dur_struct.(field).break_stratT;
                if isempty(isbreakdur)
                    continue
                else
                    idx = tab.day == categorical(ii);
                    if any(idx)
                        if d == 1 %sunday - start Time == 6
                            if isbreakstart == app.startcooking
                                %% need to insert break like CIP
                                flag = find(idx,1);
                                
                            else
                                %% need to find when to insert break by cooking times
                                temptab = tab(idx,:);
                                temptab.getTotalDuration = cumsum(temptab.getCookingT + temptab.getCleaningT);
                                subI = temptab.getTotalDuration<=isbreakstart;
                                if nnz(subI)==0 % append at the end of the day
                                    flag = find(idx,1,'last')+1;
                                else
                                   flag = find(subI,1,'last')+1;
                                end
                            end
                            addRow()
                        elseif d == dayS % start day - start time by app
                            if isbreakstart == timeS
                                %% need to insert break like CIP
                                %% need to insert break like CIP
                                flag = find(idx,1);
                            else
                                %% need to find when to insert break by cooking times
                                temptab = tab(idx,:);
                                temptab.getTotalDuration = cumsum(temptab.getCookingT + temptab.getCleaningT);
                                subI = temptab.getTotalDuration<=isbreakstart;
                                if nnz(subI)==0 % append at the end of the day
                                    flag = find(idx,1,'last')+1;
                                else
                                    flag = find(subI,1,'last')+1;
                                end
                            end
                        else % all other cases - day is continues
                            %% need to find when to insert break by cooking times
                            temptab = tab(idx,:);
                            temptab.getTotalDuration = cumsum(temptab.getCookingT + temptab.getCleaningT);
                            subI = temptab.getTotalDuration<=isbreakstart;
                            if nnz(subI)==0 % append at the end of the day
                                flag = find(idx,1,'last')+1;
                            else
                                flag = find(subI,1,'last')+1;
                            end
                        end
                    else
                       idx = string(tab.day) < string(ii);
                       flag = find(idx,1,'last'); 
                       addRow()
                    end
                    
                end
                
            end
            function addRow()
                % initialize CIP row;
                BREAK = tab(1,:);
                BREAK.getRecipes = 'BREAK';
                BREAK.getamounts = 0;
                BREAK.getCleaningT = isbreakdur;
                BREAK.getCookingT = 0;
                try
                    BREAK.getTotalDuration = tab{flag-1,'getTotalDuration'}+isbreakdur;
                catch
                    BREAK.getTotalDuration = BREAK.getCleaningT +BREAK.getCookingT;
                end
                BREAK.day = categorical(ii);
                %% insert CIP into tab;
                tab = [tab(1:flag-1,:) ;BREAK ;tab(flag:end,:)];
                tab{:,'getTotalDuration'} = cumsum(tab.getCookingT + tab.getCleaningT);
            end
            
            
        end
        
        %% this function is for writing into a tamplete output file
        function [Irow, capacity] = write_to_temp_2(app,temp,Irow,pot,portions,flag)
            %% this function is for writing into a tamplete output file
            maxDays = max([str2num(app.TdaysLabel_1.Text) ;str2num(app.TdaysLabel_2.Text);str2num(app.TdaysLabel_3.Text) ]);
            %Irow = 4; always starts with 4 for the first pot, later need to return the
            %last row in the table to start the next pot from.
            
            temp= change2duration(app,temp);
            ndays = 1:maxDays;%unique(temp.day);
            Nrecipes = zeros(1,numel(ndays));
            realDays = [app.(['start_day_' num2str(flag)]):5 1:app.(['end_day_' num2str(flag)])];
            daysH   = ['א','ב','ג','ד','ה'];
            
            %% setup - columns in EXCEL Templete
            map = {'Y','U','Q','M','I','E','A'};
            map4days = {'AA','W','S','O','K','G','C'};
            map4time = {'Z','V','R','N','J','F','B'};
            %% columns order in EXCEL Templete
            order = {'getCleaningT','getCookingT','getamounts','getRecipes'};
            cookDur = cookingTperDay(app,flag);
            % write the recipes for each day in columns
            for d = 1:numel(ndays)
                TbyDay = temp(string(temp.day) == num2str(ndays(d)),:); % extract only the relevent day
                Nrecipes(d) = height(TbyDay); % save a vector with the number of recipes for each day
                %getRange = [map(d) num2str(Irow)]; % get range for EXCEL writing
                getRange = [map(str2num(string(ndays(d)))) num2str(Irow)]; % get range for EXCEL writing
                writetable(TbyDay(:,order),[app.mainpath '\' app.outFile '.xlsx'],'Range',strcat(getRange{:}),'WriteVariableNames',false)
                %write day
                cap = {daysH(realDays(d))};
                getRange = [map4days(str2num(string(ndays(d)))) num2str(2)];
                writecell(cap,[app.mainpath '\' app.outFile '.xlsx'],'Range',strcat(getRange{:}));
                %write duration
                cap = {cookDur(d)};
                getRange = [map4time(str2num(string(ndays(d)))) num2str(2)];
                writecell(cap,[app.mainpath '\' app.outFile '.xlsx'],'Range',strcat(getRange{:}));
            end
            
            %% calculate capacity
            Irow = Irow + max(Nrecipes)+2; % set index for next pot to append to file.
            getRange = [map(1) num2str(Irow)];
            cookingTime = temp.getTotalDuration(end);
            %capacity = (cookingTime/app.(['times_' num2str(flag)]))*100;
            capacity = (cookingTime/app.capacitySetting)*100;
            cap = {[num2str(capacity) '%'],num2str(pot),' עומס שבועי עבור סיר '};
            writecell(cap,[app.mainpath '\' app.outFile '.xlsx'],'Range',strcat(getRange{:}));
            
            Irow = Irow + 1; % set index for next pot to append to file.
            getRange = [map(1) num2str(Irow)];
            
            cap = {num2str(portions) ,num2str(pot),'כמות מנות שבועית '};
            writecell(cap,[app.mainpath '\' app.outFile '.xlsx'],'Range',strcat(getRange{:}));
            
            %             Irow = Irow + 1; % set index for next pot to append to file.
            %             getRange = ['A' num2str(Irow) ':T' num2str(Irow)];
            %
            %             cap = {'-'};
            %             writecell(cap,['Outputs\' app.outFile '.xlsx'],'Range',getRange);
            
            Irow = Irow +2;
            
        end
        
        function cookDur = cookingTperDay(app,i)
            realDays = [app.(['start_day_' num2str(i)]):5 1:app.(['end_day_' num2str(i)])];
            startT = str2num(app.(['start_time_' num2str(i)]).Value);
            endT = str2num(app.(['end_time_' num2str(i)]).Value);
            totalCdays = str2num(app.(['TdaysLabel_' num2str(i)]).Text);
            cookDur = zeros(1,totalCdays);
            for d =1:totalCdays
                if d==1 && realDays(d)==5
                    cookDur(d)= app.stopcooking-startT;
                elseif d==1 && realDays(d)~=5
                    cookDur(d)= 24-startT;
                elseif d>1 && d<totalCdays && realDays(d)==1
                    cookDur(d)= 24-app.startcooking;
                    if ~isempty(app.extraTime)
                        cookDur(d) = cookDur(d) + app.extraTime;
                    end
                elseif d>1 && d<totalCdays && realDays(d)==5
                    cookDur(d)= app.stopcooking;
                    
                elseif d>1 && d<totalCdays && realDays(d)~=5 && realDays(d)~=1
                    cookDur(d)= 24;
                    
                elseif d==totalCdays && realDays(d)==1
                    cookDur(d)= endT -app.startcooking;
                    
                elseif d==totalCdays && realDays(d)~=1
                    cookDur(d) = endT;
                end
            end


        end
        
        function setupPaths(app)
          %% get main path to the app;
            fid = fopen('pathDef.txt','r');
            %read main path
            l = fgetl(fid);
            app.mainpath = extractAfter(l,'=');
            %read path for cooking
            l = fgetl(fid);
            app.cookingpath=extractAfter(l,'=');
            %read path for cleaning
            l = fgetl(fid);
            app.cookingreq = extractAfter(l,'=');
            fclose(fid);  
        end
        
        %% this function swaps the cooking day from number 2 letter
        function tab = switch2str(app,tab,i)
            startD = app.(['start_day_' num2str(i)]);
            endD = app.(['end_day_' num2str(i)]);
            Ndays = str2num(app.(['TdaysLabel_' num2str(i)]).Text);
            cookingDays =   [startD:5 1:endD];cookingDays = cookingDays(1:Ndays);
            tab.days = strings([height(tab) 1]);
            for i =1:Ndays
                tab(tab.day == categorical(i),'days') = repmat(changenum2day(i,cookingDays),[nnz(tab.day == categorical(i)) 1]);
            end
            %% inner functions
            function daystr = changenum2day(daynum,cookingDays) % i, [4,5,1,2,3]
                days  ={'יום א','יום ב','יום ג','יום ד','יום ה'};
                days = days(cookingDays);
                daystr = days(daynum);
            end
            
        end
        
        %% this function  makes sure that the oats recipes are together and at the begining of the cooking
        function q = groupOats(app,q,my_model)
            
            m = randi(2,1);
            switch m
                case 1
                    % group 1 - before DelPos
                    
                    firstPos = ismember(q,app.firstOats);
                    q = [q(firstPos) q(~firstPos)];
                    
                case 2
                    % group 2 - after DelPos
                    firstPos = ismember(q,app.firstOats);
                    subPos = q(~firstPos);
                    DelPos=find(subPos>my_model.I);
                    q = [subPos(1:DelPos) q(firstPos) subPos(DelPos+1:end)];
                    
            end
            
        end
        
        %% this function  converts cleaning and cooking values of time into duration
        function temp = change2duration(~,temp)
            
            temp.getCleaningT = hours(temp.getCleaningT);
            temp.getCleaningT.Format = 'hh:mm';
            temp.getCookingT = hours(temp.getCookingT);
            temp.getCookingT.Format = 'hh:mm';
        end
        
        function tbl = setOats(app,tbl)
            getoats = app.cleaning_table.Pair_cooking == 1; %app.cleaning_table.Cleaning_time == 24 &
            app.oats = app.cleaning_table.Serial_number(getoats);
            for i =1:numel(app.oats)
                SRoat = app.oats(i);
                R = tbl{tbl.Serial_number==SRoat,3:end};
                idx = find(R>0);
                if isempty(idx)
                    continue;
                elseif length(idx)==1
                    if idx ==1
                        continue;
                    else
                        oatamount = tbl{tbl.Serial_number==SRoat,idx+2};
                        tbl{tbl.Serial_number==SRoat,idx+2} = 0;
                        tbl{tbl.Serial_number==SRoat,3}= oatamount;
                    end
                else %multiple rew for product
                    app.getRequ.Enable = 'on';
                    app.CalcPlan.Icon = '';
                    % Change button name
                    app.CalcPlan.Text = 'חשב תוכנית שבועית';
                    app.CalcPlan.FontSize = 20;
                    error('בעיה עם כמויות עבור מתכוני שיבולת שועל - נא לאחד דרישות לזמנה אחת')
                end
                
                
            end
        end

        
        function [portionLoad, cookingLoad] = gettotalTime(app)
            cookingLoad = 0;
            portionLoad = 0;
            for i=1:app.printingCounter
                cookingLoad = cookingLoad + ...
                    (app.(['WeeklyPlan_' num2str(i)]).Data.getTotalDuration(end))/...
                    hours(app.capacityEditField.Value);
                portionLoad = portionLoad + ...
                    str2num(app.(['updateportions_' num2str(i)]).Text);
            end
            portionLoad = round(portionLoad,0);
        end
        
        function closedialog(~,~,event)
                close(event.Source);
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % this function loads all content to UI
            
            %% get main path to the app;
            setupPaths(app)
            
            
            %% pots list
            pots_list = {600;600;1200;nan};
            app.PotsTable.Data = pots_list;
            app.PotsTable.RowName = 'numbered';
            setVolumes(app)
            
            %% get times from setting:
            getCIPtimes(app)
            %%get floor times
            app.stopcooking = str2num(app.THUR_time.Value);
            app.startcooking = str2num(app.SUN_time.Value);
            
            %% get cooking duration information:
            setdays(app,-1);
            %% prepare files for Struss main function
            
            TotalNrecipes = readtable(app.cookingpath,'Range','A:A','PreserveVariableNames',true);
            app.TotalRecipes = height(TotalNrecipes) +1;
            
            
            % need to read and process requirment files, cleaning times and cooking
            % times
            %% get cooking table
            
            time_table = get_cooking_time(app,app.cookingpath);
            time_table = sortrows(time_table,'Serial_number','ascend');
            app.CookingTimesTable.Data = time_table;
            app.CookingTimesTable.ColumnName = time_table.Properties.VariableNames;
            app.CookingTimesTable.ColumnEditable = true;
            app.CookingTimesTable.Data = fillmissing(app.CookingTimesTable.Data,'constant',0,'DataVariables',@isnumeric);
            
            Portions = getportions(app,app.cookingpath);
            
            Portions.Properties.VariableNames(1:2) = {'מק"ט','מתכון'};
            app.CookingSizesTable.Data = Portions;
            app.CookingSizesTable.ColumnName = Portions.Properties.VariableNames;
            app.CookingSizesTable.ColumnEditable = true;
            
            %% cleaning time - based on the cleaning time from app
            
            app.cleaning_table = import_cleaning_table(app,app.cookingreq);
            app.cleaning_table{app.cleaning_table.Cleaning_time==0,'Cleaning_time'} = app.default_clean;
            app.cleaning_table = sortrows(app.cleaning_table,'Serial_number','ascend');
            app.cleaning_table = fillmissing(app.cleaning_table,'constant',0,'DataVariables',@isnumeric);
            %% load setting table
            %             app.DownTimeTable.Data = zeros([1 numel(app.DownTimeTable.ColumnName)]);
            %             app.DownTimeTable.ColumnFormat = {{'0','1','2','3','4','5','6','7'} [] [] };
            
            %% check
            if isequal(app.cleaning_table.Serial_number,app.CookingTimesTable.Data.Serial_number)
                %disp('cleaning and cooking tables match')
            else
                [match,locB] = ismember(app.cleaning_table.Serial_number,app.CookingTimesTable.Data.Serial_number,'rows');
                app.CookingTimesTable.Data = app.CookingTimesTable.Data(locB,:);
                app.cleaning_table = app.cleaning_table(match,:);
                isequal(app.cleaning_table.Serial_number,app.CookingTimesTable.Data.Serial_number)
            end
            
            %% prep output file copy
            timeind = datestr(now,20); timeind = strrep(timeind,'/','_');
            app.outFile = ['תוכנית_שבועית' '_' timeind];
            copyfile(fullfile(app.mainpath,'templete.xlsx'),[app.mainpath '\' app.outFile '.xlsx'])
            
            
            capacityEditFieldValueChanged(app,[])
        end

        % Button pushed function: getRequ
        function getReqPushed(app, event)
            % Change button name to "אנא המתן"
            app.getRequ.Text = 'אנא המתן...';
            app.getRequ.FontSize = 10;
            n = 3;
            % Put text on top of icon
            app.getRequ.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.getRequ.BackgroundColor,25,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.getRequ.Icon = wbar;
            %% part 1 - loading from main table:
            pause(0.5)
            import_and_process_main(app);
            app.weeklyrequirementsTable.ColumnEditable = true;
            %% get weekdays:
            reqdays = {app.weeklyrequirementsTable.Data.Properties.VariableNames{1:2},'יום ה','יום א','יום ב','יום ג','יום ד'};
            app.weeklyrequirementsTable.ColumnName =reqdays ;
            app.CalcPlan.Enable = 'on';
            app.outFileButton.Enable = 'on';
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(1/n)),size(wbar,2)-2);
            app.getRequ.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.getRequ.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.getRequ.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            pause(0.5)
            %% part 2 -make sure all tables match:
            
            %% find match between all tables: weekly, time_table,cleaning
            app.weeklyrequirementsTable.Data = sortrows(app.weeklyrequirementsTable.Data,'Serial_number','ascend');
            [match,locB] = ismember(app.weeklyrequirementsTable.Data.Serial_number,app.CookingTimesTable.Data.Serial_number,'rows');
            app.CookingTimesTable.Data = app.CookingTimesTable.Data(locB(locB>0),:);
            app.weeklyrequirementsTable.Data=app.weeklyrequirementsTable.Data(match,:);
            app.weeklyrequirementsTable.Data = sortrows(app.weeklyrequirementsTable.Data,'Serial_number','ascend');
            
            %% add check:
            if isequal(app.weeklyrequirementsTable.Data.Serial_number,app.CookingTimesTable.Data.Serial_number)
                %disp('weekly req and cooking table are set')
            else
                error('ERROR - weekly req and cooking table are not set')
            end
            [match,locB] = ismember(app.weeklyrequirementsTable.Data.Serial_number,app.cleaning_table.Serial_number,'rows');
            app.cleaning_table=app.cleaning_table(locB,:);
            app.weeklyrequirementsTable.Data=app.weeklyrequirementsTable.Data(match,:);
            %% add check:
            if isequal(app.weeklyrequirementsTable.Data.Serial_number,app.cleaning_table.Serial_number)
                %disp('weekly req and cleaning table are set')
            else
                error('ERROR - weekly req and cleaning table are not set')
            end
            if isequal(app.CookingTimesTable.Data.Serial_number,app.cleaning_table.Serial_number)
                %disp('cooking and cleaning table are set')
            else
                error('ERROR - cooking and cleaning table are not set')
            end
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(2/n)),size(wbar,2)-2);
            app.getRequ.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.getRequ.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.getRequ.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            pause(0.5)
            
            %% part 3 -final update:
            app.weeklyrequirementsTable.Data = fillmissing(app.weeklyrequirementsTable.Data,'constant',0,'DataVariables',@isnumeric);
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(3/n)),size(wbar,2)-2);
            app.getRequ.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.getRequ.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.getRequ.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            pause(0.5)
            app.TabGroup.SelectedTab = app.weeklyRequirements;
            % remove waitbar
            app.getRequ.Icon = '';
            % Change button name
            app.getRequ.Text = 'תוכנית שבועית הוזנה';
            app.getRequ.FontSize = 20;
        end

        % Button pushed function: CalcPlan
        function CalcPlanButtonPushed(app, event)
%             reqname = inputdlg('אנא הזן שם לקובץ דרישות לגיבוי',...
%                 'שמירת קובץ דרישות שבועיות לגיבוי',...
%                 [1 50],...
%                 {'weeklyRequirements'});
            reqname = {'weeklyRequirements'};
            writetable(app.weeklyrequirementsTable.Data,[app.mainpath '\' reqname{:} '.xlsx']);
            
            %% check if exist a file for output:
            if ~exist([app.mainpath '\' app.outFile '.xlsx'],'file')
                copyfile(fullfile(app.mainpath ,'templete.xlsx'),[app.mainpath '\' app.outFile '.xlsx']);
            end
            
            
            % Change button name
            app.getRequ.Enable = 'off';
            % Change button name to "אנא המתן"
            app.CalcPlan.Text = 'אנא המתן...';
            app.CalcPlan.FontSize = 10;
            n = 6;
            % Put text on top of icon
            app.CalcPlan.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.CalcPlan.BackgroundColor,25,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.CalcPlan.Icon = wbar;
            pause(0.5)
            app.printingCounter = 0;
            
            %% part 1 - set up cleaning time into large matrix for main calculation
            cross_rec = size(app.cleaning_table,1);
            app.cleaningInfo = struct;
            app.cleaningInfo.times = app.cleaning_table.Cleaning_time;
            app.cleaningInfo.RecNum = 1:cross_rec;
            matrix = zeros(cross_rec); 
            for i = 1:cross_rec
                matrix(:,i) = app.cleaning_table.Cleaning_time;
            end
            matrix = matrix - diag(diag(matrix));
            %change cleaning time for pairs:
            pairs = unique(app.cleaning_table.Pair_cooking);
            pairs = pairs(pairs>0);
            for pi = 1:numel(pairs)
                p = pairs(pi);
                index = find(app.cleaning_table.Pair_cooking == p);
                for j=1:length(index)
                    for k = 1:length(index)
                        matrix(index(j),index(k)) = app.default_clean;
                        %matrix(index(2),index(1)) = app.default_clean;
                    end
                end
            end
            app.cleaningInfo.matrix = matrix;
            tot_matrix = [matrix matrix matrix matrix matrix];
            tot_matrix = [tot_matrix ; tot_matrix ; tot_matrix  ;tot_matrix  ;tot_matrix];
            data_1_2 = num2cell(tot_matrix);
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(1/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            pause(0.5)
            
            %% part 2 - prep input:
            % cell 4 - copy of weekly reqirments.
            weekly = app.weeklyrequirementsTable.Data;
            %% check when there is a req for oats and move to thur req instead.
            weekly = setOats(app,weekly);
            
            
            data_1_4 = table2cell(weekly);
            
            % cell 1 - weekly reqirments in loop.
            cell_1_1_1 = [table2cell(weekly(:,2)); ...
                table2cell(weekly(:,2)) ;...
                table2cell(weekly(:,2)) ;...
                table2cell(weekly(:,2)) ;...
                table2cell(weekly(:,2)) ];
            cell_1_1_2 = [table2cell(weekly(:,3)); ...
                table2cell(weekly(:,4)) ;...
                table2cell(weekly(:,5)) ;...
                table2cell(weekly(:,6)) ;...
                table2cell(weekly(:,7)) ];
            data_1_1 = [cell_1_1_1 cell_1_1_2];
            
            % cell 3 - cooking times in loop.
            time_table = [app.CookingTimesTable.Data ;app.CookingTimesTable.Data;app.CookingTimesTable.Data;app.CookingTimesTable.Data;app.CookingTimesTable.Data];
            data_1_3 = table2cell(time_table(:,2:3));
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(2/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            pause(0.5)
            
            %% part 3 - unite all parts of the preped data, and needed arguments:
            app.data  = {data_1_1 data_1_2 data_1_3 data_1_4 app.cleaningInfo};
            % set model
            app.model = struct;
            % Maximum Number of Iterations
            app.MaxIt = 10000;
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(3/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            
            %% part 4- main calculation:
            allSol = main_function(app);
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(4/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            pause(0.5)
            %% part 5 - extract solution:
            
            for v =1:numel(fieldnames(allSol))
                Pot = app.vols.v(v);
                getsol = allSol.(['BestSol_' num2str(Pot)]).Sol;
                if numel(getsol.UC)==1
                    allSol.(['BestSol_' num2str(Pot)]).(['tab1_' num2str(Pot)])=extract_solution(app,getsol,Pot);
                else
                    [allSol.(['BestSol_' num2str(Pot)]).(['tab1_' num2str(Pot)]),...
                        allSol.(['BestSol_' num2str(Pot)]).(['tab2_' num2str(Pot)])]=extract_solution(app,getsol,Pot);
                end
            end
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(5/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            pause(0.5)
                     
            
            %% part 6 - write to file and main tabs:
            Irow = 4;
            flag =1;
            for f = 1:numel(app.vols.v)
                for i = 1:app.vols.n(f)
                    %%get total amounts of portions in each pot
                    portions = sum(allSol.(['BestSol_' num2str(app.vols.v(f))]).Sol.iter,'omitnan');
                    app.(['updateportions_' num2str(flag)]).Text = sprintf('%.1f',round(portions,1));
                    
                    temp = allSol.(['BestSol_'...
                        num2str(app.vols.v(f))]).(sprintf('tab%d_%d',i,app.vols.v(f)));
                    [Irow,capacity] = write_to_temp_2(app,temp,Irow,app.vols.v(f),portions,flag);
                    vars = temp.Properties.VariableNames;
                    vars = vars(~strcmp(vars,'day'));
                    %% inner function that converts cleaning and cooking values of time into duration
                    temp = change2duration(app,temp);
                    temp.getTotalDuration = hours(temp.getTotalDuration);
                    temp.getTotalDuration.Format = 'hh:mm';
                    app.(['WeeklyPlan_' num2str(flag)]).Data = temp(:,vars);
                    app.(['WeeklyPlan_' num2str(flag)]).ColumnName = {'מתכון','כמות','משך_בישול','זמני_נקיון','זמן_כולל','יום_בישול'};
                    
                    app.(['UpdateVol_' num2str(flag)]).Text = ['Vol ' num2str(app.vols.v(f))];
                    app.(['updateCap_' num2str(flag)]).Text = sprintf('%.1f%%',round(capacity,1));
                    
                    flag = flag+1;
                    if max(temp.getTotalDuration)>app.(['times_' num2str(i)])
                        error('לא ניתן לחלק את המתכונים לכמות ימים מבוקשת')
                    else
                    end
                end
            end
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(6/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            
            % Update Total load for all programs:

            [portionLoad, cookingLoad]= gettotalTime(app);           
            totalTime = app.printingCounter;
            load = round(cookingLoad/totalTime*100,1);
           
            
            str = {num2str(portionLoad),...
                'כמות מנות כוללת',...
                [num2str(load) '%'],...
                'עומס שבועי ממוצע עבור שלושת הסירים'};
                      
            
            app.TottalLoad.Text = sprintf('%s  %s',sprintf('%s %s',str{1},str{4}),sprintf('%s\t|\t %s',str{3},str{2}));
            app.TottalLoad.Visible = 'on';


            pause(0.5)
            % remove waitbar
            app.CalcPlan.Icon = '';
            % Change button name
            app.CalcPlan.Text = 'חשב תוכנית שבועית';
            app.CalcPlan.FontSize = 20;
            % Change button name
            app.getRequ.Enable = 'on';
            % Change button name
            app.getRequ.Text = 'הכנס תוכנית שבועית';
            
        end

        % Button pushed function: addPot
        function addPotButtonPushed(app, event)
            pots = app.PotsTable.Data;
            pots{end + 1} = nan;
            app.PotsTable.Data = pots;
            setVolumes(app);
        end

        % Button pushed function: removePot
        function removePotButtonPushed(app, event)
            pots = app.PotsTable.Data;
            pots =pots(1:end-1);
            app.PotsTable.Data = pots;
            setVolumes(app);
        end

        % Button pushed function: addDownTime
        function addDownTimeButtonPushed(app, event)
            % get selected day
            DownDay=app.Start_day_down.Value;
            %             value = app.Start_day_down.Value;
            %             list = app.Start_day_down.Items;
            %             [~,DownDay] =ismember(value,list);
            % get selected start time
            DownTimeS=app.start_time_down.Value;
            %             value=app.start_time_down.Value;
            %             list = app.start_time_down.Items;
            %             [~,DownTimeS] =ismember(value,list);
            % get selected End time
            DownTimeE=app.End_time_down.Value;
            %             value=app.End_time_down.Value;
            %             list = app.End_time_down.Items;
            %             [~,DownTimeE] =ismember(value,list);
            % get pot volume
            value=app.pot_list_down.Value;
            list = app.pot_list_down.Items;
            [~,DownPot] =ismember(value,list);
            vars = app.DownTimeTable.ColumnName;
            if DownPot == 4
                Pots = string(list(1:3)');
                DownTimeE = repmat(str2num(DownTimeE),[3 1]);
                DownTimeS = repmat(str2num(DownTimeS),[3 1]);
                DownDay = repmat(string(DownDay),[3 1]);
                tab = table(DownTimeE,DownTimeS,Pots,DownDay,'VariableNames',vars);
                
            else %only one pot was selected
                DownTimeE = repmat(str2num(DownTimeE),[1 1]);
                DownTimeS = repmat(str2num(DownTimeS),[1 1]);
                DownDay = repmat(string(DownDay),[1 1]);
                value = repmat(string(value),[1 1]);
                tab = table(DownTimeE,DownTimeS,value,DownDay,'VariableNames',vars);
            end
            if isempty(app.DownTimeTable.Data)
                
                app.DownTimeTable.Data = tab;
                
            else
                temp = app.DownTimeTable.Data;
                
                temp = [temp; tab];
                
                
                app.DownTimeTable.Data = temp;
            end
            app.DownTimeTable.ColumnName = vars;
            
        end

        % Button pushed function: Raddrecipe
        function RaddrecipePushed(app, event)
            
            flag = 1;
            if (app.Rserialnumber.Value == 0) || isempty(app.Rserialnumber.Value)
                flag = 0;
                waitfor(errordlg('חסר מספר מק"ט'))
            end
            if (app.RcookingT.Value == 0) || isempty(app.RcookingT.Value)
                flag = 0;
                waitfor(errordlg('חסר זמן בישול בשעות'))
            end
            if isempty(app.Rname.Value)
                flag = 0;
                waitfor(errordlg('חסר שם מתכון'))
            end
            if (app.RcleaningT.Value == 0) || isempty(app.RcleaningT.Value)
                flag = 0;
                waitfor(errordlg('חסר זמן נקיון'))
            end
            
            if flag ==1
                % add to cooking table
                Data = app.CookingTimesTable.Data;
                temp = Data(1,:);
                temp.Cooking_time = app.RcookingT.Value;
                temp.Serial_number = app.Rserialnumber.Value;
                temp.Recipe = {app.Rname.Value};
                
                app.CookingTimesTable.Data = [Data ; temp(1,Data.Properties.VariableNames)];
                
                %% add to portions table
                Data2 = app.CookingSizesTable.Data;
                temp2 = Data2(1,:);
                temp2.("מק""ט") = app.Rserialnumber.Value;
                temp2.("מתכון") = {app.Rname.Value};
                temp2.P300 = 1;temp2.P400 = 1;temp2.P450 = 1;
                temp2.P500 = 1;temp2.P600 = 1;temp2.P800 = 1;
                temp2.P1200 = 1;
                app.CookingSizesTable.Data = [Data2 ; temp2(1,Data2.Properties.VariableNames)];
                
                % add to cleaning table
                
                
                Data3 = app.cleaning_table;
                temp3 = Data3(1,:);
                
                
                if strcmp(app.RcleaningT_dur.Value,'דקות')
                    temp3.Cleaning_time = app.RcleaningT.Value/60;
                else
                    temp3.Cleaning_time = app.RcleaningT.Value;
                end
                temp3.Serial_number = app.Rserialnumber.Value;
                temp3.Recipe = {app.Rname.Value};
                temp3.Pair_cooking = 0;
                temp3.Recomendation = 1;
                
                app.cleaning_table = [Data3 ; temp3(1,Data3.Properties.VariableNames)];
                
                %% add to weeklytable
                Data4 = app.weeklyrequirementsTable.Data;
                temp4 = Data4(1,:);
                temp4.Serial_number = app.Rserialnumber.Value;
                temp4.Recipe = {app.Rname.Value};
                temp4.thur = 0;
                temp4.sun = 0;
                temp4.mon = 0;
                temp4.tue = 0;
                temp4.wed = 0;
                app.weeklyrequirementsTable.Data = [Data4 ; temp4(1,Data4.Properties.VariableNames)];
                
                waitfor(msgbox('מתכון חדש נוסף','הוספת מתכון','custom',imread("שף.jpg")))
                % restore default values
                app.Rserialnumber.Value = 0;
                app.RcookingT.Value = 3;
                app.Rname.Value = '';
            else
                waitfor(msgbox('הכנס פרטי מתכון תקינים להוספה','הוספת מתכון','error'))
            end
        end

        % Button pushed function: Rremoverecipe
        function RremoverecipePushed(app, event)
            
            if (app.Rserialnumber.Value == 0) || isempty(app.Rserialnumber.Value)
                waitfor(msgbox('לא נמצא מתכון להסרה','הסרת מתכון','error'))
                return
            end
            % remove from cooking table
            Data = app.CookingTimesTable.Data;
            Data(Data.Serial_number == app.Rserialnumber.Value,:) = [];
            
            app.CookingTimesTable.Data = Data ;
            
            %% remove from portions table
            Data2 = app.CookingSizesTable.Data;
            Data2(Data2.("מק""ט") == app.Rserialnumber.Value,:) = [];
            app.CookingSizesTable.Data = Data2;
            
            % remove from cleaning table
            
            Data3 = app.cleaning_table;
            Data3(Data3.Serial_number == app.Rserialnumber.Value,:) = [];
            
            app.cleaning_table = Data3 ;
            
            %% remove from weeklytable
            Data4 = app.weeklyrequirementsTable.Data;
            Data4(Data4.Serial_number == app.Rserialnumber.Value,:) = [];
            app.weeklyrequirementsTable.Data = Data4;
            
            waitfor(msgbox(sprintf('הוסר מתכון  %d',app.Rserialnumber.Value),'הסרת מתכון','custom',imread("שף.jpg")))
            % restore default values
            app.Rserialnumber.Value = 0;
            app.RcookingT.Value = 0;
            app.Rname.Value = '';
            
        end

        % Callback function
        function addreqMenuSelected(app, event)
            reqs = app.weeklyrequirementsTable.Data;
            reqs(end +1,:) = {'',0,0,0,0,0};
            app.weeklyrequirementsTable.Data = reqs;
        end

        % Value changed function: AllowClean1200
        function AllowClean1200ValueChanged(app, event)
            value = app.AllowClean1200.Value;
            if value == 1 %checkbox on
                app.Time_1200.Editable = 'On';
                app.Time_1200.Enable = 'On';
                app.min_hour_1200.Enable = 'On';
                app.DOTime_1200_Time_2.Editable = 'On';
                app.DOTime_1200_Time_2.Enable = 'On';
                app.DOTime_1200_min_hour_2.Enable = 'On';
            else
                app.Time_1200.Editable = 'Off';
                app.Time_1200.Enable = 'Off';
                app.min_hour_1200.Enable = 'Off';
                app.DOTime_1200_Time_2.Editable = 'Off';
                app.DOTime_1200_Time_2.Enable = 'Off';
                app.DOTime_1200_min_hour_2.Enable = 'Off';
            end
        end

        % Value changed function: AllowWashTime
        function AllowWashTimeValueChanged(app, event)
            value = app.AllowWashTime.Value;
            if value == 1 %checkbox on
                app.WashTime.Editable = 'On';
                app.WashTime.Enable = 'On';
                app.WashTime_min_hour.Enable = 'On';
            else
                app.WashTime.Editable = 'Off';
                app.WashTime.Enable = 'Off';
                app.WashTime_min_hour.Enable = 'Off';
            end
        end

        % Value changed function: AllowCIP
        function AllowCIPValueChanged(app, event)
            value = app.AllowCIP.Value;
            if value == 1 %checkbox on
                app.CIP_Time.Editable = 'On';
                app.CIP_Time.Enable = 'On';
                app.CIP_min_hour.Enable = 'On';
                app.DOCIP_Time_2.Editable = 'On';
                app.DOCIP_Time_2.Enable = 'On';
                app.DOCIP_min_hour_2.Enable = 'On';
            else
                app.CIP_Time.Editable = 'Off';
                app.CIP_Time.Enable = 'Off';
                app.CIP_min_hour.Enable = 'Off';
                app.DOCIP_Time_2.Editable = 'Off';
                app.DOCIP_Time_2.Enable = 'Off';
                app.DOCIP_min_hour_2.Enable = 'Off';
            end
        end

        % Value changed function: CIP_Time, CIP_min_hour, 
        % DOCIP_Time_2, DOCIP_min_hour_2, DOTime_1200_Time_2, 
        % DOTime_1200_min_hour_2, Time_1200, WashTime, 
        % WashTime_min_hour, min_hour_1200
        function CIP_TimeValueChanged(app, event)
            getCIPtimes(app);
        end

        % Value changed function: EndDay_1, EndDay_2, EndDay_3, 
        % StartDay_1, StartDay_2, StartDay_3, end_time_1, 
        % end_time_2, end_time_3, start_time_1, start_time_2, 
        % start_time_3
        function WeeklyTimesValueChanged(app, event)
            %get the tab where change occured
            selectTab = event.Source.Parent.Parent.Parent.Title;

            if strcmp(selectTab,'סיר 1')
                selectTabN = 1;
            elseif strcmp(selectTab,'סיר 2')
                selectTabN = 2;
            elseif strcmp(selectTab,'סיר 3')
                selectTabN = 3;
            end
           
            setdays(app,selectTabN);
        end

        % Cell edit callback: PotsTable
        function PotsTableCellEdit(app, event)
            setVolumes(app);
        end

        % Button pushed function: outFileButton
        function outFileButtonPushed(app, event)
            prompt = {'הכנס שם לקובץ פלט:'};
            dlgtitle = 'קובץ פלט';
            dims = [1 50];
            definput = {'תוכנית_שבועית'};
            out = inputdlg(prompt,dlgtitle,dims,definput);
            try
                app.outFile = out{:};
            catch
                app.outFile = definput{:};
            end
            copyfile(fullfile(app.mainpath,'templete.xlsx'),[app.mainpath '\' app.outFile '.xlsx'])
            %disp(['The output is saved in:' app.outFile])
        end

        % Callback function
        function MainAppCloseRequest(app, event)
            % NOT VALID FOR NOW, Maybe Implemented later
            % Keep for now, never called
            %
            %
            % check for changes in the cooking lists app.TotalRecipes
            if height(app.CookingTimesTable.Data) ~= app.TotalRecipes
                % some recipes were added from the original file.
                quest = 'נערכו שינויים במתכונים באפליקציה, האם לשמור לקובץ המקורי?';
                dlgtitle = 'שינויים בכמות המתכונים';
                btn1 = 'שמור שינויים';btn2='אל תשמור';defbtn = btn1;
                answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
                if strcmp(btn1,answer)
                    %want to save the recipes to orig file
                    NR = height(app.CookingTimesTable.Data)-app.TotalRecipes;
                    if NR>0
                        % נתוני בישול
                        %% prepare files for Struss main function
                        path2file2 = 'בישול  פרי נתונים.xlsx';
                        temp = app.CookingTimesTable.Data(end-NR:end,:);
                        % A col - serial num
                        % B col - recipe name
                        % C col - cooking time (int)
                        rangeval = sprintf('A%d',app.TotalRecipes+1);
                        writecell(temp.Serial_number,path2file2,'Range',rangeval);
                        rangeval = sprintf('B%d',app.TotalRecipes+1);
                        writecell(temp.Recipe,path2file2,'Range',rangeval);
                        rangeval = sprintf('C%d',app.TotalRecipes+1);
                        writecell(temp.Cooking_time,path2file2,'Range',rangeval);
                        
                        % אילוצים
                        
                    end
                end
            end
            
            
            delete(app)
            
        end

        % Value changed function: WEEKLY
        function WEEKLYValueChanged(app, event)
            value = app.WEEKLY.Value;
            if value
                app.SUN_label.Enable = 'on';
                app.SUN_time.Enable = 'on';
                app.THUR_label.Enable = 'on';
                app.THUR_time.Enable = 'on';
            else
                app.SUN_label.Enable = 'off';
                app.SUN_time.Enable = 'off';
                app.THUR_label.Enable = 'off';
                app.THUR_time.Enable = 'off';
            end
        end

        % Value changed function: FRI
        function FRIValueChanged(app, event)
            value = app.FRI.Value;
            
            if value
                app.FRI_END.Enable = 'on';
                app.FRI_END_label.Enable = 'on';
                app.FRI_START.Enable = 'on';
                app.FRI_START_label.Enable = 'on';
                %% ask to change Thursday
                dlgtitle = 'שעות בישול בחמישי';
                quest = {'האם לשנות את שעת סיום הביושל בחמישי?'};
                btn1 = 'כן - פתח לעריכה';
                btn2 = 'לא';
                defbtn  = btn1;
                answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
                switch answer
                    case btn1
                        app.WEEKLY.Value = true;
                        WEEKLYValueChanged(app, [])
                    case btn2
                        thur_end = app.THUR_time.Value;
                        warndlg(sprintf('שעת סיום הבישול בחמישי \n%s',thur_end),'שעות בישול בחמישי');
                        
                end
            else
                app.FRI_END.Enable = 'off';
                app.FRI_END_label.Enable = 'off';
                app.FRI_START.Enable = 'off';
                app.FRI_START_label.Enable = 'off';
                THUR_timeValueChanged(app, event)
            end
        end

        % Value changed function: SAT
        function SATValueChanged(app, event)
            value = app.SAT.Value;
            if value
                app.SAT_END.Enable = 'on';
                app.SAT_END_label.Enable = 'on';
                app.SAT_START.Enable = 'on';
                app.SAT_START_label.Enable = 'on';
                %% ask to change Thursday
                dlgtitle ='שעות בישול בראשון';
                quest = {'האם לשנות את תחילת הבישול בראשון?'};
                btn1 = 'כן - פתח לעריכה';
                btn2 = 'לא';
                defbtn  = btn1;
                answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
                switch answer
                    case btn1
                        app.WEEKLY.Value = true;
                        WEEKLYValueChanged(app, [])
                    case btn2
                        sun_end = app.SUN_time.Value;
                     
                        warndlg(sprintf('תחילת שעת בישול ביום ראשון \n%s',sun_end),'שעות בישול בראשון');
                        
                        
                end
            else
                app.SAT_END.Enable = 'off';
                app.SAT_END_label.Enable = 'off';
                app.SAT_START.Enable = 'off';
                app.SAT_START_label.Enable = 'off';
                app.extraTime = [];
            end
        end

        % Cell selection callback: DownTimeTable
        function DownTimeTableCellSelection(app, event)
            indices = event.Indices;
            %app.DownTimeTable.Data(indices(1),:)
            quest = 'האם תרצה למחוק את השורה שנבחרה?';
            dlgtitle = 'הגדרת זמני השבתה';
            btn1='כן';btn2='לא';
            defbtn=btn1;
            answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
            if strcmp(answer,btn1)
                app.DownTimeTable.Data(indices(1),:) = [];
            end
            
        end

        % Value changed function: THUR_time
        function THUR_timeValueChanged(app, event)
            value = app.THUR_time.Value;
            app.stopcooking = str2num(value);
        end

        % Value changed function: SUN_time
        function SUN_timeValueChanged(app, event)
            value = app.SUN_time.Value;
            app.startcooking = str2num(value);
        end

        % Value changed function: FRI_END, FRI_START
        function FRI_ValueChanged(app, event)
            THUR_timeValueChanged(app, event)
            app.stopcooking = app.stopcooking + (str2num(app.FRI_END.Value) - str2num(app.FRI_START.Value));
        end

        % Value changed function: SAT_START
        function SAT_ValueChanged(app, event)
            app.extraTime = str2num(app.SAT_END.Value)-str2num(app.SAT_START.Value);
        end

        % Value changed function: capacityEditField
        function capacityEditFieldValueChanged(app, event)
            value = app.capacityEditField.Value;
            app.capacitySetting = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'שטראוס';
            app.TabGroup = uitabgroup(app.MainApp);
            app.WeeklyRecipe = uitab(app.TabGroup);
            app.WeeklyRecipe.Title = 'מתכון שבועי';
            app.MainLayout = uigridlayout(app.WeeklyRecipe);
            app.StraussImag = uiimage(app.MainLayout);
            app.StraussImag.ImageSource = 'download.png';
            app.getRequ = uibutton(app.MainLayout, 'push');
            app.getRequ.ButtonPushedFcn = createCallbackFcn(app, @getReqPushed, true);
            app.getRequ.Text = 'הכנס תוכנית שבועית';
            app.CalcPlan = uibutton(app.MainLayout, 'push');
            app.CalcPlan.ButtonPushedFcn = createCallbackFcn(app, @CalcPlanButtonPushed, true);
            app.CalcPlan.Enable = 'off';
            app.CalcPlan.Text = 'חשב תוכנית שבועית';
            app.outFileButton = uibutton(app.MainLayout, 'push');
            app.outFileButton.ButtonPushedFcn = createCallbackFcn(app, @outFileButtonPushed, true);
            app.outFileButton.Icon = 'settings.png';
            app.outFileButton.Enable = 'off';
            app.outFileButton.Text = '';
            app.OutputTab = uitabgroup(app.MainLayout);
            app.Pot_1 = uitab(app.OutputTab);
            app.Pot_1.Title = 'סיר 1';
            app.Grid_Pot1 = uigridlayout(app.Pot_1);
            app.WeeklyPlan_1 = uitable(app.Grid_Pot1);
            app.WeeklyPlan_1.ColumnName = {'מתכון'; 'כמות'; 'משך_בישול'; 'זמני_נקיון'; 'זמן_כולל'; 'יום בישול'};
            app.WeeklyPlan_1.RowName = {};
            app.PotVolLabel_1 = uilabel(app.Grid_Pot1);
            app.PotVolLabel_1.Text = 'סיר בנפח';
            app.UpdateVol_1 = uilabel(app.Grid_Pot1);
            app.UpdateVol_1.Text = 'Vol';
            app.PotCapLabel_1 = uilabel(app.Grid_Pot1);
            app.PotCapLabel_1.Text = 'עומס בישול';
            app.updateCap_1 = uilabel(app.Grid_Pot1);
            app.updateCap_1.Text = 'Cap';
            app.PotPORLabel_1 = uilabel(app.Grid_Pot1);
            app.PotPORLabel_1.Text = 'כמות מנות';
            app.updateportions_1 = uilabel(app.Grid_Pot1);
            app.updateportions_1.Text = 'Cap';
            app.Pot_2 = uitab(app.OutputTab);
            app.Pot_2.Title = 'סיר 2';
            app.Grid_Pot2 = uigridlayout(app.Pot_2);
            app.WeeklyPlan_2 = uitable(app.Grid_Pot2);
            app.WeeklyPlan_2.ColumnName = {'מתכון'; 'כמות'; 'משך_בישול'; 'זמני_נקיון'; 'זמן_כולל'; 'יום בישול'};
            app.WeeklyPlan_2.RowName = {};
            app.PotVolLabel_2 = uilabel(app.Grid_Pot2);
            app.PotVolLabel_2.Text = 'סיר בנפח';
            app.UpdateVol_2 = uilabel(app.Grid_Pot2);
            app.UpdateVol_2.Text = 'Vol';
            app.PotCapLabel_2 = uilabel(app.Grid_Pot2);
            app.PotCapLabel_2.Text = 'עומס בישול';
            app.updateCap_2 = uilabel(app.Grid_Pot2);
            app.updateCap_2.Text = 'Cap';
            app.PotPORLabel_2 = uilabel(app.Grid_Pot2);
            app.PotPORLabel_2.Text = 'כמות מנות';
            app.updateportions_2 = uilabel(app.Grid_Pot2);
            app.updateportions_2.Text = 'Cap';
            app.Pot_3 = uitab(app.OutputTab);
            app.Pot_3.Title = 'סיר 3';
            app.Grid_Pot3 = uigridlayout(app.Pot_3);
            app.WeeklyPlan_3 = uitable(app.Grid_Pot3);
            app.WeeklyPlan_3.ColumnName = {'מתכון'; 'כמות'; 'משך_בישול'; 'זמני_נקיון'; 'זמן_כולל'; 'יום בישול'};
            app.WeeklyPlan_3.RowName = {};
            app.PotVolLabel_3 = uilabel(app.Grid_Pot3);
            app.PotVolLabel_3.Text = 'סיר בנפח';
            app.UpdateVol_3 = uilabel(app.Grid_Pot3);
            app.UpdateVol_3.Text = 'Vol';
            app.PotCapLabel_3 = uilabel(app.Grid_Pot3);
            app.PotCapLabel_3.Text = 'עומס בישול';
            app.updateCap_3 = uilabel(app.Grid_Pot3);
            app.updateCap_3.Text = 'Cap';
            app.PotPORLabel_3 = uilabel(app.Grid_Pot3);
            app.PotPORLabel_3.Text = 'כמות מנות';
            app.updateportions_3 = uilabel(app.Grid_Pot3);
            app.updateportions_3.Text = 'Cap';
            app.TottalLoad = uilabel(app.MainLayout);
            app.TottalLoad.Visible = 'off';
            app.TottalLoad.Tooltip = {'עומס כללי עבור כל השבוע'};
            app.TottalLoad.Text = 'עומס כללי';
            app.TabGroup2 = uitabgroup(app.MainLayout);
            app.Tab_1 = uitab(app.TabGroup2);
            app.Tab_1.Title = 'סיר 1';
            app.GridLayout_1 = uigridlayout(app.Tab_1);
            app.Grid_Times_1 = uigridlayout(app.GridLayout_1);
            app.Label_StartDay = uilabel(app.Grid_Times_1);
            app.Label_StartDay.Text = 'תחילת שבוע';
            app.StartDay_1 = uidropdown(app.Grid_Times_1);
            app.StartDay_1.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.StartDay_1.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.StartDay_1.Value = 'יום ד';
            app.Label_EndDay = uilabel(app.Grid_Times_1);
            app.Label_EndDay.Text = 'סוף שבוע';
            app.EndDay_1 = uidropdown(app.Grid_Times_1);
            app.EndDay_1.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.EndDay_1.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.EndDay_1.Value = 'יום ג';
            app.Label_start_time = uilabel(app.Grid_Times_1);
            app.Label_start_time.Text = 'שעת התחלה';
            app.start_time_1 = uidropdown(app.Grid_Times_1);
            app.start_time_1.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.start_time_1.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.start_time_1.Value = '6';
            app.Label_end_time = uilabel(app.Grid_Times_1);
            app.Label_end_time.Text = 'שעת סיום';
            app.end_time_1 = uidropdown(app.Grid_Times_1);
            app.end_time_1.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.end_time_1.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.end_time_1.Value = '21';
            app.Label_Tdays = uilabel(app.Grid_Times_1);
            app.Label_Tdays.Text = 'סך כל הימים לבישול';
            app.TdaysLabel_1 = uilabel(app.Grid_Times_1);
            app.TdaysLabel_1.Text = '5';
            app.Label_times = uilabel(app.Grid_Times_1);
            app.Label_times.Text = 'סך כל השעות לבישול';
            app.timesLabel_1 = uilabel(app.Grid_Times_1);
            app.timesLabel_1.Text = '102';
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'סיר 2';
            app.GridLayout_2 = uigridlayout(app.Tab_2);
            app.Grid_Times_2 = uigridlayout(app.GridLayout_2);
            app.Label_StartDay_2 = uilabel(app.Grid_Times_2);
            app.Label_StartDay_2.Text = 'תחילת שבוע';
            app.StartDay_2 = uidropdown(app.Grid_Times_2);
            app.StartDay_2.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.StartDay_2.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.StartDay_2.Value = 'יום ד';
            app.Label_EndDay_2 = uilabel(app.Grid_Times_2);
            app.Label_EndDay_2.Text = 'סוף שבוע';
            app.EndDay_2 = uidropdown(app.Grid_Times_2);
            app.EndDay_2.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.EndDay_2.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.EndDay_2.Value = 'יום ג';
            app.Label_start_time_2 = uilabel(app.Grid_Times_2);
            app.Label_start_time_2.Text = 'שעת התחלה';
            app.start_time_2 = uidropdown(app.Grid_Times_2);
            app.start_time_2.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.start_time_2.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.start_time_2.Value = '6';
            app.Label_end_time_2 = uilabel(app.Grid_Times_2);
            app.Label_end_time_2.Text = 'שעת סיום';
            app.end_time_2 = uidropdown(app.Grid_Times_2);
            app.end_time_2.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.end_time_2.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.end_time_2.Value = '21';
            app.Label_TdaysLabel_2 = uilabel(app.Grid_Times_2);
            app.Label_TdaysLabel_2.Text = 'סך כל הימים לבישול';
            app.TdaysLabel_2 = uilabel(app.Grid_Times_2);
            app.TdaysLabel_2.Text = '5';
            app.Label_timesLabel_2 = uilabel(app.Grid_Times_2);
            app.Label_timesLabel_2.Text = 'סך כל השעות לבישול';
            app.timesLabel_2 = uilabel(app.Grid_Times_2);
            app.timesLabel_2.Text = '102';
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = 'סיר 3';
            app.GridLayout_3 = uigridlayout(app.Tab_3);
            app.Grid_Times_3 = uigridlayout(app.GridLayout_3);
            app.Label_StartDay_3 = uilabel(app.Grid_Times_3);
            app.Label_StartDay_3.Text = 'תחילת שבוע';
            app.StartDay_3 = uidropdown(app.Grid_Times_3);
            app.StartDay_3.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.StartDay_3.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.StartDay_3.Value = 'יום ד';
            app.Label_EndDay_3 = uilabel(app.Grid_Times_3);
            app.Label_EndDay_3.Text = 'סוף שבוע';
            app.EndDay_3 = uidropdown(app.Grid_Times_3);
            app.EndDay_3.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.EndDay_3.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.EndDay_3.Value = 'יום ג';
            app.Label_start_time_3 = uilabel(app.Grid_Times_3);
            app.Label_start_time_3.Text = 'שעת התחלה';
            app.start_time_3 = uidropdown(app.Grid_Times_3);
            app.start_time_3.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.start_time_3.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.start_time_3.Value = '6';
            app.Label_end_time_3 = uilabel(app.Grid_Times_3);
            app.Label_end_time_3.Text = 'שעת סיום';
            app.end_time_3 = uidropdown(app.Grid_Times_3);
            app.end_time_3.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.end_time_3.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.end_time_3.Value = '21';
            app.Label_TdaysLabel_3 = uilabel(app.Grid_Times_3);
            app.Label_TdaysLabel_3.Text = 'סך כל הימים לבישול';
            app.TdaysLabel_3 = uilabel(app.Grid_Times_3);
            app.TdaysLabel_3.Text = '5';
            app.Label_timesLabel_3 = uilabel(app.Grid_Times_3);
            app.Label_timesLabel_3.Text = 'סך כל השעות לבישול';
            app.timesLabel_3 = uilabel(app.Grid_Times_3);
            app.timesLabel_3.Text = '102';
            app.capacityEditFieldLabel = uilabel(app.MainLayout);
            app.capacityEditFieldLabel.Text = 'Total Weekly Capacity:';
            app.capacityEditField = uieditfield(app.MainLayout, 'numeric');
            app.capacityEditField.Limits = [0 Inf];
            app.capacityEditField.ValueChangedFcn = createCallbackFcn(app, @capacityEditFieldValueChanged, true);
            app.capacityEditField.Value = 120;
            app.weeklyRequirements = uitab(app.TabGroup);
            app.weeklyRequirements.Title = 'דרישות ';
            app.GridLayout10 = uigridlayout(app.weeklyRequirements);
            app.weeklyrequirementsTable = uitable(app.GridLayout10);
            app.weeklyrequirementsTable.ColumnName = {'יום ה'; 'יום ד'; 'יום ג'; 'יום ב'; 'יום א'; 'מתכון'};
            app.weeklyrequirementsTable.RowName = {};
            app.CookingTimesDeafult = uitab(app.TabGroup);
            app.CookingTimesDeafult.Title = 'זמני בישול';
            app.GridLayout9 = uigridlayout(app.CookingTimesDeafult);
            app.CookingTimesTable = uitable(app.GridLayout9);
            app.CookingTimesTable.ColumnName = {'זמן בישול בשעות'; 'מתכון'; 'מק"ט'};
            app.CookingTimesTable.RowName = {};
            app.CookingTimesTable.Tag = 'זמני בישול';
            app.Cookingsize = uitab(app.TabGroup);
            app.Cookingsize.Title = 'גדלי בישול';
            app.GridLayout11 = uigridlayout(app.Cookingsize);
            app.CookingSizesTable = uitable(app.GridLayout11);
            app.CookingSizesTable.ColumnName = {'P1200'; 'P800'; 'P600'; 'P500'; 'P450'; 'P400'; 'P300'; 'מתכון'; 'מק"ט'};
            app.CookingSizesTable.RowName = {};
            app.CookingSizesTable.Tag = 'זמני בישול';
            app.settings = uitab(app.TabGroup);
            app.settings.Title = 'הגדרות';
            app.PDownTime = uipanel(app.settings);
            app.PDownTime.Title = 'זמני השבתה';
            app.GridLayout3 = uigridlayout(app.PDownTime);
            app.addDownTime = uibutton(app.GridLayout3, 'push');
            app.addDownTime.ButtonPushedFcn = createCallbackFcn(app, @addDownTimeButtonPushed, true);
            app.addDownTime.Text = 'הוסף לתכנון';
            app.Start_time_label = uilabel(app.GridLayout3);
            app.Start_time_label.Text = 'שעת התחלה';
            app.start_time_down = uidropdown(app.GridLayout3);
            app.start_time_down.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.start_time_down.Value = '6';
            app.End_time_label = uilabel(app.GridLayout3);
            app.End_time_label.Text = 'שעת סיום';
            app.End_time_down = uidropdown(app.GridLayout3);
            app.End_time_down.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.End_time_down.Value = '13';
            app.Start_day_label = uilabel(app.GridLayout3);
            app.Start_day_label.Text = 'יום בשבוע';
            app.Start_day_down = uidropdown(app.GridLayout3);
            app.Start_day_down.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.Start_day_down.Value = 'יום א';
            app.Label_12 = uilabel(app.GridLayout3);
            app.Label_12.Text = 'סיר';
            app.pot_list_down = uidropdown(app.GridLayout3);
            app.pot_list_down.Items = {'סיר 1 - 600', 'סיר 2 - 600', 'סיר 3 - 1200', 'כולם'};
            app.pot_list_down.Value = 'כולם';
            app.DownTimeTable = uitable(app.GridLayout3);
            app.DownTimeTable.ColumnName = {'שעת סיום'; 'שעת התחלה'; 'נפח סיר'; 'יום בשבוע'};
            app.DownTimeTable.RowName = {};
            app.DownTimeTable.CellSelectionCallback = createCallbackFcn(app, @DownTimeTableCellSelection, true);
            app.MaintananceImage = uiimage(app.GridLayout3);
            app.MaintananceImage.ImageSource = 'breakImg.jpg';
            app.PPots = uipanel(app.settings);
            app.PPots.Title = 'סירים';
            app.GridLayout2 = uigridlayout(app.PPots);
            app.PotsTable = uitable(app.GridLayout2);
            app.PotsTable.ColumnName = {'נפח'};
            app.PotsTable.RowName = {'1,2,3'};
            app.PotsTable.ColumnEditable = true;
            app.PotsTable.CellEditCallback = createCallbackFcn(app, @PotsTableCellEdit, true);
            app.removePot = uibutton(app.GridLayout2, 'push');
            app.removePot.ButtonPushedFcn = createCallbackFcn(app, @removePotButtonPushed, true);
            app.removePot.Text = '-';
            app.PotsImage = uiimage(app.GridLayout2);
            app.PotsImage.ImageSource = 'potImgs.jpg';
            app.addPot = uibutton(app.GridLayout2, 'push');
            app.addPot.ButtonPushedFcn = createCallbackFcn(app, @addPotButtonPushed, true);
            app.addPot.Text = '+';
            app.PAddRecipe = uipanel(app.settings);
            app.PAddRecipe.Title = 'הוספת מתכון';
            app.GridLayout4 = uigridlayout(app.PAddRecipe);
            app.Raddrecipe = uibutton(app.GridLayout4, 'push');
            app.Raddrecipe.ButtonPushedFcn = createCallbackFcn(app, @RaddrecipePushed, true);
            app.Raddrecipe.Text = 'הוספה';
            app.Rremoverecipe = uibutton(app.GridLayout4, 'push');
            app.Rremoverecipe.ButtonPushedFcn = createCallbackFcn(app, @RremoverecipePushed, true);
            app.Rremoverecipe.Text = 'הסרה';
            app.RecImg = uiimage(app.GridLayout4);
            app.RecImg.ImageSource = 'addrecipeImg.jpg';
            app.Label_9 = uilabel(app.GridLayout4);
            app.Label_9.Text = 'מספר מק"ט';
            app.Rserialnumber = uieditfield(app.GridLayout4, 'numeric');
            app.Label_11 = uilabel(app.GridLayout4);
            app.Label_11.Text = 'שם מתכון';
            app.Rname = uieditfield(app.GridLayout4, 'text');
            app.RcleaningT = uieditfield(app.GridLayout4, 'numeric');
            app.RcleaningT.Limits = [0 Inf];
            app.RcleaningT.Value = 20;
            app.RcleaningT_Label = uilabel(app.GridLayout4);
            app.RcleaningT_Label.Text = 'זמן נקיון';
            app.RcleaningT_dur = uidropdown(app.GridLayout4);
            app.RcleaningT_dur.Items = {'שעות', 'דקות'};
            app.RcleaningT_dur.Value = 'דקות';
            app.RcookingT_Label = uilabel(app.GridLayout4);
            app.RcookingT_Label.Text = 'זמן בישול';
            app.RcookingT = uieditfield(app.GridLayout4, 'numeric');
            app.RcookingT.Limits = [0 Inf];
            app.RcookingT.Value = 3;
            app.RcookingT_Label_2 = uilabel(app.GridLayout4);
            app.RcookingT_Label_2.Text = 'שעות';
            app.PCleaning = uipanel(app.settings);
            app.PCleaning.Title = 'זמני נקיון';
            app.GridLayout = uigridlayout(app.PCleaning);
            app.AllowCIP = uicheckbox(app.GridLayout);
            app.AllowCIP.ValueChangedFcn = createCallbackFcn(app, @AllowCIPValueChanged, true);
            app.AllowCIP.Text = '';
            app.DescriptionCIP = uilabel(app.GridLayout);
            app.DescriptionCIP.Text = 'זמן נקיון עבור כל הסירים CIP';
            app.AllowClean1200 = uicheckbox(app.GridLayout);
            app.AllowClean1200.ValueChangedFcn = createCallbackFcn(app, @AllowClean1200ValueChanged, true);
            app.AllowClean1200.Text = '';
            app.Description1200 = uilabel(app.GridLayout);
            app.Description1200.Text = 'זמן נקיון עבור הסיר בנפח 1200 בלבד';
            app.CIP_Time = uieditfield(app.GridLayout, 'numeric');
            app.CIP_Time.Limits = [0 Inf];
            app.CIP_Time.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.CIP_Time.Enable = 'off';
            app.CIP_Time.Value = 4;
            app.CIP_min_hour = uidropdown(app.GridLayout);
            app.CIP_min_hour.Items = {'שעות', 'דקות'};
            app.CIP_min_hour.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.CIP_min_hour.Enable = 'off';
            app.CIP_min_hour.Value = 'שעות';
            app.Time_1200 = uieditfield(app.GridLayout, 'numeric');
            app.Time_1200.Limits = [0 Inf];
            app.Time_1200.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.Time_1200.Enable = 'off';
            app.Time_1200.Value = 40;
            app.min_hour_1200 = uidropdown(app.GridLayout);
            app.min_hour_1200.Items = {'שעות', 'דקות'};
            app.min_hour_1200.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.min_hour_1200.Enable = 'off';
            app.min_hour_1200.Value = 'דקות';
            app.AllowWashTime = uicheckbox(app.GridLayout);
            app.AllowWashTime.ValueChangedFcn = createCallbackFcn(app, @AllowWashTimeValueChanged, true);
            app.AllowWashTime.Text = '';
            app.DescriptionWashTime = uilabel(app.GridLayout);
            app.DescriptionWashTime.Text = 'שטיפה בין פרי לפי - SETUP';
            app.WashTime = uieditfield(app.GridLayout, 'numeric');
            app.WashTime.Limits = [0 Inf];
            app.WashTime.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.WashTime.Enable = 'off';
            app.WashTime.Value = 20;
            app.WashTime_min_hour = uidropdown(app.GridLayout);
            app.WashTime_min_hour.Items = {'שעות', 'דקות'};
            app.WashTime_min_hour.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.WashTime_min_hour.Enable = 'off';
            app.WashTime_min_hour.Value = 'דקות';
            app.DOCIP_min_hour_2 = uidropdown(app.GridLayout);
            app.DOCIP_min_hour_2.Items = {'שעות', 'דקות'};
            app.DOCIP_min_hour_2.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.DOCIP_min_hour_2.Enable = 'off';
            app.DOCIP_min_hour_2.Value = 'שעות';
            app.DOCIP_Time_2 = uieditfield(app.GridLayout, 'numeric');
            app.DOCIP_Time_2.Limits = [0 Inf];
            app.DOCIP_Time_2.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.DOCIP_Time_2.Enable = 'off';
            app.DOCIP_Time_2.Value = 40;
            app.DOTime_1200_Time_2 = uieditfield(app.GridLayout, 'numeric');
            app.DOTime_1200_Time_2.Limits = [0 Inf];
            app.DOTime_1200_Time_2.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.DOTime_1200_Time_2.Enable = 'off';
            app.DOTime_1200_Time_2.Value = 24;
            app.DOTime_1200_min_hour_2 = uidropdown(app.GridLayout);
            app.DOTime_1200_min_hour_2.Items = {'שעות', 'דקות'};
            app.DOTime_1200_min_hour_2.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.DOTime_1200_min_hour_2.Enable = 'off';
            app.DOTime_1200_min_hour_2.Value = 'שעות';
            app.DO_afterT = uilabel(app.GridLayout);
            app.DO_afterT.Text = 'לאחר כל';
            app.FOR_T = uilabel(app.GridLayout);
            app.FOR_T.Text = 'נקיון לאורך';
            app.FloorHrs = uipanel(app.settings);
            app.FloorHrs.Title = 'שעות פעילות- רצפת ייצור';
            app.GridLayout8 = uigridlayout(app.FloorHrs);
            app.SUN_label = uilabel(app.GridLayout8);
            app.SUN_label.Enable = 'off';
            app.SUN_label.Text = 'יום ראשון - שעת התחלה';
            app.SUN_time = uidropdown(app.GridLayout8);
            app.SUN_time.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.SUN_time.ValueChangedFcn = createCallbackFcn(app, @SUN_timeValueChanged, true);
            app.SUN_time.Enable = 'off';
            app.SUN_time.Value = '6';
            app.THUR_label = uilabel(app.GridLayout8);
            app.THUR_label.Enable = 'off';
            app.THUR_label.Text = 'יום חמישי - שעת סיום';
            app.THUR_time = uidropdown(app.GridLayout8);
            app.THUR_time.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.THUR_time.ValueChangedFcn = createCallbackFcn(app, @THUR_timeValueChanged, true);
            app.THUR_time.Enable = 'off';
            app.THUR_time.Value = '15';
            app.SAT = uicheckbox(app.GridLayout8);
            app.SAT.ValueChangedFcn = createCallbackFcn(app, @SATValueChanged, true);
            app.SAT.Text = '';
            app.SAT_label = uilabel(app.GridLayout8);
            app.SAT_label.Text = 'מוצאי שבת';
            app.FRI_label = uilabel(app.GridLayout8);
            app.FRI_label.Text = 'יום שישי';
            app.FRI = uicheckbox(app.GridLayout8);
            app.FRI.ValueChangedFcn = createCallbackFcn(app, @FRIValueChanged, true);
            app.FRI.Text = '';
            app.FRI_START_label = uilabel(app.GridLayout8);
            app.FRI_START_label.Enable = 'off';
            app.FRI_START_label.Text = 'שעת התחלה';
            app.FRI_START = uidropdown(app.GridLayout8);
            app.FRI_START.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.FRI_START.ValueChangedFcn = createCallbackFcn(app, @FRI_ValueChanged, true);
            app.FRI_START.Enable = 'off';
            app.FRI_START.Value = '0';
            app.FRI_END_label = uilabel(app.GridLayout8);
            app.FRI_END_label.Enable = 'off';
            app.FRI_END_label.Text = 'שעת סיום';
            app.FRI_END = uidropdown(app.GridLayout8);
            app.FRI_END.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.FRI_END.ValueChangedFcn = createCallbackFcn(app, @FRI_ValueChanged, true);
            app.FRI_END.Enable = 'off';
            app.FRI_END.Value = '12';
            app.SAT_START_label = uilabel(app.GridLayout8);
            app.SAT_START_label.Enable = 'off';
            app.SAT_START_label.Text = 'שעת התחלה';
            app.SAT_START = uidropdown(app.GridLayout8);
            app.SAT_START.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.SAT_START.ValueChangedFcn = createCallbackFcn(app, @SAT_ValueChanged, true);
            app.SAT_START.Enable = 'off';
            app.SAT_START.Value = '18';
            app.SAT_END_label = uilabel(app.GridLayout8);
            app.SAT_END_label.Enable = 'off';
            app.SAT_END_label.Text = 'שעת סיום';
            app.SAT_END = uidropdown(app.GridLayout8);
            app.SAT_END.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.SAT_END.Enable = 'off';
            app.SAT_END.Value = '21';
            app.Weekly_label = uilabel(app.GridLayout8);
            app.Weekly_label.Text = 'שבועי';
            app.WEEKLY = uicheckbox(app.GridLayout8);
            app.WEEKLY.ValueChangedFcn = createCallbackFcn(app, @WEEKLYValueChanged, true);
            app.WEEKLY.Text = '';
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Strauss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end