classdef Last_Ver_of_Amit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                  matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        WeeklyRecipe             matlab.ui.container.Tab
        GridLayout12             matlab.ui.container.GridLayout
        outFileButton            matlab.ui.control.Button
        CalcPlan                 matlab.ui.control.Button
        getRequ                  matlab.ui.control.Button
        TabGroup2                matlab.ui.container.TabGroup
        Tab_1                    matlab.ui.container.Tab
        GridLayout_1             matlab.ui.container.GridLayout
        Grid_Times_1             matlab.ui.container.GridLayout
        Label_16                 matlab.ui.control.Label
        StartDay_1               matlab.ui.control.DropDown
        Label_17                 matlab.ui.control.Label
        EndDay_1                 matlab.ui.control.DropDown
        Label_start_time         matlab.ui.control.Label
        start_time_1             matlab.ui.control.DropDown
        Label_end_time           matlab.ui.control.Label
        end_time_1               matlab.ui.control.DropDown
        Button                   matlab.ui.control.Button
        Tab_2                    matlab.ui.container.Tab
        GridLayout_2             matlab.ui.container.GridLayout
        Grid_Times_2             matlab.ui.container.GridLayout
        Label_StartDay_2         matlab.ui.control.Label
        StartDay_2               matlab.ui.control.DropDown
        Label_EndDay_2           matlab.ui.control.Label
        EndDay_2                 matlab.ui.control.DropDown
        Label_start_time_2       matlab.ui.control.Label
        start_time_2             matlab.ui.control.DropDown
        Label_end_time_2         matlab.ui.control.Label
        end_time_2               matlab.ui.control.DropDown
        Tab_3                    matlab.ui.container.Tab
        GridLayout_3             matlab.ui.container.GridLayout
        Grid_Times_3             matlab.ui.container.GridLayout
        Label_StartDay_3         matlab.ui.control.Label
        StartDay_3               matlab.ui.control.DropDown
        Label_EndDay_3           matlab.ui.control.Label
        EndDay_3                 matlab.ui.control.DropDown
        Label_start_time_3       matlab.ui.control.Label
        start_time_3             matlab.ui.control.DropDown
        Label_end_time_3         matlab.ui.control.Label
        end_time_3               matlab.ui.control.DropDown
        TottalLoad               matlab.ui.control.Label
        OutputTab                matlab.ui.container.TabGroup
        Pot_1                    matlab.ui.container.Tab
        Grid_Pot1                matlab.ui.container.GridLayout
        WeeklyPlan_1             matlab.ui.control.Table
        PotVolLabel_1            matlab.ui.control.Label
        UpdateVol_1              matlab.ui.control.Label
        PotCapLabel_1            matlab.ui.control.Label
        updateCap_1              matlab.ui.control.Label
        PotPORLabel_1            matlab.ui.control.Label
        updateportions_1         matlab.ui.control.Label
        Pot_2                    matlab.ui.container.Tab
        Grid_Pot2                matlab.ui.container.GridLayout
        WeeklyPlan_2             matlab.ui.control.Table
        PotVolLabel_2            matlab.ui.control.Label
        UpdateVol_2              matlab.ui.control.Label
        PotCapLabel_2            matlab.ui.control.Label
        updateCap_2              matlab.ui.control.Label
        PotPORLabel_2            matlab.ui.control.Label
        updateportions_2         matlab.ui.control.Label
        Pot_3                    matlab.ui.container.Tab
        Grid_Pot3                matlab.ui.container.GridLayout
        WeeklyPlan_3             matlab.ui.control.Table
        PotVolLabel_3            matlab.ui.control.Label
        UpdateVol_3              matlab.ui.control.Label
        PotCapLabel_3            matlab.ui.control.Label
        updateCap_3              matlab.ui.control.Label
        PotPORLabel_3            matlab.ui.control.Label
        updateportions_3         matlab.ui.control.Label
        StraussImag              matlab.ui.control.Image
        capacityEditField        matlab.ui.control.NumericEditField
        capacityEditFieldLabel   matlab.ui.control.Label
        weeklyRequirements       matlab.ui.container.Tab
        GridLayout10             matlab.ui.container.GridLayout
        weeklyrequirementsTable  matlab.ui.control.Table
        CookingTimesDeafult      matlab.ui.container.Tab
        GridLayout9              matlab.ui.container.GridLayout
        CookingTimesTable        matlab.ui.control.Table
        Cookingsize              matlab.ui.container.Tab
        GridLayout11             matlab.ui.container.GridLayout
        CookingSizesTable        matlab.ui.control.Table
        settings                 matlab.ui.container.Tab
        GridLayout13             matlab.ui.container.GridLayout
        PDownTime                matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        addDownTime              matlab.ui.control.Button
        Label_13                 matlab.ui.control.Label
        duration_time_down       matlab.ui.control.DropDown
        Start_day_label          matlab.ui.control.Label
        Start_day_down           matlab.ui.control.DropDown
        Label_12                 matlab.ui.control.Label
        pot_list_down            matlab.ui.control.DropDown
        DownTimeTable            matlab.ui.control.Table
        MaintananceImage         matlab.ui.control.Image
        PCleaning                matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        AllowCIP                 matlab.ui.control.CheckBox
        DescriptionCIP           matlab.ui.control.Label
        AllowClean1200           matlab.ui.control.CheckBox
        Description1200          matlab.ui.control.Label
        CIP_Time                 matlab.ui.control.NumericEditField
        CIP_min_hour             matlab.ui.control.DropDown
        Time_1200                matlab.ui.control.NumericEditField
        min_hour_1200            matlab.ui.control.DropDown
        AllowWashTime            matlab.ui.control.CheckBox
        DescriptionWashTime      matlab.ui.control.Label
        WashTime                 matlab.ui.control.NumericEditField
        WashTime_min_hour        matlab.ui.control.DropDown
        DOCIP_min_hour_2         matlab.ui.control.DropDown
        DOCIP_Time_2             matlab.ui.control.NumericEditField
        DOTime_1200_Time_2       matlab.ui.control.NumericEditField
        DOTime_1200_min_hour_2   matlab.ui.control.DropDown
        DO_afterT                matlab.ui.control.Label
        FOR_T                    matlab.ui.control.Label
        FloorHrs                 matlab.ui.container.Panel
        GridLayout8              matlab.ui.container.GridLayout
        Label_15                 matlab.ui.control.Label
        SUN_time                 matlab.ui.control.DropDown
        Label_14                 matlab.ui.control.Label
        THUR_time                matlab.ui.control.DropDown
        FRI_label                matlab.ui.control.Label
        FRI                      matlab.ui.control.CheckBox
        FRI_START_label          matlab.ui.control.Label
        FRI_START                matlab.ui.control.DropDown
        FRI_END_label            matlab.ui.control.Label
        FRI_END                  matlab.ui.control.DropDown
        Weekly_label             matlab.ui.control.Label
        WEEKLY                   matlab.ui.control.CheckBox
        Label_18                 matlab.ui.control.Label
        NumberofIterations       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        model % calculation model
        MaxIt % number of iterations untill achiving the final setup.
        vols % structure of the volumes of the pots
        data % all the input data
        
        start_day_1; % start time of the week after convertion pot 1
        start_day_2; % start time of the week after convertion pot 2
        start_day_3; % start time of the week after convertion pot 3
        
        end_day_1; % end time of the week after convertion pot1
        end_day_2; % end time of the week after convertionpot 2
        end_day_3; % end time of the week after convertion pot 3
        
        days_1; %number of cooking days avilable pot 1
        days_2; %number of cooking days avilable pot 2
        days_3; %number of cooking days avilable pot 3
        times_1; %total number of hr of cooking in the week pot 1
        times_2; %total number of hr of cooking in the week pot 2
        times_3; %total number of hr of cooking in the week pot 3
        capacitySetting %capacity setting time for all factory
        
        startcooking; %formal start cooking time for the week
        stopcooking; %formal end cooking time for the week
        withFRI; % consider Friday
        withSAT; % consider Saturday
        extraTime % addSat hr to SUN
        
        cleaning_table % table of cleaning times
        cleaningInfo % struct with information about the cleaning matrix


        TotalRecipes; %% when adding new recipies need to update the row number
        oats; %% mark special outs recipes that have to be cooked on wed/thur for Kosher
        
        JoinOats;
        firstOats % original Index number of oats recipes in x.Position vector.
        
        printingCounter % extructs the information for each pot
        
        %% New Properties
        MetaDataFile_Constarains % contraint Table
        MetaDataFile_Times % Cooking Time and cleaning Time Table
        
        
        mainpath; % main folder of this app
        outFile % name of output file
        path4MetaDataFile % path to MetaData
        
        rawdata % original Table for requirments read from 'נוכחי'
        mainTable % filtered Table of requirments
        mainTableJoined %all data merged into one table
        setupTable % a table of all the information on weekly timing for cooking
        setup600
        
        reqDays = 1:5; % reuirments are always from thursday till wednsday.
        pots = [600,600,1200];
        StartTime  = [0,0,0]% array of start hrs for each pot
        StartDay ={'','',''}% cell of starting days for all pots
        EndTime  = [0,0,0]% array of end hrs for each pot
        EndDay ={'','',''}% cell of end days for all pots
        
        
        includefriday = [0,0,0]; % time in hours
        includesaturday = [0,0,0]; % time in hours
        SunStart = nan; % time in hours
        ThurEnd = nan; % time in hours
        % time in hours
        
        
        breakTable = table;
        break_day = nan;
        break_duration = 5;
        break_pot = "600";
        
        
        deafualtCleaning = 20/60; % base cleaning time between recipes
        default_CIP_clean; % CIP cleaning time
        doCIPTime = 40; % CIP cleaning frequency
        default_1200_clean; % CIP cleaning time for 1200 pot
        default_1200_dur; %CIP cleaning frequency for 1200 pot
        
        
        %group tables
        oatsTable
        chiaTable
        allTable
        
        
        Allmatrix % cleaning matrix
        
        % structurs of data per group
        allstruct
        chiastruct
        oatsstruct
        allstructOptim600
        allstructOptim1200
        
        
        %balance vars:
        capacity600
        recipes2move600
        updateOptimStruct600
        
        capacity1200
        recipes2move1200
        updateOptimStruct1200
        
        maxamount1200 = 1800;
        minamount600 = 1200;
        
        % FinalTables
        FinalTable1200
        FinalTable600_1
        FinalTable600_2
    end
    
    
    methods (Access = private)
        %% this function reads the constrains sheet from 'MetaDataFile'
        function getConstrains(app)
            opts = spreadsheetImportOptions("NumVariables", 12);
            
            % Specify sheet and range
            opts.Sheet = "Constrains";
            
            
            % Specify column names and types
            opts.VariableNames = ["SerialNumber", "Recipe", "LifeTime", "P_300", "P_400", "P_450", "P_500", "P_600", "P_700", "P_800", "P_900", "P_1200"];
            opts.VariableTypes = ["double", "string", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
            
            % Specify variable properties
            opts = setvaropts(opts, "Recipe", "WhitespaceRule", "preserve");
            opts = setvaropts(opts, "Recipe", "EmptyFieldRule", "auto");
            
            % Import the data
            app.MetaDataFile_Constarains = readtable(app.path4MetaDataFile, opts, "UseExcel", false);
            
            app.MetaDataFile_Constarains = app.MetaDataFile_Constarains(~isnan(app.MetaDataFile_Constarains.SerialNumber),:);
        end
        
        %% this function reads the times sheet from 'MetaDataFile'
        function getTimesFile(app)
            opts = spreadsheetImportOptions("NumVariables", 5);
            
            % Specify sheet and range
            opts.Sheet = "Times";
            
            % Specify column names and types
            opts.VariableNames = ["SerialNumber", "Recipe", "CookingTime", "CleaningTime", "Group"];
            opts.VariableTypes = ["double", "string", "double", "double", "double"];
            
            % Specify variable properties
            opts = setvaropts(opts, "Recipe", "WhitespaceRule", "preserve");
            opts = setvaropts(opts, "Recipe", "EmptyFieldRule", "auto");
            
            % Import the data
            app.MetaDataFile_Times = readtable(app.path4MetaDataFile, opts, "UseExcel", false);
            
            app.MetaDataFile_Times = app.MetaDataFile_Times(~isnan(app.MetaDataFile_Times.SerialNumber),:);
        end
        
        
        %% this Function reads the נוכחי sheet from 'תוכנית יוגורט חדש'
        function import_and_process_main(app)
            %% this Function convert FULL_PLAN into weekly plan and reads it as a table.
            
            %% get the main file
            [name,path] = uigetfile('*.xlsx','בחר קובץ ראשי של דרישות שבועייות','MultiSelect','off');
            path2file = fullfile(path,name);
            %path2file = 'C:\Users\amitk\OneDrive - Systematics LTD\Desktop\Amit\Consults\Strauss - Copy\Full_plan\FULL_PLAN.xlsx';
            figure(app.MainApp);
            %read table only for relevent part - not changing
            
            %% Set up the Import Options and import the data
            opts = spreadsheetImportOptions("NumVariables", 18);
            
            % Specify sheet and range
            opts.Sheet = "נוכחי";
            opts.DataRange = "C254:T313";
            
            % Specify column names and types
            opts.VariableNames = ["VarName3", "VarName4", "VarName5", "VarName6", "VarName7", "VarName8", "VarName9", "VarName10", "VarName11", "VarName12", "VarName13", "VarName14", "VarName15", "VarName16", "VarName17", "V1", "VarName19", "VarName20"];
            opts.VariableTypes = ["double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "string", "double", "double"];
            
            % Specify variable properties
            opts = setvaropts(opts, "V1", "WhitespaceRule", "preserve");
            opts = setvaropts(opts, "V1", "EmptyFieldRule", "auto");
            
            % Import the data
            app.rawdata = readtable(path2file, opts, "UseExcel", false);
            
            % Remove missing data from SerialNumber in manu's table of what2cookthis
            % week
            [~,missingIndices] = rmmissing(app.rawdata(:,end));
            % keep only lines with SerialNumber
            app.rawdata = app.rawdata(~missingIndices,:);
            
            %keep only information on recipes to be cooked by raw data:
            [~,~,ib] = intersect(app.rawdata{:,end},app.MetaDataFile_Times.SerialNumber);
            app.MetaDataFile_Times = app.MetaDataFile_Times(ib,:);
            [~,~,ib] = intersect(app.rawdata{:,end},app.MetaDataFile_Constarains.SerialNumber);
            app.MetaDataFile_Constarains = app.MetaDataFile_Constarains(ib,:);
            %% this sets a main table of cooking recipes
            app.mainTable = table('Size',[size(app.MetaDataFile_Constarains,1),6],'VariableTypes',repmat({'double'},1,6),'VariableNames',{'SerialNumber','Day1','Day2','Day3','Day4','Day5'});


            % insert all SerialNumber recepies to cook this week into mainTable.
            app.mainTable.SerialNumber = app.rawdata{:,end};
            % go over every three columns to join into one day going backwards.
            for day = app.reqDays
                dayStart = day + (day-1)*2;                dayEnd = dayStart + 2 ;
                temp = app.rawdata{:,dayStart:dayEnd};
                scheduldays = flip(app.reqDays);
                app.mainTable.(['Day' num2str(scheduldays(day))]) = sum(temp,2,"omitnan");
            end
            % at this point i have a the main table with all the recipes by
            % SerialNumber and cooking amount
            
            
            % sort all tables by the same ascendind direction.
            app.mainTable = sortrows(app.mainTable,'SerialNumber',"ascend");
            app.MetaDataFile_Times = sortrows(app.MetaDataFile_Times,'SerialNumber',"ascend");
            app.MetaDataFile_Constarains = sortrows(app.MetaDataFile_Constarains,'SerialNumber','ascend');
            
            % join all tables into one table - each row represents a different recipe
            % with all it's information.
            
            metaData = join(app.MetaDataFile_Times,app.MetaDataFile_Constarains);
            
            %verify all recipes in weekly req and metadata are matching
            Contained = intersect(app.mainTable.SerialNumber,metaData.SerialNumber);
            idx = ismember(app.mainTable.SerialNumber,Contained);
            if any(~idx)
                disp(app.mainTable.SerialNumber(~idx)) % TODO - switch to popup!
                disp('is not matching:')
                disp(metaData.SerialNumber(~idx))
                disp('please check and re run')
%                 error('Main:FileImport','יש מתכונים חסרים בקובץ נתונים');
                uialert(app.MainApp,'יש מתכונים חסרים בקובץ נתונים','Main:FileImport');
            end
            
            
            app.mainTableJoined = join(app.mainTable,metaData);
            % add original number to table for future operations.
            app.mainTableJoined.OriginalNum = (1:size(app.mainTableJoined,1))';
            
            
        end
        
        function createSetupTable(app)
            
            % get time and days for each pot


            app.setupTable = table(app.pots',cellfun(@(c)swapday2num(app,c),app.StartDay)',app.StartTime',cellfun(@(c)swapday2num(app,c),app.EndDay)',app.EndTime',...
                'VariableNames',{'Size','StartDay','StartTime','EndDay','EndTime'});
            % get all days and total number of days
            flag = [true,true,true];
            for pot = 1:size(app.setupTable,1)
                
                if app.setupTable.StartDay(pot)>=app.setupTable.EndDay(pot) %normal week
                    days = [app.setupTable.StartDay(pot):5,1:app.setupTable.EndDay(pot)];
                    beforeweekend = 0;
                    aftereweekend = 0;
                else % week without weekend
                    days = app.setupTable.StartDay(pot):app.setupTable.EndDay(pot);
                    beforeweekend = 0;
                    aftereweekend = nan;
                end
                times = days;
                for d = 1:length(days)
                    startT = 0;
                    endT = 24;
                    if d == 1
                        %this is the first day - get starting time from table
                        startT = app.setupTable.StartTime(pot);
                    end
                    if d == length(days)
                        %this is the last day - get end time from table
                        endT = app.setupTable.EndTime(pot);
                    end
                    if days(d) == 1
                        if app.includesaturday(pot) == 0
                            startT = app.SunStart;
                        end
                    end
                    if days(d) == 5
                        if app.includefriday(pot) == 0
                            endT = app.ThurEnd;
                        end
                    end
                    
                    dailytime = endT-startT;
                    
                    if days(d) == 1
                        if app.includesaturday(pot) > 0
                            dailytime = dailytime + app.includesaturday(pot);
                        end
                        if ~isnan(aftereweekend)
                            aftereweekend = d;
                        end
                    end
                    if days(d) == 5 && flag(pot)
                        if app.includefriday(pot) > 0 
                            dailytime = dailytime + app.includefriday(pot);
                            
                        end
                        
                        beforeweekend = d;
                        flag(pot) = false;
                    end
                    times(d) = dailytime;
        

                end
                app.setupTable.before(pot) = sum(times(1:beforeweekend));
                if isnan(aftereweekend)
                    app.setupTable.after(pot) = 0;
                else
                    app.setupTable.after(pot) = sum(times(aftereweekend:end));
                end
                
                app.setupTable.days(pot) = {days};
                app.setupTable.times(pot) = {times};
                app.setupTable.totaltime(pot) = sum(times);
                app.setupTable.Ndays(pot) = cellfun(@numel,app.setupTable.days(pot));
            end
            app.setupTable.CIPbefore = floor(app.setupTable.before/app.doCIPTime);
            app.setupTable.CIPafter = floor(app.setupTable.after/app.doCIPTime);
            
        end
        
        %% this function converts hebrew days into numbers
        function numday = swapday2num(~,day)
            if iscell(day)
                day = char(day{:});
            end
            if ~ischar(day)
                day = char(day);
            end
            
            switch day
                case 'ד'
                    numday =4;
                case 'ה'
                    numday = 5;
                case 'א'
                    numday =1;
                case 'ב'
                    numday = 2;
                case 'ג'
                    numday =3;
            end
            
        end
        
        
        %% this function sets the selected days days into numbers
        function setdays(app,iPOT)
            %% this function converts hebrew days into numbers
            
            if iPOT~=-1
                update(app,iPOT)
                
            else % IPOT == -1 to set all
                for ii = 1:3
                    update(app,ii)
                    
                end
            end
            %the update function is a local function of setdays
            % we could just put it inside the for without using any
            % function it is unneccery.
            
            function update(app,index)
                % convert start day
                app.StartDay{index}=app.(['StartDay_' num2str(index)]).Value;
                % convert end day
                app.EndDay{index}=app.(['EndDay_' num2str(index)]).Value;
                % convert start time
                app.StartTime(index)=str2num(app.(['start_time_' num2str(index)]).Value); %#ok<*ST2NM> 
                % convert end time
                app.EndTime(index)=str2num(app.(['end_time_' num2str(index)]).Value);
            end
            
        end
        
        %% this function extructs the cleaning times by the settings
        function getCIPtimes(app)
            %% this function extructs the cleaning times by the settings
            
            %% SETUP TIME
            if app.WashTime_min_hour.Value == 'דקות' %#ok<BDSCA>
                app.deafualtCleaning = app.WashTime.Value/60;
            else
                app.deafualtCleaning = app.WashTime.Value;
            end
            %% CIP
            if app.CIP_min_hour.Value == 'דקות' %#ok<BDSCA>
                app.default_CIP_clean = app.CIP_Time.Value/60;
            else
                app.default_CIP_clean = app.CIP_Time.Value;
            end
            if app.DOCIP_min_hour_2.Value == 'דקות' %#ok<BDSCA>
                app.doCIPTime = app.DOCIP_Time_2.Value/60;
            else
                app.doCIPTime = app.DOCIP_Time_2.Value;
            end
            %% CIP - 1200
            if app.min_hour_1200.Value == 'דקות' %#ok<BDSCA>
                app.default_1200_clean= app.Time_1200.Value/60;
            else
                app.default_1200_clean = app.Time_1200.Value;
            end
            if app.DOTime_1200_min_hour_2.Value == 'דקות' %#ok<BDSCA>
                app.default_1200_dur = app.DOTime_1200_Time_2.Value/60;
            else
                app.default_1200_dur = app.DOTime_1200_Time_2.Value;
            end
        end
        
        
        %% this function is for writing into a tamplete output file
        function write2file(app)
            
            insertDailyTime = ('V' -0):-4:('B'-0);
            replacenum2day = ('W' -0):-4:('C'-0);
            insertCookingdaytitle = ('X' -0):-4:('D'-0);
            fullrange = ('U' -0):-4:('A'-0);
            startrow = 4;
            gaprows = 4;
            insert_startingRow = 'יום בישול';
            rowcounter = 0;
            filepath = [app.mainpath '\' app.outFile '.xlsx'];
            tables = {app.FinalTable600_1 , app.FinalTable600_2, app.FinalTable1200};
            setup = {app.setup600(1,:) , app.setup600(2,:), app.setupTable(app.setupTable.Size == 1200,:)};
            titleRow = 2;
            % times
            % days
            % Connect to Excel
            Excel = actxserver('excel.application');
            
            
            for t = 1:numel(tables)
                setuptable = setup{t};
                FinalTable = tables{t};
                currdays = cell2mat(setuptable.days);
                currtimes = cell2mat(setuptable.times);
                for d = 1:numel(currdays)
                    dayTab = FinalTable(FinalTable.Day2Cook3 == d,:);
                    %insert time and day:
                    writecell({insert_startingRow},filepath,"FileType","spreadsheet","Range",[char(insertCookingdaytitle(d)) num2str(titleRow)]);
                    writecell({swapnum2day(app,currdays(d))},filepath,"FileType","spreadsheet","Range",[char(replacenum2day(d)) num2str(titleRow)]);
                    writecell({currtimes(d)},filepath,"FileType","spreadsheet","Range",[char(insertDailyTime(d)) num2str(titleRow)]);
                    
                    
                    if isempty(dayTab)
                        continue;
                    end
                    writeascell = table2cell(dayTab(:,{'CleaningTime','CookingTime','Amount','RecipeName'}));
                    if rowcounter<size(dayTab,1)
                        rowcounter = size(dayTab,1);
                    end
                    for r = 0:size(dayTab,1)-1
                        
                        range2write = [char(fullrange(d)) num2str(startrow+r)];
                        writecell(writeascell(r+1,:),filepath,"FileType","spreadsheet","Range",range2write);
                        if ~dayTab.OnTime(r+1)
                            % Get Workbook object
                            WB = Excel.Workbooks.Open(filepath,0,false);
                            % Set the color of cell of Sheet 1 to RED
                            WB.Worksheets.Item(1).Range([char(fullrange(d)+3) num2str(startrow+r)]).Interior.ColorIndex = 3;
                            % Save Workbook
                            WB.Save();
                            % Close Workbook
                            WB.Close();
                        end
                    end
                end
                
                % change counters:
                startrow = startrow + rowcounter + gaprows;
                % add stats for each pot
                writecell({'עומס סיר'},filepath,"FileType","spreadsheet","Range",[char(insertCookingdaytitle(1)) num2str(startrow-3)]);
                timing = (FinalTable.TotalTime(end)/setuptable.totaltime);
                if isduration(timing)
                    timing = (hours(FinalTable.TotalTime(end))/setuptable.totaltime);
                end
                writecell({round(timing*100,2)},filepath,"FileType","spreadsheet","Range",[char(insertCookingdaytitle(1)-1) num2str(startrow-3)]);
                writecell({'אחוז'},filepath,"FileType","spreadsheet","Range",[char(insertCookingdaytitle(1)-2) num2str(startrow-3)]);
                
                %% add portion amounts
                for d = 1:numel(currdays)
                    tab = tables{t};
                    str_portion = num2str(round(sum(tab.Iterations(tab.Day2Cook3 == d)),0));
                    writecell({'כמות מנות'},filepath,"FileType","spreadsheet","Range",[char(insertCookingdaytitle(d)) num2str(startrow-4)]);
                    writecell({str_portion},filepath,"FileType","spreadsheet","Range",[char(insertCookingdaytitle(d)-1) num2str(startrow-4)]);
                end
                WB = Excel.Workbooks.Open(filepath,0,false);
                % Set the color of cell of Sheet 1 to RED
                WB.Worksheets.Item(1).Range([char(insertCookingdaytitle(1)) num2str(startrow-3)]).Interior.ColorIndex =4; %green
                WB.Worksheets.Item(1).Range([char(insertCookingdaytitle(1)-1) num2str(startrow-3)]).Interior.ColorIndex = 4;
                WB.Worksheets.Item(1).Range([char(insertCookingdaytitle(1)-2) num2str(startrow-3)]).Interior.ColorIndex = 4;
                % Save Workbook
                WB.Save();
                % Close Workbook
                WB.Close();
                % insert header row:
                titleRow = startrow-1;
            end
            
            % Quit Excel
            Excel.Quit();
        end


        function setupPaths(app)
            %% get main path to the app;
            fid = fopen('pathDef.txt','r');
            %read main path
            l = fgetl(fid);
            app.mainpath = extractAfter(l,'=');
            %read path for MetaData
            l = fgetl(fid);
            app.path4MetaDataFile=extractAfter(l,'=');
            
            fclose(fid);
        end
        
        %% this function swaps the cooking day from number 2 letter
        function day = swapnum2day(~,numday)
            switch numday
                case 4
                    day ='ד';
                case 5
                    day ='ה';
                case 1
                    day ='א';
                case 2
                    day ='ב';
                case 3
                    day ='ג';
            end
        end
        
        
        function [portionLoad, cookingLoad] = gettotalTime(app)
            cookingLoad = 0;
            portionLoad = 0;
            for i=1:3
                cookingLoad = cookingLoad + ...
                    (app.(['WeeklyPlan_' num2str(i)]).Data.TotalTime(end))/...
                    hours(app.capacityEditField.Value);
                portionLoad = portionLoad + ...
                    str2num(app.(['updateportions_' num2str(i)]).Text);
            end
            portionLoad = round(portionLoad,0);
        end


        function check_break(app)
            
            % check that DownTimeTable has info
            if isempty(app.DownTimeTable.Data)
                return
            end
            for r = 1:size(app.DownTimeTable.Data,1)
                app.break_pot = app.DownTimeTable.Data.('נפח סיר'){r};
                
                app.break_day = app.DownTimeTable.Data.('יום בשבוע'){r};
                
                app.break_duration = app.DownTimeTable.Data.('משך ההשבתה'){r};
                
                
                if strcmp(app.break_pot,'all')
                    % add break to all pots
                    rows = 1:3;
                else
                    rows = find(strcmp(app.setupTable.Size,app.break_pot));
                end
                i = cell2mat(app.setupTable.days(rows)) == app.break_day;
                currtimes = cell2mat(app.setupTable.times(rows));
                currtimes(i) = currtimes(i) - app.break_duration;
                app.setupTable.times(rows) = num2cell(currtimes,2);
                app.setupTable.totaltime(rows) = app.setupTable.totaltime(rows) - app.break_duration;
                
                if isempty(app.breakTable)
                    app.breakTable = cell2table({swapday2num(app,app.break_day),str2num(app.break_duration),app.break_pot},"VariableNames",{'break_day','break_duration','break_pot'});
                else
                    app.breakTable =[app.breakTable;cell2table({swapday2num(app,app.break_day),str2num(app.break_duration),app.break_pot},"VariableNames",{'break_day','break_duration','break_pot'})];
                end
            end
        end
        
        function allTableStruct = getPortions(app,allTable)
            allDays = app.reqDays;
            % divide into 600/1200 pots
            portions = extractAfter(allTable.Properties.VariableNames,'P_'); % get portion values
            % get only the columns of the portions and convert to number
            portions_columns = find(~cellfun(@isempty,portions));
            portions = portions(portions_columns);
            portions = cellfun(@str2num,portions);
            % bulid a portion matrix - in table inserted as 0/1 - convert to actual
            % portion value.
            portions_matrix= allTable{:,portions_columns}.*portions;
            %% get minimal cooking time and maximal portion.
            allTable.MinimalTime = nan(size(allTable,1),1);
            allTable.MaximalPortion = nan(size(allTable,1),1);
            allTable.Niteration = nan(size(allTable,1),1);
            allTableStruct = struct();
            % check division per day
            for day = allDays %(1:5)
                getdailyRecs = allTable.(['Day' num2str(day)]) ~= 0; %get daily amount per recipe IDX.
                
                tempDay=allTable(getdailyRecs,{'OriginalNum',['Day' num2str(day)],'CookingTime','SeqNum','LifeTime','MinimalTime','MaximalPortion','Niteration'}); % slice table only for daily recipes.
                tempDay.Properties.VariableNames(2) = {'Amounts'}; % change column name from day_N to Amounts
                iterationMat = (tempDay.Amounts./portions_matrix(tempDay.SeqNum,:));
                portions_matrix_per_day = iterationMat.*tempDay.CookingTime; % calculate per day the cooking time for each amount at each portion.
                [minimalTime,minimalportion] = min(portions_matrix_per_day,[],2);
                %% replace values in allTable instead of portions allowed
                portions_table_per_day = array2table(portions_matrix_per_day,'VariableNames',allTable.Properties.VariableNames(portions_columns));
                tempDay = [tempDay portions_table_per_day];
                tempDay.MinimalTime = minimalTime;
                tempDay.MaximalPortion = portions(:,minimalportion)';
                tempDay.Niteration = iterationMat(sub2ind(size(iterationMat),(1:numel(minimalportion))',minimalportion));
                %% store tempday table in struct
                allTableStruct.(['Day' num2str(day)]) = tempDay;
            end
            allTableStruct = splitMainPots(app,allTableStruct,allDays);
            
        end
        function  groupstruct = splitMainPots(~,groupstruct,allDays)
            for day = allDays %(1:5)
                getdailyTab = groupstruct.(['Day' num2str(day)]); %get daily Table.
                spltidx = getdailyTab.MaximalPortion > 600; % find recipes to be cooked in 1200 pot;
                groupstruct.(['Day' num2str(day)]) = struct;
                groupstruct.(['Day' num2str(day)]).originalTable = getdailyTab;
                groupstruct.(['Day' num2str(day)]).Table600 = getdailyTab(~spltidx,:);
                groupstruct.(['Day' num2str(day)]).Table1200 = getdailyTab(spltidx,:);
            end
            
        end
        %% this function is used to calculate the cost of each day in different groups of recipes (600/1200, oats, chia)
        function groupstruct = calcCost(app,groupstruct,cleaningMatrix,allDays,p)
            % groupstruct - structure of each pot (600/1200, oats, chia)
            % cleaningMatrix - matrix of cleaning pairs by SeqNum order
            % allDays - total cooking days
            % p - numeric flag: 1 or 2 -> 600 or 1200
            
            for day = allDays %(1:5)
                dailyStruct = groupstruct.(['Day' num2str(day)]); %get daily Table.
                if p == 1 %600 cost setup
                    dailyTable = dailyStruct.Table600;
                    groupstruct.(['Day' num2str(day)]).cost600 = dailyTable;
                    groupstruct.(['Day' num2str(day)]).cost600.TotalTime = nan(size(dailyTable,1),1);
                    cost = inf;
                else  %1200 cost setup
                    dailyTable = dailyStruct.Table1200;
                    groupstruct.(['Day' num2str(day)]).cost1200 = dailyTable;
                    groupstruct.(['Day' num2str(day)]).cost1200.TotalTime = nan(size(dailyTable,1),1);
                    cost = inf;
                end
                if size(dailyTable,1)<2
                    continue; % if there is only one recipe that day there is no point in optimizing.
                end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%Gilis part %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 if length(dailyTable.OriginalNum) > 10
%                     orders = zeros(app.NumberofIterations.Value,length(dailyTable.OriginalNum));
%                     orders(1,:) = randperm(length(dailyTable.OriginalNum),length(dailyTable.OriginalNum));
%                     for kk = 2: app.NumberofIterations.Value
%                         orders(kk,:) = randperm(length(dailyTable.OriginalNum),length(dailyTable.OriginalNum));
%                         if all(orders(kk,:) == orders(kk-1,:))
%                             orders(kk,:) = randperm(length(dailyTable.OriginalNum),length(dailyTable.OriginalNum));
%                         else
%                             continue
%                         end
%                         
%                     end
%                     
%                     for ii = 1:length(dailyTable.OriginalNum)
%                         orders(orders == ii) = dailyTable.OriginalNum(ii);
%                     end
%                     
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 else
                    orders = perms(dailyTable.OriginalNum); % get all possible permutations of recipes that day
                    if size(orders,1)>app.NumberofIterations.Value
                        orders = orders(1:app.NumberofIterations.Value,:);
                    end
%                 end
                tic
                for r = 1:size(orders,1) % run according to the number of possible permutation
                    order = orders(r,:); % get permutation row  
                    cleaningTimes = diag(cleaningMatrix(order(1:end-1),order(2:end)));
                    cleaningTimes = [cleaningTimes ;0]; % extract cleaning times by the perm order
                    %find indx by table
                    [~,reorder] = ismember(order,dailyTable.OriginalNum); % find original location
                    minimalTime = dailyTable.MinimalTime(reorder); % build a cooking time vectore by the perm order in original indexes.
                    calccost_temp = sum(minimalTime + cleaningTimes); % calculate the cost of cooking and cleaning.
                    if calccost_temp<cost
                        cost = calccost_temp;
                        %try to find minimized cost and save
                        if p ==1
                            groupstruct.(['Day' num2str(day)]).cost600 = dailyTable(reorder,:);
                            groupstruct.(['Day' num2str(day)]).cost600.TotalTime = (minimalTime + cleaningTimes); % add total cooking + cleaning time to cost table
                        else
                            groupstruct.(['Day' num2str(day)]).cost1200 = dailyTable(reorder,:);
                            groupstruct.(['Day' num2str(day)]).cost1200.TotalTime = (minimalTime + cleaningTimes); % add total cooking + cleaning time to cost table
                        end
                    end
                end
                toc
            end
        end
        
        function allcost = gettablefromstruct(~,allstruct,allDays,pot)
            % allstruct - struct,
            % allDays - days list
            % pot - str ("600" or "1200")
            
            allcost =[];
            for i = allDays
                costTable = allstruct.(['Day' num2str(i)]).("cost" + pot);
                costTable.plannedDay = ones(size(costTable,1),1)*i;
                allcost = [allcost;costTable];
            end
        end
        
        function  [capacity,recipes2move,optimStruct] = checkbalance_byamount(~,plannedtime2cook,potTime2cook,optimStruct,weeklytimes,pot)
            % this function checks the planned time in comparison to the actual time
            % and finds recipes that need to move to the other pot - sorting by min
            % amount for 1200 pot and max amount for 600 pot.
            % plannedtime2cook - total time to cook for this pot (600/1200)
            % potTime2cook - double from cost table -> minimalTime column
            % optimStruct - struct array for each pot with tables for each cooking day with cost included.
            % weeklytimes - weekly times per pot for each cooking day.
            % pot- str var 600/1200;
            
            
            ndays = numel(fieldnames(optimStruct));
            if ndays ~= length(weeklytimes) %(1:5)
                weeklytimes(ndays) = sum(weeklytimes(ndays:end));
            end
            capacity = (potTime2cook/plannedtime2cook);
            recipes2move = [];
            if capacity > 0.85
                %% need to move recipes to the other pots beacuse the total time to cook is heigher than the time to cook per pot
                for day = 1:ndays
                    
                    %optimStruct.(['Day' num2str(day)])
                    dailycost = optimStruct.(['Day' num2str(day)]);
                    %% check daily time
                    dailyTime = weeklytimes(day);
                    if strcmp(pot,'600')
                        portions2chk = {'P_700','P_800','P_900','P_1200'};
                        
                        if isnan(dailycost.cost600.TotalTime)
                            Timestr = 'MinimalTime';
                        else
                            Timestr = 'TotalTime';
                        end %isnan(dailycost.cost600.TotalTime)
                        
                        dailyTimecalc = sum(dailycost.cost600.(Timestr));
                        while dailyTimecalc>dailyTime % daily cooking is bigger then planned time for that day
                            
                            %[~,changerow] = max(dailycost.cost600.(Timestr));
                            [~,changerow] = max(dailycost.cost600.Amounts);
                            row2change = dailycost.cost600(changerow,:);
                            chkpor = isinf(row2change{:,portions2chk});
                            if all(chkpor) %% Non of the portions of 1200 are avilable
                                row2change.P_1200 = row2change.(['P_' num2str(row2change.MaximalPortion)]);
                                row2change.MaximalPortion = 1200;
                                row2change.day = day;
                                recipes2move = [recipes2move;row2change];
                                dailycost.cost600(changerow,:) = [];
                                optimStruct.(['Day' num2str(day)]) = dailycost; %% recipe removed only from cost!!
                            else %% some portions are optional
                                
                                maxPor = portions2chk{find(~chkpor,1,'last')};%string
                                maxPor = strrep(maxPor,'P_','');
                                maxpordouble = str2num(maxPor);
                                row2change.Niteration = row2change.Amounts/maxpordouble;
                                row2change.MinimalTime = row2change.Niteration * row2change.CookingTime;
                                row2change.MaximalPortion = maxpordouble;
                                %% send to update
                                row2change.day = day;
                                recipes2move = [recipes2move;row2change];
                                dailycost.cost600(changerow,:) = [];
                                optimStruct.(['Day' num2str(day)]) = dailycost; %% recipe removed only from cost!!
                            end
                            dailyTimecalc = sum(dailycost.cost600.(Timestr));
                        end %if dailyTimecalc>dailyTime
                    else % this is for 1200
                        portions2chk = {'P_300','P_400','P_450','P_500','P_600'};
                        if isnan(dailycost.cost1200.TotalTime)
                            Timestr = 'MinimalTime';
                        else
                            Timestr = 'TotalTime';
                        end %isnan(dailycost.cost1200.TotalTime)
                        dailyTimecalc = sum(dailycost.cost1200.(Timestr));
                        while dailyTimecalc>dailyTime % daily cooking is bigger then planned time for that day
                            
                            %                 [~,changerow] = max(dailycost.cost1200.(Timestr));
                            [~,changerow] = min(dailycost.cost1200.Amounts);
                            row2change = dailycost.cost1200(changerow,:);
                            chkpor = isinf(row2change{:,portions2chk});
                            if all(chkpor) %% Non of the portions of 600 are avilable
                                warning('cant move the recipes from 1200')
                                
                            else %% some portions are optional
                                
                                maxPor = portions2chk{find(~chkpor,1,'last')};%string
                                maxPor = strrep(maxPor,'P_','');
                                maxpordouble = str2num(maxPor);
                                row2change.Niteration = row2change.Amounts/maxpordouble;
                                row2change.MinimalTime = row2change.Niteration * row2change.CookingTime;
                                row2change.MaximalPortion = maxpordouble;
                                row2change.day = day;
                                recipes2move = [recipes2move;row2change];
                                dailycost.cost1200(changerow,:) = [];
                                optimStruct.(['Day' num2str(day)]) = dailycost; %% recipe removed only from cost!!
                            end
                            dailyTimecalc = sum(dailycost.cost1200.(Timestr));
                        end % dailyTimecalc>dailyTime % daily cooking is bigger then planned time for that day
                        
                    end %if strcmp(pot,'600')
                end % for day = allDays
            end %if time2cook >= potTime2cook  %% there is enough Time to cook the recipes as they are.
        end %func


        function [recipes2move,OptimStruct]= check_recipes2move(app,recipes2move,pot,OptimStruct)
            % check recipes to move
            
            % pot ->600/1200
            % recipes2move1200/recipes2move600 - > recipes2move
            
            % updateOptimStruct1200/updateOptimStruct600 -> OptimStruct
            
            % step one return recipes that are [over the max / under the min] amount to struct
            if pot == 1200
                rtnI =  recipes2move.Amounts>=app.maxamount1200;
                % find max option to cook
                portion = {'P_700','P_800','P_900','P_1200'};
            else
                rtnI =  recipes2move.Amounts<=app.minamount600;
                % find max option to cook
                portion = {'P_300','P_400','P_450','P_500 ','P_600'};
            end
            rtnTable = recipes2move(rtnI,:);
            if isempty(rtnTable)
                return
            end
            recipes2move(rtnI,:) = [];% keep only the rows to move
            portArray = rtnTable{:,portion};
            [~,portI] = min(portArray,[],2);
            maxpordouble = portion(portI);
            maxpordouble = strrep(maxpordouble,'P_',''); % extract P_ from string to convert to num
            maxpordouble = cellfun(@str2num,maxpordouble,'UniformOutput',false)';
            maxpordouble = cell2mat(maxpordouble);
            rtnTable.Niteration = rtnTable.Amounts./maxpordouble;
            rtnTable.MinimalTime = rtnTable.Niteration .* rtnTable.CookingTime;
            rtnTable.MaximalPortion = maxpordouble;
            days = unique(rtnTable.day);
            for d = 1:numel(days)
                dayrec = rtnTable(rtnTable.day == days(d),:);
                dayrec.day  = [];
                OptimStruct.(['Day' num2str(days(d))]).(['cost' num2str(pot)]) = [OptimStruct.(['Day' num2str(days(d))]).(['cost' num2str(pot)]);...
                    dayrec];
            end
        end
        
        function FinalTable = addCIP(app,inptable,mainTableJoined,setupTable,pot,Allmatrix)
            table2write = table('Size',[0 9],'VariableTypes',{'string','double','double','double','double','double','logical','double','double'},'VariableNames',...
                {'RecipeName','Amount','CookingTime','CleaningTime','Day2Cook','TotalTime','OnTime','OriginalNum','Iterations'});
            warning('off','MATLAB:table:RowsAddedExistingVars');
            for r = 1:size(inptable,1)
                
                table2write.RecipeName(r) = mainTableJoined.Recipe(mainTableJoined.OriginalNum == inptable.OriginalNum(r));
                table2write.Amount(r) = inptable.Amounts(r);
                table2write.CookingTime(r)  = inptable.MinimalTime(r);
                table2write.CleaningTime(r)  = inptable.TotalTime(r) - inptable.MinimalTime(r);
                table2write.TotalTime(r)  = inptable.TotalTime(r);
                table2write.Day2Cook(r) = inptable.plannedDay(r);
                table2write.OnTime(r) = true;
                table2write.OriginalNum(r) = inptable.OriginalNum(r);
                table2write.Iterations(r) = inptable.Niteration(r);
            end
            warning('on','MATLAB:table:RowsAddedExistingVars');
            FinalTable = table;
            % Tbefore = setupTable{setupTable.Size == pot, "before"};
            % Tafter = setupTable{setupTable.Size == pot, "after"};
            CIP_before = setupTable{setupTable.Size == pot, "CIPbefore"};
            CIP_after = setupTable{setupTable.Size == pot, "CIPafter"};
            days = setupTable.days{setupTable.Size == pot};
            times = setupTable.times{setupTable.Size == pot};
            Dbefore = find(days == 5,1,"first");% get value from app - last day to cook
            %incase that the cooking include two thursdays-> get the first
            %only
            CIPTime = app.doCIPTime;
            CIPCleaningTime = app.default_CIP_clean;
            % if CIP_before
            %     Tbefore = Tbefore - CIPCleaningTime*CIP_before;
            % end
            % if CIP_after
            %     Tafter = Tafter - CIPCleaningTime*CIP_after;
            % end
            Tcount_before = 0;
            Tcount_after = 0;
            % cleaning every 24 hrs in 1200
            
            cleaning1200 = app.default_1200_clean; % get value from app
            every24 = app.default_1200_dur; % get value from app
            lasttest = false;
            for d = 1:numel(days)
                temp = table2write(table2write.Day2Cook == d,:);
                if isempty(temp)
                    
                    continue;
                end
                temp.TotalTime = cumsum(temp.CleaningTime + temp.CookingTime);
                
                
                if d<=Dbefore % before weekend
                    Tcount_before = Tcount_before + temp.TotalTime(end);
                    
                    
                        if Tcount_before >= CIPTime %% need to insert row for CIP
                            
                            if CIP_before %notinserted
                                addCIPtotemp
                                CIP_before = false;
                            end
                            
                        end
                    
                    
                elseif d> Dbefore % after weekend
                    Tcount_after = Tcount_after + temp.TotalTime(end);
                    
                        if Tcount_after >= CIPTime %% need to insert row for CIP
                            
                            if CIP_after %notinserted
                                addCIPtotemp
                                CIP_after = false;
                            end
                            
                        end
                    
                end
                
                FinalTable = [FinalTable; temp];
                
            end
            
            %update day2cook
            FinalTable.TotalTime = cumsum(FinalTable.CleaningTime + FinalTable.CookingTime);
            
            splitIDX = FinalTable.TotalTime<= sum(times(1:Dbefore));
            before = FinalTable(splitIDX,:);
            
            after = FinalTable(~splitIDX,:);
            after.TotalTime = cumsum(after.CleaningTime + after.CookingTime);
            %% make sure CIP is in the correct place:
            
            CIP_before = setupTable{setupTable.Size == pot, "CIPbefore"};
            CIP_after = setupTable{setupTable.Size == pot, "CIPafter"};
            if CIP_before
                  CIPIdx = strcmpi('CIP',before.RecipeName);  
                if any(CIPIdx) % found the CIP row
                    if before.TotalTime(CIPIdx)>CIPTime % CIP is not in the right place need to check how far from it 
                        before = swaprows4CIP(before,CIPIdx);
                    end
                else % there should be CIP and it was missed. 
                    %% add CIP to the END
                    lasttest = true;
                    temp = before;
                    addCIPtotemp
                    before = temp;
                    CIPIdx = strcmpi('CIP',before.RecipeName);  
                    if before.TotalTime(CIPIdx)>CIPTime % CIP is not in the right place need to check how far from it 
                        before = swaprows4CIP(before,CIPIdx);
                    end
                    lasttest = false;
                end
            end
            if CIP_after
                  CIPIdx = strcmpi('CIP',after.RecipeName);  
                if any(CIPIdx) % found the CIP row
                    if after.TotalTime(CIPIdx)>CIPTime % CIP is not in the right place need to check how far from it 
                        after = swaprows4CIP(after,CIPIdx);
                    end
                else % there should be CIP and it was missed. 
                    %% add CIP to the END
                    lasttest = true;
                    temp = after;
                    addCIPtotemp
                    after = temp;
                    CIPIdx = strcmpi('CIP',after.RecipeName);  
                    if after.TotalTime(CIPIdx)>CIPTime % CIP is not in the right place need to check how far from it 
                        after = swaprows4CIP(after,CIPIdx);
                    end
                    lasttest = false;
                end
            end
            
            
            FinalDays_before = nan(size(before,1),1);
            FinalDays2_after = nan(size(after,1),1);
            
            FinalDays_after = nan(size(after,1),1);
            FinalDays2_before = nan(size(before,1),1);
            
            % to avoid problem with oats and other recipes. need to fix order
            oatsI = contains(before.RecipeName,'ש"ש') | contains(before.RecipeName,'שיבולת') | contains(before.RecipeName,'שועל') | contains(before.RecipeName,'שש');
            
            if any(oatsI)
                before = [before(~oatsI,:);before(oatsI,:)];
                CIPI = contains(before.RecipeName,'CIP');
                if any(CIPI)
                    CIP_oats_Row = before(CIPI,:);
                    before = before(~CIPI,:);
                end
                Nrec = before.OriginalNum;
                cleanMat = Allmatrix(Nrec,Nrec(2:end));
                cleanTime = diag(cleanMat);
                cleanTime = [cleanTime ; 0];
                before.CleaningTime = cleanTime;
                if any(CIPI)
                    row2insert = find(before.TotalTime>CIPTime,1,'first');
                    if ~isempty(row2insert)
                        before = [before(1:row2insert - 1,:);CIP_oats_Row;before(row2insert:end,:)];
                    else
                        before = [before;CIP_oats_Row];
                    end
                end
                before.TotalTime = cumsum(before.CookingTime + before.CleaningTime);
            end
            
            
            totalTime = 0;
            for d = 1:Dbefore
                dayTime = times(d); % daily time for cooking
                totalTime = totalTime + dayTime;
                FinalDays_before(isnan(FinalDays_before) & before.TotalTime <= totalTime) = days(d);
                FinalDays2_before(isnan(FinalDays2_before) & before.TotalTime <= totalTime) = (d);
            end
            
            
            totalTime = 0;
            for d = Dbefore+1:numel(days)
                dayTime = times(d); % daily time for cooking
                totalTime = totalTime + dayTime;
                FinalDays_after(isnan(FinalDays_after) & after.TotalTime <= totalTime) = days(d);
                FinalDays2_after(isnan(FinalDays2_after) & after.TotalTime <= totalTime) = (d);
            end
            FinalDays2 = [FinalDays2_before;FinalDays2_after];
            FinalDays = [FinalDays_before;FinalDays_after];
            FinalTable = [before;after];
            if any(isnan(FinalDays))
                FinalDays(isnan(FinalDays)) = days(d);
            end
            if any(isnan(FinalDays2))
                FinalDays2(isnan(FinalDays2)) =(d);
            end
            % need to check that the planned cooking time is the actual cooking time
            % compare Day2cook to final Days
            for j = 1:size(FinalTable,1)
                orig = FinalTable.Day2Cook(j);
                actual = FinalDays(j);
                okdays = days(1:orig);
                lia = actual == okdays;
                if ~any(lia)
                    if contains(FinalTable.RecipeName(j),'ש"ש') || contains(FinalTable.RecipeName(j),'שיבולת') || contains(FinalTable.RecipeName(j),'שועל') || contains(FinalTable.RecipeName(j),'שש')
                        continue;
                    end
                    FinalTable.OnTime(j) = false;
                end
            end
            
            FinalTable.Day2Cook2 = FinalDays;
            FinalTable.Day2Cook3 = FinalDays2;
            
            % update cleaning time for 1200
            if pot == 1200
                % check Final Table before and after
                after = FinalTable(FinalDays2>Dbefore,:);
                after.TotalTime = cumsum(after.CleaningTime + after.CookingTime);
                FinalTable = [addcleaning1200(FinalTable(FinalDays2<=Dbefore,:)); addcleaning1200(after)];
            end
            
            FinalTable.TotalTime = cumsum(FinalTable.CleaningTime + FinalTable.CookingTime);
            
            FinalTable.CookingTime = hours(FinalTable.CookingTime);
            FinalTable.CookingTime.Format = "hh:mm";
            FinalTable.CleaningTime = hours(FinalTable.CleaningTime);
            FinalTable.CleaningTime.Format = "hh:mm";
            
            % nested functions of addCIP
            
            function addCIPtotemp
                %% add CIP ROW and add a break option to aviod copies
                CIPRow = cell2table({"CIP",0,CIPCleaningTime,0,d,0,true,nan(1),0},"VariableNames",temp.Properties.VariableNames);
                if lasttest
                   CIPRow.Day2Cook = temp.Day2Cook(end);
                   temp = [temp;CIPRow];
                   temp.TotalTime = cumsum(temp.CleaningTime + temp.CookingTime);
                   return
                end
                %find where to insert CIP row after ~40 hrs
                if isempty(FinalTable)
                    remaining_cookingT = CIPTime;
                else
                    remaining_cookingT = CIPTime - FinalTable.TotalTime(end);
                end
                slice = find(temp.TotalTime<=remaining_cookingT,1,'last');
                if isempty(slice)
                    temp = [CIPRow;temp];
                    FinalTable.CleaningTime(end) = 0;
                else
                    temp = [temp(1:slice,:);CIPRow;temp(slice+1:end,:)];
                    temp.CleaningTime(slice) = 0;
                end
                
                temp.TotalTime = cumsum(temp.CleaningTime + temp.CookingTime);
            end
            function tab = swaprows4CIP(tab,CIPIdx)
                diffCIP =  abs(tab.TotalTime(CIPIdx)-CIPTime);
                if diffCIP> CIPTime/10
                    %will move CIP one row above
                    row2swap = find(tab.TotalTime>CIPTime,1,'first');
                    if row2swap == find(CIPIdx,1)
                        row2swap = find(CIPIdx,1) -1;
                    end
                    rowbefore = tab(row2swap,:);
                    CIProw = tab(find(CIPIdx,1),:);
                    saveday = CIProw.Day2Cook;
                    CIProw.Day2Cook = rowbefore.Day2Cook;
                    rowbefore.Day2Cook = saveday;
                    tab(row2swap,:) = CIProw;
                    
                    tab = [tab(1:find(CIPIdx,1) - 1,:);rowbefore;tab(find(CIPIdx,1)+1:end,:)];
                    tab.TotalTime = cumsum(tab.CleaningTime + tab.CookingTime);
                end
            end
            function sub1200 = addcleaning1200(sub1200)
                % check how many times need to replace cleaning time
                cleaningIt = sub1200.TotalTime(end)/every24;
                
                
                for c = 1:floor(cleaningIt)
                    repIdx = find(sub1200.TotalTime<=every24*c,1,'last');
                    if isempty(repIdx)
                        break;
                    elseif repIdx == size(sub1200,1)
                        sub1200.CleaningTime(repIdx) = cleaning1200;
                    else
                        %check what is closer repIdx or repIdx +1
                        if abs(sub1200.TotalTime(repIdx) - every24*c) <= abs(sub1200.TotalTime(repIdx+1) - every24*c)
                            sub1200.CleaningTime(repIdx) = cleaning1200;
                        else
                            sub1200.CleaningTime(repIdx+1) = cleaning1200;
                        end
                    end
                end
                
            end
            
        end
        
        
        function [P1,P2] = pre600potassignment(app,setupTable,oatcost600,Allmatrix,updatecost600 , Chiacost600,recipes2move1200)
            % pre 600 pt assignment
            app.setup600 = setupTable(setupTable.Size == 600,:);
            if numel(unique(app.setup600.before)) == 1
                % same time before the weekend
            else
                app.setup600 = sortrows(app.setup600,'before',"descend"); % order by the time to cook before the weekend
            end
            
            %check Total Time
            Nrec = oatcost600.OriginalNum;
            cleanMat = Allmatrix(Nrec,Nrec(2:end));
            cleanTime = diag(cleanMat);
            cleanTime = [cleanTime ; 0];
            
            oatcost600.TotalTime = cumsum(oatcost600.MinimalTime + cleanTime);
            merge600 = [updatecost600 ; Chiacost600];
            if ~isempty(recipes2move1200)
                %% in next step need to update cost table with new recipes
                recipes2move1200.Properties.VariableNames = merge600.Properties.VariableNames;
                
            end
            %% sort by PlannedDay
            merge600 = [merge600 ; recipes2move1200];
            merge600 = sortrows(merge600,'plannedDay',"ascend");
            
            %check Total Time
            Nrec = merge600.OriginalNum;
            cleanMat = Allmatrix(Nrec,Nrec(2:end));
            cleanTime = diag(cleanMat);
            cleanTime = [cleanTime ; 0];
            
            merge600.TotalTime =  merge600.MinimalTime + cleanTime;
            %check how much time i need to add to cooking before weekend with oats
            % select pot with closest cooking time befor weekend to oats
            delta = app.setup600.before - oatcost600.TotalTime(end);
            [sparecookingTime, Ipot] = min(delta);
            allrec600_totalTime = cumsum(merge600.TotalTime);
            getrec = find(allrec600_totalTime<= sparecookingTime,1,"last");
            if isempty(getrec)
            else
                oatcost600 = [merge600(1:getrec,:) ; oatcost600]; % add selected Recipes to oats.
                merge600 = merge600(getrec+1:end,:);
            end
            %split the rest:
            allrec600_totalTime = cumsum(merge600.TotalTime);
            if Ipot == 1
                Tbefore = app.setup600{2,'before'};
                P1 = oatcost600;
                P2 = merge600(allrec600_totalTime<=Tbefore,:);
            else
                Tbefore = app.setup600{1,'before'};
                P2 = oatcost600;
                P1 = merge600(allrec600_totalTime<=Tbefore,:) ;
            end
            merge600 = merge600(find(allrec600_totalTime<=Tbefore,1,'last')+1:end,:);
            spare = inf;
            while spare>4
                allrec_beforeweekend = merge600(merge600.plannedDay == min(merge600.plannedDay),:);
                % check spare time before weekend
                if Ipot == 1
                    spare = Tbefore - sum(P2.TotalTime);
                    [~,i] = min(abs(allrec_beforeweekend.TotalTime - spare));
                    P2  = [P2; merge600(i,:)]; %#ok<*AGROW>
                    merge600(i,:) = [];
                    spare = Tbefore - sum(P2.TotalTime);
                else
                    spare = Tbefore - sum(P1.TotalTime);
                    [~,i] = min(abs(allrec_beforeweekend.TotalTime - spare));
                    P1  = [P1; merge600(i,:)];
                    merge600(i,:) = [];
                    spare = Tbefore - sum(P1.TotalTime);
                end
            end
            % split after weekend
            % recalc cleaning time
            Nrec = merge600.OriginalNum;
            cleanMat = Allmatrix(Nrec,Nrec(2:end));
            cleanTime = diag(cleanMat);
            cleanTime = [cleanTime ; 0];
            
            merge600.TotalTime =  merge600.MinimalTime + cleanTime;
            daysleft = unique(merge600.plannedDay);
            for ii = 1:numel(daysleft)
                daytable = merge600(merge600.plannedDay == daysleft(ii),:);
                if size(daytable,1)>2
                    daytimes = daytable.TotalTime;
                    daytimes = [(1:size(daytable,1))', daytimes];
                    daytimes = sortrows(daytimes,2,'descend');
                    g1 = daytimes(1,2);
                    g2 = daytimes(2,2);
                    g1_list = daytimes(1,1);
                    g2_list = daytimes(2,1);
                    for jj = 3:size(daytimes,1)
                        if g1<g2
                            g1 = g1 + daytimes(jj,2);
                            g1_list = [daytimes(jj,1);g1_list];
                            
                        else
                            g2 = g2 + daytimes(jj,2);
                            g2_list = [daytimes(jj,1);g2_list];
                        end
                    end
                    P1  = [P1; merge600(ismember(merge600.OriginalNum , daytable.OriginalNum(g1_list)),:)];
                    P2  = [P2; merge600(ismember(merge600.OriginalNum , daytable.OriginalNum(g2_list)),:)];
                    merge600(merge600.plannedDay == daysleft(ii),:) = [];
                    
                    
                else
                    if size(daytable,1) == 1
                        if Ipot == 1
                            P2  = [P2; merge600(merge600.OriginalNum == daytable.OriginalNum,:)];
                        else
                            P1  = [P1; merge600(merge600.OriginalNum == daytable.OriginalNum,:)];
                        end
                        
                        merge600(merge600.plannedDay == daysleft(ii),:) = [];
                    end
                    if size(daytable,1) == 2
                        P1  = [P1; merge600(merge600.OriginalNum == daytable.OriginalNum(1),:)];
                        P2  = [P2; merge600(merge600.OriginalNum == daytable.OriginalNum(2),:)];
                        merge600(merge600.plannedDay == daysleft(ii),:) = [];
                    end
                end
            end
        end
        
        
        function fulltable = insertbreak(~,fulltable,breaktable,r,setuptable)
            breaktable.break_duration = hours(breaktable.break_duration);
            breaktable.break_duration.Format = 'hh:mm';
            insertbreakI = find(fulltable.Day2Cook2 == breaktable.break_day(r),1,'first');
            if isempty(insertbreakI)
                if setuptable.StartDay == breaktable.break_day(r)
                    breakrow = cell2table({'BREAK',0,breaktable.break_duration(r),0,1,0,true,breaktable.break_day(r),1},"VariableNames",fulltable.Properties.VariableNames);
                    fulltable = [breakrow;fulltable];
                elseif setuptable.EndDay == breaktable.break_day(r)
                    breakrow = cell2table({'BREAK',0,breaktable.break_duration(r),0,setuptable.Ndays,0,true,breaktable.break_day(r),setuptable.Ndays},"VariableNames",fulltable.Properties.VariableNames);
                    fulltable = [fulltable;breakrow];
                else
                    error('בעיה עם הוספת השבתה לאקסל');
                end
            elseif isequal(insertbreakI,1)
                breakrow = cell2table({'BREAK',0,breaktable.break_duration(r),0,fulltable.Day2Cook(1),0,true,breaktable.break_day(r),0,fulltable.Day2Cook2(insertbreakI),fulltable.Day2Cook3(1)},"VariableNames",fulltable.Properties.VariableNames);
                fulltable = [breakrow;fulltable];
            else
                breakrow = cell2table({'BREAK',0,breaktable.break_duration(r),0,fulltable.Day2Cook(insertbreakI),0,true,breaktable.break_day(r),0,fulltable.Day2Cook2(insertbreakI),fulltable.Day2Cook3(insertbreakI)},"VariableNames",fulltable.Properties.VariableNames);
                fulltable = [fulltable(1:insertbreakI-1,:) ;breakrow;fulltable(insertbreakI:end,:)];
            end
            
            fulltable.TotalTime = cumsum(fulltable.CookingTime + fulltable.CleaningTime);
            
        end
        
        
        function updatetables(app)
            s = uistyle('BackgroundColor','red');
            app.WeeklyPlan_1.Data = app.FinalTable600_1(:,{'RecipeName'	,'Amount',	'CookingTime'	,'CleaningTime'	,'TotalTime'	,'Day2Cook2'});
            app.UpdateVol_1.Text = '600';
            getI = find(~app.FinalTable600_1.OnTime);
            cell2color = [getI  ones(size(getI))];
            addStyle(app.WeeklyPlan_1,s,'cell',cell2color);
            app.WeeklyPlan_1.Data.Day2Cook2= arrayfun(@(ar)swapnum2day(app,ar),app.WeeklyPlan_1.Data.Day2Cook2);
            app.updateportions_1.Text = num2str(round(sum(app.FinalTable600_1.Iterations),0));


            app.WeeklyPlan_2.Data = app.FinalTable600_2(:,{'RecipeName'	,'Amount',	'CookingTime'	,'CleaningTime'	,'TotalTime'	,'Day2Cook2'});
            app.UpdateVol_2.Text = '600';
            getI = find(~app.FinalTable600_2.OnTime);
            cell2color = [getI  ones(size(getI))];
            addStyle(app.WeeklyPlan_2,s,'cell',cell2color);
            app.WeeklyPlan_2.Data.Day2Cook2= arrayfun(@(ar)swapnum2day(app,ar),app.WeeklyPlan_2.Data.Day2Cook2);
            app.updateportions_2.Text = num2str(round(sum(app.FinalTable600_2.Iterations),0));
            
            
            app.WeeklyPlan_3.Data = app.FinalTable1200(:,{'RecipeName'	,'Amount',	'CookingTime'	,'CleaningTime'	,'TotalTime'	,'Day2Cook2'});
            app.UpdateVol_3.Text = '1200';
            getI = find(~app.FinalTable1200.OnTime);
            cell2color = [getI  ones(size(getI))];
            addStyle(app.WeeklyPlan_3,s,'cell',cell2color);
            app.WeeklyPlan_3.Data.Day2Cook2= arrayfun(@(ar)swapnum2day(app,ar),app.WeeklyPlan_3.Data.Day2Cook2);
            app.updateportions_3.Text = num2str(round(sum(app.FinalTable1200.Iterations),0));
            
            
            for i = 1:3
                
                if ~isduration(app.(['WeeklyPlan_' num2str(i)]).Data.TotalTime)
                app.(['WeeklyPlan_' num2str(i)]).Data.TotalTime = hours(app.(['WeeklyPlan_' num2str(i)]).Data.TotalTime);
                end
                app.(['WeeklyPlan_' num2str(i)]).Data.TotalTime.Format = "hh:mm";
                app.(['updateCap_' num2str(i)]).Text = [ num2str(round((app.(['WeeklyPlan_' num2str(i)]).Data.TotalTime(end))/...
                    hours(app.capacityEditField.Value)*100,2)) ' %'];
                
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % this function loads all content to UI
            
            %% get main path to the app;
            setupPaths(app)
            
            %% get times from setting:
            getCIPtimes(app)
            %%get floor times
            app.stopcooking = str2num(app.THUR_time.Value);
            app.startcooking = str2num(app.SUN_time.Value);
            
            %% get cooking duration information:
            setdays(app,-1);
            
            getConstrains(app);
            getTimesFile(app);
            
            app.MetaDataFile_Times = sortrows(app.MetaDataFile_Times,'SerialNumber',"ascend");
            app.MetaDataFile_Constarains = sortrows(app.MetaDataFile_Constarains,'SerialNumber','ascend');
            % need to read and process requirment files, cleaning times and cooking
            % times
            %% get cooking table
            cookingTime = app.MetaDataFile_Times(:,1:3);
            app.CookingTimesTable.Data = cookingTime;
            app.CookingTimesTable.ColumnName = cookingTime.Properties.VariableNames;
            
            Portions = app.MetaDataFile_Constarains(:,[1,2,4:end]);
            app.CookingSizesTable.Data = Portions;
            app.CookingSizesTable.ColumnName = Portions.Properties.VariableNames;
            
            
            %% prep output file copy
            timeind = datestr(now,20); timeind = strrep(timeind,'/','_');
            app.outFile = ['תוכנית_שבועית' '_' timeind];
            
            % create a copy of 'TemplateNew' that the name is:
            % ['תוכנית_שבועית' '_' the day of today]
            copyfile(fullfile(app.mainpath,'TempleteNew.xlsx'),[app.mainpath '\' app.outFile '.xlsx'])
                        
            capacityEditFieldValueChanged(app,[])
        end

        % Button pushed function: getRequ
        function getReqPushed(app, event)
            % Change button name to "אנא המתן"
            app.getRequ.Text = 'אנא המתן...';
            app.getRequ.FontSize = 10;
            
            %% part 1 - loading from main table:
            
            import_and_process_main(app);
            
            app.weeklyrequirementsTable.Data = app.mainTable;
            app.weeklyrequirementsTable.ColumnName = {'מספר סידורי','יום ה','יום א','יום ב','יום ג','יום ד'};
            
            app.CalcPlan.Enable = 'on';
            app.outFileButton.Enable = 'on';
            
            app.TabGroup.SelectedTab = app.weeklyRequirements;
            % Change button name
            app.getRequ.Text = 'תוכנית שבועית הוזנה';
            app.getRequ.FontSize = 20;
        end

        % Button pushed function: CalcPlan
        function CalcPlanButtonPushed(app, event)
            app.breakTable = []; 
            %% check if exist a file for output:
            if ~exist([app.mainpath '\' app.outFile '.xlsx'],'file')
                copyfile(fullfile(app.mainpath ,'TempleteNew.xlsx'),[app.mainpath '\' app.outFile '.xlsx']);
            end
            
            % Change button name
            app.getRequ.Enable = 'off';
            % Change button name to "אנא המתן"
            app.CalcPlan.Text = 'אנא המתן...';
            app.CalcPlan.FontSize = 10;
            n = 6;
            % Put text on top of icon
            app.CalcPlan.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.CalcPlan.BackgroundColor,25,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.CalcPlan.Icon = wbar;

            app.printingCounter = 0;
            
            app.CalcPlan.Text = 'מבצע הגדרת זמני בישול ונקיון';
            % part 1 - set up cooking times for each pot
            setdays(app,-1); % this reads all the data from the app and sets the time/days for cooking
            THUR_timeValueChanged(app, []);
            SUN_timeValueChanged(app,[]);
            createSetupTable(app);
            check_break(app);
            % part 2 - update cleaning times
            getCIPtimes(app);
            % replace 0 (ordinary cleaning time) with the default value.
            app.mainTableJoined.CleaningTime(app.mainTableJoined.CleaningTime==0) =app.deafualtCleaning;
            
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(1/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;

            %% part 2 - get special groups:
            app.CalcPlan.Text = 'מחפש מתכונים מיוחדים - ש"ש';
            % oats - 1
            % chia - 2
            oats_idx = app.mainTableJoined.Group == 1;
            chia_idx = app.mainTableJoined.Group == 2;
            
            % cut tables by groups: oats,chia and the rest of the recipes.
            app.oatsTable = app.mainTableJoined(oats_idx,:);
            app.chiaTable = app.mainTableJoined(chia_idx,:);
            app.allTable = app.mainTableJoined((~oats_idx & ~chia_idx),:);
            
            % add sequential Nums for all tables.
            app.oatsTable.SeqNum = (1:size(app.oatsTable,1))';
            app.chiaTable.SeqNum = (1:size(app.chiaTable,1))';
            app.allTable.SeqNum = (1:size(app.allTable,1))';
            
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(2/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            pause(0.5)
            
            %% part 3 - build cleaning matrix for pairs - get values for pairs by SeqNum
            app.CalcPlan.Text = 'הפרדת מתכונים לפי סירים';
            app.Allmatrix = repmat(app.mainTableJoined.CleaningTime,[1 length(app.mainTableJoined.CleaningTime)]);
            app.Allmatrix = app.Allmatrix - diag(diag(app.Allmatrix));
            % use getPortion function to update the portion columns and calculate the
            % minimal time per day for each recipe - returns a structure of 5 tables
            % each for each cooking day 1:5 seperated into initial pots.
            % Struct -> dayN -> originalTable/Table600/Table1200
            app.allstruct = getPortions(app,app.allTable);
            app.chiastruct = getPortions(app,app.chiaTable);
            app.oatsstruct = getPortions(app,app.oatsTable);
            % find optimal order for each pot for each day
            app.allstructOptim600 = calcCost(app,app.allstruct,app.Allmatrix,app.reqDays,1);
            app.allstructOptim1200 = calcCost(app,app.allstruct,app.Allmatrix,app.reqDays,2);
            %check total time of allcost per pot to see balance;
            
            allcost600 = gettablefromstruct(app,app.allstructOptim600,app.reqDays,"600");
            %             Total600 = sum(allcost600.MinimalTime); %Minimal Time is cleaning and cooking Time.
            allcost1200 = gettablefromstruct(app,app.allstructOptim1200,app.reqDays,"1200");
            %             Total1200 = sum(allcost1200.MinimalTime); %Minimal Time is cleaning and cooking Time.
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(3/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            
            %% part 4- % check balance of cost and recipes needed to move.
            % for 600
            app.CalcPlan.Text = 'תכנון עבור סירי 600';
            pot = "600";
            plannedtime2cook = sum(app.setupTable.totaltime(app.setupTable.Size == str2num(pot)));
            potTime2cook = sum(allcost600.MinimalTime);
            optimStruct = app.allstructOptim600;
            weeklytimes = sum(cell2mat(app.setupTable.times(app.setupTable.Size == str2num(pot))),1); %time perday
            
            [app.capacity600,app.recipes2move600,app.updateOptimStruct600] = checkbalance_byamount(app,plannedtime2cook,potTime2cook,optimStruct,weeklytimes,pot);
            
            % check balance of cost and recipes needed to move. for 1200
            app.CalcPlan.Text = 'תכנון עבור סיר 1200';
            pot = "1200";
            plannedtime2cook = sum(app.setupTable.totaltime(app.setupTable.Size == str2num(pot)));
            potTime2cook = sum(allcost1200.MinimalTime);
            optimStruct = app.allstructOptim1200;
            weeklytimes = sum(cell2mat(app.setupTable.times(app.setupTable.Size == str2num(pot))),1); %time perday
            
            [app.capacity1200,app.recipes2move1200,app.updateOptimStruct1200] = checkbalance_byamount(app,plannedtime2cook,potTime2cook,optimStruct,weeklytimes,pot);
            
            ChiastructOptim600 = calcCost(app,app.chiastruct,app.Allmatrix,app.reqDays,1);
            ChiastructOptim1200 = calcCost(app,app.chiastruct,app.Allmatrix,app.reqDays,2);
            Chiacost1200 = gettablefromstruct(app,ChiastructOptim1200,app.reqDays,"1200");
            Chiacost600 = gettablefromstruct(app,ChiastructOptim600,app.reqDays,"600");
            
            
            oatstructOptim600 = calcCost(app,app.oatsstruct,app.Allmatrix,app.reqDays,1);
            oatstructOptim1200 = calcCost(app,app.oatsstruct,app.Allmatrix,app.reqDays,2);
            oatcost1200 = gettablefromstruct(app,oatstructOptim1200,app.reqDays,"1200");
            oatcost600 = gettablefromstruct(app,oatstructOptim600,app.reqDays,"600");
            % only for oats
            if ~isempty(oatcost600)
                oatcost600.plannedDay = ones(size(oatcost600,1),1);
            end
            if ~isempty(oatcost1200)
                oatcost1200.plannedDay = ones(size(oatcost1200,1),1);
            end
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(4/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            
            app.CalcPlan.Text = 'בדיקות איזון מתכונים בין סירים';
            %% part 5 - % check balance for recipes to move:
            if isempty(app.recipes2move1200) && isempty(app.recipes2move600)
                % no need to move recipes
            elseif ~isempty(app.recipes2move1200) && ~isempty(app.recipes2move600)
                % both Pots have recipes to move - check that the recipes in the list
                % are ok to move!
                % for 1200 - move only recipes with amounts that are under 1800
                % for 600 - move all recipes that are over 1200
                % MOVE TO GROUP OR ADD BACK TO COST TABLE IN THE
                % RIGHT PLACE
                [app.recipes2move600,app.updateOptimStruct600]= check_recipes2move(app,app.recipes2move600,600,app.updateOptimStruct600);
                [app.recipes2move1200,app.updateOptimStruct1200]= check_recipes2move(app,app.recipes2move1200,1200,app.updateOptimStruct1200);
            elseif ~isempty(app.recipes2move1200) && isempty(app.recipes2move600)
                % only 1200 Pot has recipes to move - check that the recipes in the list
                % are ok to move!
                % for 1200 - move only recipes with amounts that are under 1800
                % MOVE TO GROUP OR ADD BACK TO COST TABLE IN THE
                % RIGHT PLACE
                [app.recipes2move1200,app.updateOptimStruct1200]= check_recipes2move(app,app.recipes2move1200,1200,app.updateOptimStruct1200);
            elseif isempty(app.recipes2move1200) && ~isempty(app.recipes2move600)
                % only 600 Pot has recipes to move - check that the recipes in the list
                % are ok to move!
                % for 600 - move all recipes that are over 1200
                % MOVE TO GROUP OR ADD BACK TO COST TABLE IN THE
                % RIGHT PLACE
                [app.recipes2move600,app.updateOptimStruct600]= check_recipes2move(app,app.recipes2move600,600,app.updateOptimStruct600);
                
            end
            
            app.updateOptimStruct1200 = calcCost(app,app.updateOptimStruct1200,app.Allmatrix,app.reqDays,2);
            app.updateOptimStruct600 = calcCost(app,app.updateOptimStruct600,app.Allmatrix,app.reqDays,1);
            updatecost1200 = gettablefromstruct(app,app.updateOptimStruct1200,app.reqDays,"1200");
            updatecost600 = gettablefromstruct(app,app.updateOptimStruct600,app.reqDays,"600");
            
            %             at this point i have all the optimized cost tables + recipes that i need to move between pots for balance.
            
            merge1200 = [updatecost1200 ; Chiacost1200 ; oatcost1200];
            if ~isempty(app.recipes2move600)
                
                app.recipes2move600.Properties.VariableNames = merge1200.Properties.VariableNames;
            end
           
            merge1200 = [merge1200 ; app.recipes2move600];
            merge1200 = sortrows(merge1200,'plannedDay',"ascend");
            
            %check Total Time
            Nrec = merge1200.OriginalNum;
            cleanMat = app.Allmatrix(Nrec,Nrec(2:end));
            cleanTime = diag(cleanMat);
            cleanTime = [cleanTime ; 0];
            
            merge1200.TotalTime =  merge1200.MinimalTime + cleanTime;
            app.CalcPlan.Text = 'הוספת זמני נקיון';
            getCIPtimes(app); %updateCIP time from app
            app.FinalTable1200 = addCIP(app,merge1200,app.mainTableJoined,app.setupTable,1200,app.Allmatrix);
            
            [P1,P2] = pre600potassignment(app,app.setupTable,oatcost600,app.Allmatrix,updatecost600 , Chiacost600,app.recipes2move1200);
            % reset P1/P2 TotalTime to minimalTime +cleaning
            Nrec = P1.OriginalNum;
            cleanMat = app.Allmatrix(Nrec,Nrec(2:end));
            cleanTime = diag(cleanMat);
            cleanTime = [cleanTime ; 0];
            P1.TotalTime = P1.MinimalTime + cleanTime;
            
            Nrec = P2.OriginalNum;
            cleanMat = app.Allmatrix(Nrec,Nrec(2:end));
            cleanTime = diag(cleanMat);
            cleanTime = [cleanTime ; 0];
            P2.TotalTime = P2.MinimalTime + cleanTime;
            
            getCIPtimes(app); %update CIP times
            app.FinalTable600_1 = addCIP(app,P1,app.mainTableJoined,app.setup600(1,:),600,app.Allmatrix);
            app.FinalTable600_2 = addCIP(app,P2,app.mainTableJoined,app.setup600(2,:),600,app.Allmatrix);
            
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(5/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;
            app.CalcPlan.Text = 'כתיבה לקובץ';
            
            %% part 6 - write to file and main tabs:
            %check if breakTable is empty
            if isempty(app.breakTable)
            else
                %check if the break is for all pots or only to 1200/600
                for r = 1:size(app.breakTable,1)
                    if strcmp(app.breakTable.break_pot(r),'all')
                        app.FinalTable1200 = insertbreak(app,app.FinalTable1200,app.breakTable,r,app.setupTable(app.setupTable.Size == 1200,:));
                        app.FinalTable600_1 = insertbreak(app,app.FinalTable600_1,app.breakTable,r,app.setup600(1,:));
                        app.FinalTable600_2 = insertbreak(app,app.FinalTable600_2,app.breakTable,r,app.setup600(2,:));
                    elseif strcmp(app.breakTable.break_pot(r),'1200')
                        app.FinalTable1200 = insertbreak(app,app.FinalTable1200,app.breakTable,r,app.setupTable(app.setupTable.Size == 1200,:));
                    elseif strcmp(app.breakTable.break_pot(r),'600')
                        app.FinalTable600_1 = insertbreak(app,app.FinalTable600_1,app.breakTable,r,app.setup600(1,:));
                        app.FinalTable600_2 = insertbreak(app,app.FinalTable600_2,app.breakTable,r,app.setup600(2,:));
                    end
                end
            end
            
            
            app.FinalTable1200.RecipeName = cellstr(app.FinalTable1200.RecipeName);
            app.FinalTable600_2.RecipeName = cellstr(app.FinalTable600_2.RecipeName);
            app.FinalTable600_1.RecipeName = cellstr(app.FinalTable600_1.RecipeName);
            write2file(app);
            
            % insert tables into app
            updatetables(app)
            
            
            % Update image data (royalblue)
            currentProg = min(round((size(wbar,2)-2)*(6/n)),size(wbar,2)-2);
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 1) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 2) = 0.0;
            app.CalcPlan.Icon(2:end-1, 2:currentProg+1, 3) = 0.0;


            % Update Total load for all programs:           
            portionLoad = str2num(app.updateportions_1.Text) +...
                str2num(app.updateportions_2.Text)+...
                str2num(app.updateportions_3.Text);
            
            load = str2num(extractBefore(app.updateCap_1.Text,'%'))+... 
                str2num(extractBefore(app.updateCap_2.Text,'%'))+... 
                str2num(extractBefore(app.updateCap_3.Text,'%'));
            
            
            str = {num2str(portionLoad),...
                'כמות מנות כוללת',...
                [num2str(round(load/3,1)) '%'],...
                'עומס שבועי ממוצע עבור שלושת הסירים'};
            
            
            app.TottalLoad.Text = sprintf('%s  %s',sprintf('%s %s',str{1},str{4}),sprintf('%s\t|\t %s',str{3},str{2}));
            app.TottalLoad.Visible = 'on';

            % remove waitbar
            app.CalcPlan.Icon = '';
            % Change button name
            app.CalcPlan.Text = 'חשב תוכנית שבועית';
            app.CalcPlan.FontSize = 20;
            % Change button name
            app.getRequ.Enable = 'on';
            % Change button name
            app.getRequ.Text = 'הכנס תוכנית שבועית';
            
        end

        % Button pushed function: addDownTime
        function addDownTimeButtonPushed(app, event)
            
            app.DownTimeTable.Data =[app.DownTimeTable.Data;...
                cell2table({app.duration_time_down.Value,app.pot_list_down.Value,app.Start_day_down.Value},'VariableNames',app.DownTimeTable.ColumnName)];
            
        end

        % Value changed function: AllowClean1200
        function AllowClean1200ValueChanged(app, event)
            value = app.AllowClean1200.Value;
            if value == 1 %checkbox on
                app.Time_1200.Editable = 'On';
                app.Time_1200.Enable = 'On';
                app.min_hour_1200.Enable = 'On';
                app.DOTime_1200_Time_2.Editable = 'On';
                app.DOTime_1200_Time_2.Enable = 'On';
                app.DOTime_1200_min_hour_2.Enable = 'On';
            else
                app.Time_1200.Editable = 'Off';
                app.Time_1200.Enable = 'Off';
                app.min_hour_1200.Enable = 'Off';
                app.DOTime_1200_Time_2.Editable = 'Off';
                app.DOTime_1200_Time_2.Enable = 'Off';
                app.DOTime_1200_min_hour_2.Enable = 'Off';
            end
        end

        % Value changed function: AllowWashTime
        function AllowWashTimeValueChanged(app, event)
            value = app.AllowWashTime.Value;
            if value == 1 %checkbox on
                app.WashTime.Editable = 'On';
                app.WashTime.Enable = 'On';
                app.WashTime_min_hour.Enable = 'On';
            else
                app.WashTime.Editable = 'Off';
                app.WashTime.Enable = 'Off';
                app.WashTime_min_hour.Enable = 'Off';
            end
        end

        % Value changed function: AllowCIP
        function AllowCIPValueChanged(app, event)
            value = app.AllowCIP.Value;
            if value == 1 %checkbox on
                app.CIP_Time.Editable = 'On';
                app.CIP_Time.Enable = 'On';
                app.CIP_min_hour.Enable = 'On';
                app.DOCIP_Time_2.Editable = 'On';
                app.DOCIP_Time_2.Enable = 'On';
                app.DOCIP_min_hour_2.Enable = 'On';
            else
                app.CIP_Time.Editable = 'Off';
                app.CIP_Time.Enable = 'Off';
                app.CIP_min_hour.Enable = 'Off';
                app.DOCIP_Time_2.Editable = 'Off';
                app.DOCIP_Time_2.Enable = 'Off';
                app.DOCIP_min_hour_2.Enable = 'Off';
            end
        end

        % Value changed function: CIP_Time, CIP_min_hour, 
        % DOCIP_Time_2, DOCIP_min_hour_2, DOTime_1200_Time_2, 
        % DOTime_1200_min_hour_2, Time_1200, WashTime, 
        % WashTime_min_hour, min_hour_1200
        function CIP_TimeValueChanged(app, event)
            getCIPtimes(app); 
        end

        % Value changed function: EndDay_1, EndDay_2, EndDay_3, 
        % StartDay_1, StartDay_2, StartDay_3, end_time_1, 
        % end_time_2, end_time_3, start_time_1, start_time_2, 
        % start_time_3
        function WeeklyTimesValueChanged(app, event)
            %get the tab where change occured
            selectTab = event.Source.Parent.Parent.Parent.Title;
            
            if strcmp(selectTab,'סיר 1')
                %600
                selectTabN = 1;
            elseif strcmp(selectTab,'סיר 2')
                %600
                selectTabN = 2;
            elseif strcmp(selectTab,'סיר 3')
                %1200
                selectTabN = 3;
            end
            
            setdays(app,selectTabN);


        end

        % Button pushed function: outFileButton
        function outFileButtonPushed(app, event)
            prompt = {'הכנס שם לקובץ פלט:'};
            dlgtitle = 'קובץ פלט';
            dims = [1 50];
            definput = {'תוכנית_שבועית'};
            out = inputdlg(prompt,dlgtitle,dims,definput);
            try
                app.outFile = out{:};
            catch
                app.outFile = definput{:};
            end
            copyfile(fullfile(app.mainpath,'TempleteNew.xlsx'),[app.mainpath '\' app.outFile '.xlsx'])
            %disp(['The output is saved in:' app.outFile])
        end

        % Value changed function: WEEKLY
        function WEEKLYValueChanged(app, event)
            value = app.WEEKLY.Value;
            if value
                app.Label_15.Enable = 'on';
                app.SUN_time.Enable = 'on';
                app.Label_14.Enable = 'on';
                app.THUR_time.Enable = 'on';
            else
                app.Label_15.Enable = 'off';
                app.SUN_time.Enable = 'off';
                app.Label_14.Enable = 'off';
                app.THUR_time.Enable = 'off';
            end
        end

        % Value changed function: FRI
        function FRIValueChanged(app, event)
            value = app.FRI.Value;
            
            if value
                app.FRI_END.Enable = 'on';
                app.FRI_END_label.Enable = 'on';
                app.FRI_START.Enable = 'on';
                app.FRI_START_label.Enable = 'on';
                %% ask to change Thursday
                dlgtitle = 'שעות בישול בחמישי';
                quest = {'האם לשנות את שעת סיום הביושל בחמישי?'};
                btn1 = 'כן - פתח לעריכה';
                btn2 = 'לא';
                defbtn  = btn1;
                answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
                switch answer
                    case btn1
                        app.WEEKLY.Value = true;
                        WEEKLYValueChanged(app, [])
                    case btn2
                        thur_end = app.THUR_time.Value;
                        warndlg(sprintf('שעת סיום הבישול בחמישי \n%s',thur_end),'שעות בישול בחמישי');
                        
                end
                FRI_ValueChanged(app, []);
            else
                app.FRI_END.Enable = 'off';
                app.FRI_END_label.Enable = 'off';
                app.FRI_START.Enable = 'off';
                app.FRI_START_label.Enable = 'off';
                THUR_timeValueChanged(app, [])
                app.includefriday = [0,0,0];
                
            end
        end

        % Callback function
        function SATValueChanged(app, event)
            value = app.SAT.Value;
            if value
                app.SAT_END.Enable = 'on';
                app.SAT_END_label.Enable = 'on';
                app.SAT_START.Enable = 'on';
                app.SAT_START_label.Enable = 'on';
                %% ask to change Thursday
                dlgtitle ='שעות בישול בראשון';
                quest = {'האם לשנות את תחילת הבישול בראשון?'};
                btn1 = 'כן - פתח לעריכה';
                btn2 = 'לא';
                defbtn  = btn1;
                answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
                switch answer
                    case btn1
                        app.WEEKLY.Value = true;
                        WEEKLYValueChanged(app, [])
                    case btn2
                        sun_end = app.SUN_time.Value;
                        
                        warndlg(sprintf('תחילת שעת בישול ביום ראשון \n%s',sun_end),'שעות בישול בראשון');
                        
                        
                end
            else
                app.SAT_END.Enable = 'off';
                app.SAT_END_label.Enable = 'off';
                app.SAT_START.Enable = 'off';
                app.SAT_START_label.Enable = 'off';
                app.extraTime = [];
            end
        end

        % Cell selection callback: DownTimeTable
        function DownTimeTableCellSelection(app, event)
            indices = event.Indices;
            %app.DownTimeTable.Data(indices(1),:)
            quest = 'האם תרצה למחוק את השורה שנבחרה?';
            dlgtitle = 'הגדרת זמני השבתה';
            btn1='כן';btn2='לא';
            defbtn=btn1;
            answer = questdlg(quest,dlgtitle,btn1,btn2,defbtn);
            if strcmp(answer,btn1)
                app.DownTimeTable.Data(indices(1),:) = [];
            end
            
        end

        % Value changed function: THUR_time
        function THUR_timeValueChanged(app, event)
            value = app.THUR_time.Value;
            app.ThurEnd = str2num(value);
        end

        % Value changed function: SUN_time
        function SUN_timeValueChanged(app, event)
            value = app.SUN_time.Value;
            app.SunStart = str2num(value);
        end

        % Value changed function: FRI_END, FRI_START
        function FRI_ValueChanged(app, event)
            app.includefriday = ones([1,3])*(str2num(app.FRI_END.Value) - str2num(app.FRI_START.Value));
        end

        % Callback function
        function SAT_ValueChanged(app, event)
            app.includesaturday = ones([1,3])*(str2num(app.SAT_END.Value) - str2num(app.SAT_START.Value));
        end

        % Value changed function: capacityEditField
        function capacityEditFieldValueChanged(app, event)
            value = app.capacityEditField.Value;
            app.capacitySetting = value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            for i = 2:3
                app.(['StartDay_' num2str(i)]).Value = app.(['StartDay_' num2str(i-1)]).Value;
                app.(['EndDay_' num2str(i)]).Value = app.(['EndDay_' num2str(i-1)]).Value;
                app.(['start_time_' num2str(i)]).Value = app.(['start_time_' num2str(i-1)]).Value;
                app.(['end_time_' num2str(i)]).Value = app.(['end_time_' num2str(i-1)]).Value;
                setdays(app,i);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'שטראוס';
            app.TabGroup = uitabgroup(app.MainApp);
            app.WeeklyRecipe = uitab(app.TabGroup);
            app.WeeklyRecipe.Title = 'מתכון שבועי';
            app.GridLayout12 = uigridlayout(app.WeeklyRecipe);
            app.outFileButton = uibutton(app.GridLayout12, 'push');
            app.outFileButton.ButtonPushedFcn = createCallbackFcn(app, @outFileButtonPushed, true);
            app.outFileButton.Icon = 'settings.png';
            app.outFileButton.Enable = 'off';
            app.outFileButton.Text = '';
            app.CalcPlan = uibutton(app.GridLayout12, 'push');
            app.CalcPlan.ButtonPushedFcn = createCallbackFcn(app, @CalcPlanButtonPushed, true);
            app.CalcPlan.Enable = 'off';
            app.CalcPlan.Text = 'חשב תוכנית שבועית';
            app.getRequ = uibutton(app.GridLayout12, 'push');
            app.getRequ.ButtonPushedFcn = createCallbackFcn(app, @getReqPushed, true);
            app.getRequ.Text = 'הכנס תוכנית שבועית';
            app.TabGroup2 = uitabgroup(app.GridLayout12);
            app.Tab_1 = uitab(app.TabGroup2);
            app.Tab_1.Title = 'סיר 1';
            app.GridLayout_1 = uigridlayout(app.Tab_1);
            app.Grid_Times_1 = uigridlayout(app.GridLayout_1);
            app.Label_16 = uilabel(app.Grid_Times_1);
            app.Label_16.Text = ' תחילת שבוע נוכחי';
            app.StartDay_1 = uidropdown(app.Grid_Times_1);
            app.StartDay_1.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.StartDay_1.ItemsData = {'א', 'ב', 'ג', 'ד', 'ה'};
            app.StartDay_1.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.StartDay_1.Value = 'ד';
            app.Label_17 = uilabel(app.Grid_Times_1);
            app.Label_17.Text = ' סוף שבוע הבא';
            app.EndDay_1 = uidropdown(app.Grid_Times_1);
            app.EndDay_1.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.EndDay_1.ItemsData = {'א', 'ב', 'ג', 'ד', 'ה'};
            app.EndDay_1.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.EndDay_1.Value = 'ג';
            app.Label_start_time = uilabel(app.Grid_Times_1);
            app.Label_start_time.Text = 'שעת התחלה';
            app.start_time_1 = uidropdown(app.Grid_Times_1);
            app.start_time_1.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.start_time_1.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.start_time_1.Value = '6';
            app.Label_end_time = uilabel(app.Grid_Times_1);
            app.Label_end_time.Text = 'שעת סיום';
            app.end_time_1 = uidropdown(app.Grid_Times_1);
            app.end_time_1.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.end_time_1.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.end_time_1.Value = '21';
            app.Button = uibutton(app.Grid_Times_1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'החל על כולם';
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'סיר 2';
            app.GridLayout_2 = uigridlayout(app.Tab_2);
            app.Grid_Times_2 = uigridlayout(app.GridLayout_2);
            app.Label_StartDay_2 = uilabel(app.Grid_Times_2);
            app.Label_StartDay_2.Text = 'תחילת שבוע';
            app.StartDay_2 = uidropdown(app.Grid_Times_2);
            app.StartDay_2.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.StartDay_2.ItemsData = {'א', 'ב', 'ג', 'ד', 'ה'};
            app.StartDay_2.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.StartDay_2.Value = 'ד';
            app.Label_EndDay_2 = uilabel(app.Grid_Times_2);
            app.Label_EndDay_2.Text = 'סוף שבוע';
            app.EndDay_2 = uidropdown(app.Grid_Times_2);
            app.EndDay_2.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.EndDay_2.ItemsData = {'א', 'ב', 'ג', 'ד', 'ה'};
            app.EndDay_2.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.EndDay_2.Value = 'ג';
            app.Label_start_time_2 = uilabel(app.Grid_Times_2);
            app.Label_start_time_2.Text = 'שעת התחלה';
            app.start_time_2 = uidropdown(app.Grid_Times_2);
            app.start_time_2.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.start_time_2.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.start_time_2.Value = '6';
            app.Label_end_time_2 = uilabel(app.Grid_Times_2);
            app.Label_end_time_2.Text = 'שעת סיום';
            app.end_time_2 = uidropdown(app.Grid_Times_2);
            app.end_time_2.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.end_time_2.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.end_time_2.Value = '21';
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = 'סיר 3';
            app.GridLayout_3 = uigridlayout(app.Tab_3);
            app.Grid_Times_3 = uigridlayout(app.GridLayout_3);
            app.Label_StartDay_3 = uilabel(app.Grid_Times_3);
            app.Label_StartDay_3.Text = 'תחילת שבוע';
            app.StartDay_3 = uidropdown(app.Grid_Times_3);
            app.StartDay_3.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.StartDay_3.ItemsData = {'א', 'ב', 'ג', 'ד', 'ה'};
            app.StartDay_3.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.StartDay_3.Value = 'ד';
            app.Label_EndDay_3 = uilabel(app.Grid_Times_3);
            app.Label_EndDay_3.Text = 'סוף שבוע';
            app.EndDay_3 = uidropdown(app.Grid_Times_3);
            app.EndDay_3.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.EndDay_3.ItemsData = {'א', 'ב', 'ג', 'ד', 'ה'};
            app.EndDay_3.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.EndDay_3.Value = 'ג';
            app.Label_start_time_3 = uilabel(app.Grid_Times_3);
            app.Label_start_time_3.Text = 'שעת התחלה';
            app.start_time_3 = uidropdown(app.Grid_Times_3);
            app.start_time_3.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.start_time_3.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.start_time_3.Value = '6';
            app.Label_end_time_3 = uilabel(app.Grid_Times_3);
            app.Label_end_time_3.Text = 'שעת סיום';
            app.end_time_3 = uidropdown(app.Grid_Times_3);
            app.end_time_3.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.end_time_3.ValueChangedFcn = createCallbackFcn(app, @WeeklyTimesValueChanged, true);
            app.end_time_3.Value = '21';
            app.TottalLoad = uilabel(app.GridLayout12);
            app.TottalLoad.Visible = 'off';
            app.TottalLoad.Tooltip = {'עומס כללי עבור כל השבוע'};
            app.TottalLoad.Text = 'עומס כללי';
            app.OutputTab = uitabgroup(app.GridLayout12);
            app.Pot_1 = uitab(app.OutputTab);
            app.Pot_1.Title = 'סיר 1';
            app.Grid_Pot1 = uigridlayout(app.Pot_1);
            app.WeeklyPlan_1 = uitable(app.Grid_Pot1);
            app.WeeklyPlan_1.ColumnName = {'מתכון'; 'כמות'; 'משך_בישול'; 'זמני_נקיון'; 'זמן_כולל'; 'יום בישול'};
            app.WeeklyPlan_1.RowName = {};
            app.PotVolLabel_1 = uilabel(app.Grid_Pot1);
            app.PotVolLabel_1.Text = 'סיר בנפח';
            app.UpdateVol_1 = uilabel(app.Grid_Pot1);
            app.UpdateVol_1.Text = 'Vol';
            app.PotCapLabel_1 = uilabel(app.Grid_Pot1);
            app.PotCapLabel_1.Text = 'עומס בישול';
            app.updateCap_1 = uilabel(app.Grid_Pot1);
            app.updateCap_1.Text = 'Cap';
            app.PotPORLabel_1 = uilabel(app.Grid_Pot1);
            app.PotPORLabel_1.Text = 'כמות מנות';
            app.updateportions_1 = uilabel(app.Grid_Pot1);
            app.updateportions_1.Text = 'Cap';
            app.Pot_2 = uitab(app.OutputTab);
            app.Pot_2.Title = 'סיר 2';
            app.Grid_Pot2 = uigridlayout(app.Pot_2);
            app.WeeklyPlan_2 = uitable(app.Grid_Pot2);
            app.WeeklyPlan_2.ColumnName = {'מתכון'; 'כמות'; 'משך_בישול'; 'זמני_נקיון'; 'זמן_כולל'; 'יום בישול'};
            app.WeeklyPlan_2.RowName = {};
            app.PotVolLabel_2 = uilabel(app.Grid_Pot2);
            app.PotVolLabel_2.Text = 'סיר בנפח';
            app.UpdateVol_2 = uilabel(app.Grid_Pot2);
            app.UpdateVol_2.Text = 'Vol';
            app.PotCapLabel_2 = uilabel(app.Grid_Pot2);
            app.PotCapLabel_2.Text = 'עומס בישול';
            app.updateCap_2 = uilabel(app.Grid_Pot2);
            app.updateCap_2.Text = 'Cap';
            app.PotPORLabel_2 = uilabel(app.Grid_Pot2);
            app.PotPORLabel_2.Text = 'כמות מנות';
            app.updateportions_2 = uilabel(app.Grid_Pot2);
            app.updateportions_2.Text = 'Cap';
            app.Pot_3 = uitab(app.OutputTab);
            app.Pot_3.Title = 'סיר 3';
            app.Grid_Pot3 = uigridlayout(app.Pot_3);
            app.WeeklyPlan_3 = uitable(app.Grid_Pot3);
            app.WeeklyPlan_3.ColumnName = {'מתכון'; 'כמות'; 'משך_בישול'; 'זמני_נקיון'; 'זמן_כולל'; 'יום בישול'};
            app.WeeklyPlan_3.RowName = {};
            app.PotVolLabel_3 = uilabel(app.Grid_Pot3);
            app.PotVolLabel_3.Text = 'סיר בנפח';
            app.UpdateVol_3 = uilabel(app.Grid_Pot3);
            app.UpdateVol_3.Text = 'Vol';
            app.PotCapLabel_3 = uilabel(app.Grid_Pot3);
            app.PotCapLabel_3.Text = 'עומס בישול';
            app.updateCap_3 = uilabel(app.Grid_Pot3);
            app.updateCap_3.Text = 'Cap';
            app.PotPORLabel_3 = uilabel(app.Grid_Pot3);
            app.PotPORLabel_3.Text = 'כמות מנות';
            app.updateportions_3 = uilabel(app.Grid_Pot3);
            app.updateportions_3.Text = 'Cap';
            app.StraussImag = uiimage(app.GridLayout12);
            app.StraussImag.ImageSource = 'download.png';
            app.capacityEditField = uieditfield(app.GridLayout12, 'numeric');
            app.capacityEditField.Limits = [0 Inf];
            app.capacityEditField.ValueChangedFcn = createCallbackFcn(app, @capacityEditFieldValueChanged, true);
            app.capacityEditField.Value = 120;
            app.capacityEditFieldLabel = uilabel(app.GridLayout12);
            app.capacityEditFieldLabel.Text = 'Total Weekly Capacity:';
            app.weeklyRequirements = uitab(app.TabGroup);
            app.weeklyRequirements.Title = 'דרישות ';
            app.GridLayout10 = uigridlayout(app.weeklyRequirements);
            app.weeklyrequirementsTable = uitable(app.GridLayout10);
            app.weeklyrequirementsTable.ColumnName = {'יום ה'; 'יום ד'; 'יום ג'; 'יום ב'; 'יום א'; 'מתכון'};
            app.weeklyrequirementsTable.RowName = {};
            app.CookingTimesDeafult = uitab(app.TabGroup);
            app.CookingTimesDeafult.Title = 'זמני בישול';
            app.GridLayout9 = uigridlayout(app.CookingTimesDeafult);
            app.CookingTimesTable = uitable(app.GridLayout9);
            app.CookingTimesTable.ColumnName = {'זמן בישול בשעות'; 'מתכון'; 'מק"ט'};
            app.CookingTimesTable.RowName = {};
            app.CookingTimesTable.Tag = 'זמני בישול';
            app.Cookingsize = uitab(app.TabGroup);
            app.Cookingsize.Title = 'גדלי בישול';
            app.GridLayout11 = uigridlayout(app.Cookingsize);
            app.CookingSizesTable = uitable(app.GridLayout11);
            app.CookingSizesTable.ColumnName = {'P1200'; 'P800'; 'P600'; 'P500'; 'P450'; 'P400'; 'P300'; 'מתכון'; 'מק"ט'};
            app.CookingSizesTable.RowName = {};
            app.CookingSizesTable.Tag = 'זמני בישול';
            app.settings = uitab(app.TabGroup);
            app.settings.Title = 'הגדרות';
            app.GridLayout13 = uigridlayout(app.settings);
            app.PDownTime = uipanel(app.GridLayout13);
            app.PDownTime.Title = 'זמני השבתה';
            app.GridLayout3 = uigridlayout(app.PDownTime);
            app.addDownTime = uibutton(app.GridLayout3, 'push');
            app.addDownTime.ButtonPushedFcn = createCallbackFcn(app, @addDownTimeButtonPushed, true);
            app.addDownTime.Text = 'הוסף לתכנון';
            app.Label_13 = uilabel(app.GridLayout3);
            app.Label_13.Text = 'משך ההשבתה';
            app.duration_time_down = uidropdown(app.GridLayout3);
            app.duration_time_down.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.duration_time_down.Value = '6';
            app.Start_day_label = uilabel(app.GridLayout3);
            app.Start_day_label.Text = 'יום בשבוע';
            app.Start_day_down = uidropdown(app.GridLayout3);
            app.Start_day_down.Items = {'יום א', 'יום ב', 'יום ג', 'יום ד', 'יום ה'};
            app.Start_day_down.ItemsData = {'א', 'ב', 'ג', 'ד', 'ה'};
            app.Start_day_down.Value = 'א';
            app.Label_12 = uilabel(app.GridLayout3);
            app.Label_12.Text = 'סיר';
            app.pot_list_down = uidropdown(app.GridLayout3);
            app.pot_list_down.Items = {'600', '1200', 'כולם'};
            app.pot_list_down.ItemsData = {'600', '1200', 'all'};
            app.pot_list_down.Value = 'all';
            app.DownTimeTable = uitable(app.GridLayout3);
            app.DownTimeTable.ColumnName = {'משך ההשבתה'; 'נפח סיר'; 'יום בשבוע'};
            app.DownTimeTable.RowName = {};
            app.DownTimeTable.CellSelectionCallback = createCallbackFcn(app, @DownTimeTableCellSelection, true);
            app.MaintananceImage = uiimage(app.GridLayout3);
            app.MaintananceImage.ImageSource = 'breakImg.jpg';
            app.PCleaning = uipanel(app.GridLayout13);
            app.PCleaning.Title = 'זמני נקיון';
            app.GridLayout = uigridlayout(app.PCleaning);
            app.AllowCIP = uicheckbox(app.GridLayout);
            app.AllowCIP.ValueChangedFcn = createCallbackFcn(app, @AllowCIPValueChanged, true);
            app.AllowCIP.Text = '';
            app.DescriptionCIP = uilabel(app.GridLayout);
            app.DescriptionCIP.Text = 'זמן נקיון עבור כל הסירים CIP';
            app.AllowClean1200 = uicheckbox(app.GridLayout);
            app.AllowClean1200.ValueChangedFcn = createCallbackFcn(app, @AllowClean1200ValueChanged, true);
            app.AllowClean1200.Text = '';
            app.Description1200 = uilabel(app.GridLayout);
            app.Description1200.Text = 'זמן נקיון עבור הסיר בנפח 1200 בלבד';
            app.CIP_Time = uieditfield(app.GridLayout, 'numeric');
            app.CIP_Time.Limits = [0 Inf];
            app.CIP_Time.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.CIP_Time.Enable = 'off';
            app.CIP_Time.Value = 4;
            app.CIP_min_hour = uidropdown(app.GridLayout);
            app.CIP_min_hour.Items = {'שעות', 'דקות'};
            app.CIP_min_hour.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.CIP_min_hour.Enable = 'off';
            app.CIP_min_hour.Value = 'שעות';
            app.Time_1200 = uieditfield(app.GridLayout, 'numeric');
            app.Time_1200.Limits = [0 Inf];
            app.Time_1200.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.Time_1200.Enable = 'off';
            app.Time_1200.Value = 40;
            app.min_hour_1200 = uidropdown(app.GridLayout);
            app.min_hour_1200.Items = {'שעות', 'דקות'};
            app.min_hour_1200.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.min_hour_1200.Enable = 'off';
            app.min_hour_1200.Value = 'דקות';
            app.AllowWashTime = uicheckbox(app.GridLayout);
            app.AllowWashTime.ValueChangedFcn = createCallbackFcn(app, @AllowWashTimeValueChanged, true);
            app.AllowWashTime.Text = '';
            app.DescriptionWashTime = uilabel(app.GridLayout);
            app.DescriptionWashTime.Text = 'שטיפה בין פרי לפי - SETUP';
            app.WashTime = uieditfield(app.GridLayout, 'numeric');
            app.WashTime.Limits = [0 Inf];
            app.WashTime.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.WashTime.Enable = 'off';
            app.WashTime.Value = 20;
            app.WashTime_min_hour = uidropdown(app.GridLayout);
            app.WashTime_min_hour.Items = {'שעות', 'דקות'};
            app.WashTime_min_hour.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.WashTime_min_hour.Enable = 'off';
            app.WashTime_min_hour.Value = 'דקות';
            app.DOCIP_min_hour_2 = uidropdown(app.GridLayout);
            app.DOCIP_min_hour_2.Items = {'שעות', 'דקות'};
            app.DOCIP_min_hour_2.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.DOCIP_min_hour_2.Enable = 'off';
            app.DOCIP_min_hour_2.Value = 'שעות';
            app.DOCIP_Time_2 = uieditfield(app.GridLayout, 'numeric');
            app.DOCIP_Time_2.Limits = [0 Inf];
            app.DOCIP_Time_2.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.DOCIP_Time_2.Enable = 'off';
            app.DOCIP_Time_2.Value = 48;
            app.DOTime_1200_Time_2 = uieditfield(app.GridLayout, 'numeric');
            app.DOTime_1200_Time_2.Limits = [0 Inf];
            app.DOTime_1200_Time_2.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.DOTime_1200_Time_2.Enable = 'off';
            app.DOTime_1200_Time_2.Value = 24;
            app.DOTime_1200_min_hour_2 = uidropdown(app.GridLayout);
            app.DOTime_1200_min_hour_2.Items = {'שעות', 'דקות'};
            app.DOTime_1200_min_hour_2.ValueChangedFcn = createCallbackFcn(app, @CIP_TimeValueChanged, true);
            app.DOTime_1200_min_hour_2.Enable = 'off';
            app.DOTime_1200_min_hour_2.Value = 'שעות';
            app.DO_afterT = uilabel(app.GridLayout);
            app.DO_afterT.Text = 'לאחר כל';
            app.FOR_T = uilabel(app.GridLayout);
            app.FOR_T.Text = 'נקיון לאורך';
            app.FloorHrs = uipanel(app.GridLayout13);
            app.FloorHrs.Title = 'שעות פעילות- רצפת ייצור';
            app.GridLayout8 = uigridlayout(app.FloorHrs);
            app.Label_15 = uilabel(app.GridLayout8);
            app.Label_15.Enable = 'off';
            app.Label_15.Text = {'תחילת שבוע הבא: יום ראשון'; 'שעת התחלה'};
            app.SUN_time = uidropdown(app.GridLayout8);
            app.SUN_time.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.SUN_time.ValueChangedFcn = createCallbackFcn(app, @SUN_timeValueChanged, true);
            app.SUN_time.Enable = 'off';
            app.SUN_time.Value = '6';
            app.Label_14 = uilabel(app.GridLayout8);
            app.Label_14.Enable = 'off';
            app.Label_14.Text = {'סיום שבוע נוכחי:  יום חמישי'; 'שעת סיום'};
            app.THUR_time = uidropdown(app.GridLayout8);
            app.THUR_time.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.THUR_time.ValueChangedFcn = createCallbackFcn(app, @THUR_timeValueChanged, true);
            app.THUR_time.Enable = 'off';
            app.THUR_time.Value = '15';
            app.FRI_label = uilabel(app.GridLayout8);
            app.FRI_label.Text = 'יום שישי';
            app.FRI = uicheckbox(app.GridLayout8);
            app.FRI.ValueChangedFcn = createCallbackFcn(app, @FRIValueChanged, true);
            app.FRI.Text = '';
            app.FRI_START_label = uilabel(app.GridLayout8);
            app.FRI_START_label.Enable = 'off';
            app.FRI_START_label.Text = 'שעת התחלה';
            app.FRI_START = uidropdown(app.GridLayout8);
            app.FRI_START.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23'};
            app.FRI_START.ValueChangedFcn = createCallbackFcn(app, @FRI_ValueChanged, true);
            app.FRI_START.Enable = 'off';
            app.FRI_START.Value = '0';
            app.FRI_END_label = uilabel(app.GridLayout8);
            app.FRI_END_label.Enable = 'off';
            app.FRI_END_label.Text = 'שעת סיום';
            app.FRI_END = uidropdown(app.GridLayout8);
            app.FRI_END.Items = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.FRI_END.ValueChangedFcn = createCallbackFcn(app, @FRI_ValueChanged, true);
            app.FRI_END.Enable = 'off';
            app.FRI_END.Value = '6';
            app.Weekly_label = uilabel(app.GridLayout8);
            app.Weekly_label.Text = 'שבועי';
            app.WEEKLY = uicheckbox(app.GridLayout8);
            app.WEEKLY.ValueChangedFcn = createCallbackFcn(app, @WEEKLYValueChanged, true);
            app.WEEKLY.Text = '';
            app.Label_18 = uilabel(app.GridLayout13);
            app.Label_18.Text = 'כמות איטרציות לחישוב';
            app.NumberofIterations = uieditfield(app.GridLayout13, 'numeric');
            app.NumberofIterations.ValueDisplayFormat = '%.0f';
            app.NumberofIterations.Value = 150000;
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Last_Ver_of_Amit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end